classdef CaSignalsAnalysisXYT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu_openImg                    matlab.ui.container.Menu
        Menu_settings                   matlab.ui.container.Menu
        Menu_loadAnalyzedImgSeries      matlab.ui.container.Menu
        Menu_saveAxesAsImgs             matlab.ui.container.Menu
        Menu_convertMatFileToTIFFs      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        Tab_CaEventsDetection           matlab.ui.container.Tab
        Panel_cellMask                  matlab.ui.container.Panel
        CheckBox_cellMaskForEachFrame   matlab.ui.control.CheckBox
        Panel_cellMaskManual            matlab.ui.container.Panel
        CheckBox_cellMask_manulalCorrection_fillHoles  matlab.ui.control.CheckBox
        CheckBox_cellMask_manualCorrection_toAll  matlab.ui.control.CheckBox
        Button_cellMask_manualCorrection  matlab.ui.control.Button
        CheckBox_cellMask_show          matlab.ui.control.CheckBox
        EditField_kernelSz_cellMask     matlab.ui.control.NumericEditField
        kernelsizeLabel                 matlab.ui.control.Label
        Button_cellMask                 matlab.ui.control.Button
        selfratioPanel                  matlab.ui.container.Panel
        CheckBox_F0ForEachFrame         matlab.ui.control.CheckBox
        EditField_polyDegree            matlab.ui.control.NumericEditField
        polynomialdegreeEditFieldLabel  matlab.ui.control.Label
        DropDown_photobleachFcn         matlab.ui.control.DropDown
        functionforcorrectionLabel      matlab.ui.control.Label
        Button_selfRatio                matlab.ui.control.Button
        UIAxes_F0                       matlab.ui.control.UIAxes
        Panel_imgVisual                 matlab.ui.container.Panel
        Button_restart                  matlab.ui.control.Button
        Panel_cropImageSeries           matlab.ui.container.Panel
        EditField_cropStartFrame        matlab.ui.control.NumericEditField
        startframeLabel                 matlab.ui.control.Label
        EditField_cropEndFrame          matlab.ui.control.NumericEditField
        endframeLabel                   matlab.ui.control.Label
        Button_crop                     matlab.ui.control.Button
        Panel_imgFilters                matlab.ui.container.Panel
        Button_butterworthFilter        matlab.ui.control.Button
        UITable_filters                 matlab.ui.control.Table
        filterimageserieswithselectedfiltersButton  matlab.ui.control.Button
        Panel_visualisation             matlab.ui.container.Panel
        EditField_setglobalmax          matlab.ui.control.NumericEditField
        setglobalmaxEditFieldLabel      matlab.ui.control.Label
        EditField_setglobalmin          matlab.ui.control.NumericEditField
        setglobalminEditFieldLabel      matlab.ui.control.Label
        DropDown_lookuptable            matlab.ui.control.DropDown
        lookuptableDropDownLabel        matlab.ui.control.Label
        Lamp_state                      matlab.ui.control.Lamp
        stateLampLabel                  matlab.ui.control.Label
        EditField_imgTime               matlab.ui.control.NumericEditField
        timemsLabel                     matlab.ui.control.Label
        EditField_framesPerSec          matlab.ui.control.NumericEditField
        framessEditFieldLabel           matlab.ui.control.Label
        Spinner_imgFrame                matlab.ui.control.Spinner
        frame1SpinnerLabel              matlab.ui.control.Label
        Button_stop                     matlab.ui.control.Button
        CheckBox_playInLoop             matlab.ui.control.CheckBox
        Button_play                     matlab.ui.control.Button
        Slider_imgFrame                 matlab.ui.control.Slider
        Panel_caEventsDetection         matlab.ui.container.Panel
        CheckBox_saveDetection          matlab.ui.control.CheckBox
        Panel_batchMode                 matlab.ui.container.Panel
        CheckBox_calcSelfRatioInBatch   matlab.ui.control.CheckBox
        CheckBox_findCellMaskInBatch    matlab.ui.control.CheckBox
        DropDown_typeOfAnalysisInBatch  matlab.ui.control.DropDown
        typeofprocessDropDownLabel      matlab.ui.control.Label
        CheckBox_includeSubfolders      matlab.ui.control.CheckBox
        Button_batchAnalysis            matlab.ui.control.Button
        Panel_unetThresholds            matlab.ui.container.Panel
        EditField_spark_thr             matlab.ui.control.NumericEditField
        sparkEditField_5Label           matlab.ui.control.Label
        EditField_wave_thr              matlab.ui.control.NumericEditField
        waveEditFieldLabel              matlab.ui.control.Label
        EditField_puff_thr              matlab.ui.control.NumericEditField
        puffEditFieldLabel              matlab.ui.control.Label
        Button_detectevents             matlab.ui.control.Button
        DropDown_detectionMethod        matlab.ui.control.DropDown
        selectmethodDropDownLabel       matlab.ui.control.Label
        UITable_imgMeta                 matlab.ui.control.Table
        UIAxes_avrgCellSignal           matlab.ui.control.UIAxes
        UIAxes_img                      matlab.ui.control.UIAxes
        Tab_analysedEventsBrowser       matlab.ui.container.Tab
        Button_save                     matlab.ui.control.Button
        Panel_imgVisual_events          matlab.ui.container.Panel
        CheckBox_showSelectedEvent      matlab.ui.control.CheckBox
        CheckBox_showCellMask_events    matlab.ui.control.CheckBox
        CheckBox_showBB_events          matlab.ui.control.CheckBox
        CheckBox_showmask_events        matlab.ui.control.CheckBox
        CheckBox_showevents_events      matlab.ui.control.CheckBox
        Panel_visualisation_events      matlab.ui.container.Panel
        EditField_setglobalmax_events   matlab.ui.control.NumericEditField
        setglobalmaxEditFieldLabel_2    matlab.ui.control.Label
        EditField_setglobalmin_events   matlab.ui.control.NumericEditField
        setglobalminEditFieldLabel_2    matlab.ui.control.Label
        DropDown_lookuptable_events     matlab.ui.control.DropDown
        lookuptableDropDownLabel_3      matlab.ui.control.Label
        Lamp_state_events               matlab.ui.control.Lamp
        stateLampLabel_2                matlab.ui.control.Label
        EditField_imgTime_events        matlab.ui.control.NumericEditField
        timemsLabel_2                   matlab.ui.control.Label
        EditField_framesPerSec_events   matlab.ui.control.NumericEditField
        framessEditFieldLabel_2         matlab.ui.control.Label
        Spinner_imgFrame_events         matlab.ui.control.Spinner
        frame1SpinnerLabel_2            matlab.ui.control.Label
        Button_stop_events              matlab.ui.control.Button
        CheckBox_playInLoop_events      matlab.ui.control.CheckBox
        Button_play_events              matlab.ui.control.Button
        Slider_imgFrame_events          matlab.ui.control.Slider
        SelectedeventPanel              matlab.ui.container.Panel
        Panel_firCorrection             matlab.ui.container.Panel
        EditField_weightsPower2DGaussFit  matlab.ui.control.NumericEditField
        k_thpowerofweights2DGaussianfitLabel  matlab.ui.control.Label
        DropDown_fitFunSelection        matlab.ui.control.DropDown
        selectfitfunctiontprofileLabel  matlab.ui.control.Label
        EditField_smoothParam           matlab.ui.control.NumericEditField
        smoothspanmstprofileLabel       matlab.ui.control.Label
        UITable_eventParams             matlab.ui.control.Table
        UIAxes_eventXYprof              matlab.ui.control.UIAxes
        UIAxes_eventTprof               matlab.ui.control.UIAxes
        Panel_detectedEvents            matlab.ui.container.Panel
        UITable_events                  matlab.ui.control.Table
        UIAxes_img_events               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % save data used/created during analysis
        data = struct( ...
            'imgPath', [], ...
            'batchMode', false, ...
            'loadAnalyzedImgSeriesFlag', false, ...
            'pxSz_x', [], ...     % spatial pixel size
            'pxSz_y', [], ...     % spatial pixel size 
            'pxSzT', [], ...      % time step
            't', [], ... % time in ms
            'imgDataAll', [], ... % all image data as loaded by bioformats
            'imgDataRaw', [], ... % raw data, cropped, not filtered or normalized
            'imgData', [], ...    % fluorescent image data, filtered, normalized etc
            'cellMask', [], ...   % mask of cell in image series
            'crop_s_frame', [], ...   % crop start, end and crop ROI position data
            'crop_e_frame', [], ...
            'crop_roi_xy_pos', [], ...
            'detectedEvents', table(), ... % table with detected events, each row is event with data, position, mask, type, ...
            'selectedEvent', [], ...
            'condaEnvPath', [], ...
            'previousAppState', struct() ... % saved properties of closed app, such as paths to conda env... 
            );
    end
    
    properties (Access = private)
        % filter names used in app
        filterNames = {'none', 'gaussian', 'average', ...
            'median', 'anisotropic diffusion', 'PURE-let', ...
            'Butterworth filter'};
    end
    
    methods (Access = private)
        
        function showImgAndRescaleAxes(app, event)
            % get image data
            imgData = app.data.imgData;
            % resize axis based on image size (can be only smaller as original size)
            switch event.Source.Tag 
                case 'detectEvents'
                    %if strcmp(event.Source.Text, 'detect events')
                        % old positon of axes
                        old_pos = app.data.orgCompPos.axImg_events;
                        % handles to uicontrols in respective tab
                        ax = app.UIAxes_img_events;
                        dropDown_cmap = app.DropDown_lookuptable_events;
                        panel_visual = app.Panel_imgVisual_events;
                        edit_imgTime = app.EditField_imgTime_events;
                        slider_imgFrame = app.Slider_imgFrame_events;
                        spinner_imgFrame = app.Spinner_imgFrame_events;
                        spinner_label = app.frame1SpinnerLabel_2;
                        edit_imgGlobalMin = app.EditField_setglobalmin_events;
                        edit_imgGlobalMax = app.EditField_setglobalmax_events;
                        
                        % show tab for processing detected events
                        app.TabGroup.SelectedTab = app.Tab_analysedEventsBrowser;

                        % show detected events in table
                        app.UITable_events.Data = app.data.detectedEvents(:,[1,6,2,3,15]);
                        % add contex menu to export event
                        cm = uicontextmenu(app.UIFigure,'Tag', 'contexmenu_exportEvent');
                        m1 = uimenu(cm,'Text','export event as TIFF', ...
                            'MenuSelectedFcn', {@app.showSelectedEventInTableOfEvents});
                        m2 = uimenu(cm,'Text','export event as GIF', ...
                            'MenuSelectedFcn', {@app.showSelectedEventInTableOfEvents});
                        app.UITable_events.ContextMenu = cm;
                    %end

                case {'menu_openImg', 'cropImage', 'restartAnalysis'}
                    % old positon of axes
                    old_pos = app.data.orgCompPos.axImg;
                    % handles to uicontrols in respective tab
                    ax = app.UIAxes_img;
                    dropDown_cmap = app.DropDown_lookuptable;
                    panel_visual = app.Panel_imgVisual;
                    edit_imgTime = app.EditField_imgTime;
                    slider_imgFrame = app.Slider_imgFrame;
                    spinner_imgFrame = app.Spinner_imgFrame;
                    spinner_label = app.frame1SpinnerLabel;
                    edit_imgGlobalMin = app.EditField_setglobalmin;
                    edit_imgGlobalMax = app.EditField_setglobalmax;
            end

            if size(imgData,1) < size(imgData,2)
                r = size(imgData,1) / size(imgData,2);
                new_ax_h = ceil(old_pos(3)*r)*1.25;
                if new_ax_h > old_pos(4)
                    new_ax_h = old_pos(4);
                end
                % how much to shift axes and sliders
                dy = old_pos(4)-new_ax_h;
                new_pos = [ old_pos(1), old_pos(2)+dy, ...
                    old_pos(3), new_ax_h ];
            
            else
                r = size(imgData,2) / size(imgData,1);
                new_ax_w = ceil(old_pos(4)*r)*1.25;
                if new_ax_w > old_pos(3)
                    new_ax_w = old_pos(3);
                end
                dy = 0;
                new_pos = [ old_pos(1)+(old_pos(3)-new_ax_w)/2, ...
                    old_pos(2)+dy, new_ax_w, old_pos(4) ];
            end
            
            % change size of axis
            ax.InnerPosition = new_pos;
            
            % show first frame in tiff image series in image axes
            imgFirst = imgData(:,:,1);
            img_caxis_lims = prctile(imgData(:),[1 99]);
            cla(ax)

            imshow(imgFirst,'Parent',ax, ...
                'InitialMagnification','fit', ...
                'Border', 'tight');
            %image(imgFirst, ...
            %    'CDataMapping','scaled', ...
            %    'Parent',app.UIAxes_img);
            set(ax, 'XLim', [1 size(imgData,2)], ...
                'YLim', [1 size(imgData,1)])
            colormap(ax, dropDown_cmap.Value)
            caxis(ax, img_caxis_lims)
            ax.XAxis.Visible = "on";
            ax.YAxis.Visible = "on";
            
            % set position of panel for visualization control, move up and
            % down
            panel_visual.Position(2) = ... 
                ax.Position(2)-panel_visual.Position(4)-20;
            
            % setup time, image slider and selector
            edit_imgTime.Value = app.data.t(1);
            slider_imgFrame.Value = 1;
            slider_imgFrame.Limits = [1 size(imgData,3)];
            spinner_imgFrame.Value = 1;
            spinner_imgFrame.Limits = [1 size(imgData,3)];
            spinner_label.Text = 'frame #:';
            
            % set up limits for image as 
            edit_imgGlobalMin.Value = double(img_caxis_lims(1));
            edit_imgGlobalMax.Value = double(img_caxis_lims(2));
             
            % turn of axes interactivity
            disableDefaultInteractivity(ax)
            
            % save caxis limits
            app.data.imgDataCaxisLimits = img_caxis_lims;
            
            % specific for first tab
            if strcmp(event.Source.Tag, 'menu_openImg')
                % set up limints for crop edit fields
                app.EditField_cropStartFrame.Value = 1;
                app.EditField_cropEndFrame.Value = size(imgData,3);

                % maybe add, also with some parameters of function
                % set up GUI
                % set up pushbutton for cropping
                app.Button_crop.Text = 'select xy area to crop';
                app.Button_crop.FontWeight = 'normal';
            end
            
        end
        
        
        function showFilteredImgWithCellMask(app, event, resetCmapLims)
            
            % check if there are some image data
            if isempty(app.data.imgData)
                return
            end
           
            % get current selected image frame
            curr_frame = app.Spinner_imgFrame.Value;
            
            % show image
            img_series_h = findobj(app.UIAxes_img, 'Type', 'Image');
            img_series_h.CData = app.data.imgData(:,:,curr_frame);
     
            % change caxis limits only after filtering or cropping, but not
            % when moving with slider or spinner
            if resetCmapLims
                
                % set up and save caxis limits
                app.data.imgDataCaxisLimits = ...
                    prctile(app.data.imgData(:),[1 99]);
              
                img_caxis_lims = app.data.imgDataCaxisLimits;
                app.UIAxes_img.CLim = [img_caxis_lims(1) img_caxis_lims(2)];
                app.UIAxes_img.Title.String = 'XYT image series';
                
                app.EditField_setglobalmin.Value = double(img_caxis_lims(1));
                app.EditField_setglobalmax.Value = double(img_caxis_lims(2));
                
            end

            % check if to show cell boundary
            if app.CheckBox_cellMask_show.Value
                % get cell boundary
                cellBoundary = bwboundaries( ...
                    app.data.cellMask(:,:,curr_frame), 8, 'noholes');
                % delete already shown cell boundary, as patch
                delete( findobj(app.UIAxes_img, ...
                    'Type', 'Patch', '-or', 'Tag', 'cellMask') );
                % replot cell boundary as patch
                % select color for cell mask patch based on lookup table
                % used for image series visualization
                patch_color = app.cellMaskPatchColor;
                cell_boundary_h = patch( ...
                            'Faces',(1:numel(cellBoundary{1}(:,2))), ...
                            'Vertices',[cellBoundary{1}(:,2), ...
                            cellBoundary{1}(:,1)], ...
                            'FaceColor',patch_color, 'FaceAlpha',0.2, ...
                            'EdgeColor',patch_color, 'EdgeAlpha',1, ...
                            'Parent', app.UIAxes_img, ...
                            'LineWidth',1, 'Tag', 'cellMask');
            end

            % show global signal in axis
            avrg_F = zeros(size(app.data.imgData, 3),1);
            for i=1:size(app.data.imgData, 3)
                img = app.data.imgData(:,:,i);
                if isfield(app.data, 'cellMask')
                    % from cell area
                    m_cell = app.data.cellMask(:,:,i);
                    title_str = 'average signal from cell area';
                else
                    % from whole frame
                    m_cell = true(size(app.data.imgData, [1,2]));
                    title_str = 'average signal from whole frame';
                end
                avrg_F(i) = mean(img(m_cell));
            end
            % plot it
            plot(app.UIAxes_avrgCellSignal, ...
                (1:size(app.data.imgData, 3)), avrg_F, ...
                'Marker','.', 'MarkerSize',10, ...
                'Color','k', 'LineStyle','-')
            % set up axis
            set(app.UIAxes_avrgCellSignal, ...
                'XLim', [1 size(app.data.imgData, 3)], ...
                'YLim', [min(avrg_F)*0.99 max(avrg_F)*1.01])
            app.UIAxes_avrgCellSignal.Title.String = title_str;
            
        end
        
        
        function filterImgForAnalysis(app, type) 
            % get data
            imgDataRaw = app.data.imgDataRaw;
            imgDataFilt = ones(size(imgDataRaw));
            switch type
                case {'blank', 'cellEdge'}
                    % apply median and average filter, one after each other
                    kernel_sz = 5;
                    % median
                    for i=1:size(imgDataRaw,3)
                        imgDataFilt(:,:,i) = medfilt2(imgDataRaw(:,:,i), ...
                            [kernel_sz kernel_sz]);
                    end
                    % average
                    H = fspecial('average',[kernel_sz kernel_sz]);
                    for i=1:size(imgDataRaw,3)
                        imgDataFilt(:,:,i) = imfilter(imgDataFilt(:,:,i), ...
                            H, 'symmetric');
                    end
                
                case 'calculation_deltaF_div_F0'
                    kernel_sz = 3;
                    % median
                    for i=1:size(imgDataRaw,3)
                        imgDataFilt(:,:,i) = medfilt2(imgDataRaw(:,:,i), ...
                            [kernel_sz kernel_sz]);
                    end
                    
                case 'eventsDetection'
                    
                    
                case 'eventsAnalysis'
                    
            end
            % save filtered data
            app.data.imgData = imgDataFilt;
        end
        
        
        function showFilteredImgWithEventsBoundaries(app, event, ...
                resetCmapLims, createEventsHandlesInAllFrames)
        
            % get table with detected events
            try
                detectedEvents = app.data.detectedEvents;
            catch
                return
            end
            if isempty(detectedEvents)
                return
            end

            % get current selected image frame
            curr_frame = app.Spinner_imgFrame_events.Value;
            if curr_frame==size(app.data.imgData, 3)
                curr_frame = 1;
            end

            % show image and detected events
            img_series_h = findobj(app.UIAxes_img_events, 'Type', 'Image');
            if isempty(img_series_h)
                cla(app.UIAxes_img_events)
            else
                img_series_h.CData = app.data.imgData(:,:,curr_frame);
            end

            % change caxis limits only after filtering or cropping, but not
            % when moving with slider or spinner
            if resetCmapLims
               
                % set up and save caxis limits
                app.data.imgDataCaxisLimits = ...
                    prctile(app.data.imgData(:),[1 99]);
                
                img_caxis_lims = app.data.imgDataCaxisLimits;
                app.UIAxes_img_events.CLim = [img_caxis_lims(1) img_caxis_lims(2)];
                app.UIAxes_img_events.Title.String = 'XYT image series';
                
                app.EditField_setglobalmin_events.Value = double(img_caxis_lims(1));
                app.EditField_setglobalmax_events.Value = double(img_caxis_lims(2));
            
            end
           
            % check if to show cell boundary
            if app.CheckBox_showCellMask_events.Value
                % get calculated cell boundaries
                % get cell boundary
                cellBoundary = bwboundaries( ...
                    app.data.cellMask(:,:,curr_frame), 8, 'noholes');
                % get handle of already shown cell boundary
                cell_boundary_h = findobj(app.UIAxes_img_events, 'Type', 'Patch', ...
                    'Tag', 'cellMask');
                % get color of patch 
                patch_color = app.cellMaskPatchColor;
                if isempty(cell_boundary_h)
                    patch( ...
                        'Faces',(1:numel(cellBoundary{1}(:,2))), ...
                        'Vertices',[cellBoundary{1}(:,2), ...
                                    cellBoundary{1}(:,1)], ...
                        'FaceColor',patch_color, 'FaceAlpha',0, ... % 0.2
                        'EdgeColor',patch_color, 'EdgeAlpha',1, ...
                        'Parent', app.UIAxes_img_events, ...
                        'LineWidth', 1, 'Tag', 'cellMask');
                else
                    cell_boundary_h.Faces = (1:numel(cellBoundary{1}(:,2)));
                    cell_boundary_h.Vertices = [cellBoundary{1}(:,2), ...
                        cellBoundary{1}(:,1)];
                    
                end
            end
           
            % check if to create cell array of handles of events for each frame, 
            % plot them in events axis and then set Visible to off
            % create uicontextmenu to change type of event later
            % 1 = spark, 2 = puff, 3 = wave, 4 = none
            cm = uicontextmenu(app.UIFigure, 'Tag','changeEventType');
            m1 = uimenu(cm,'Text','spark', ...
                'MenuSelectedFcn', {@app.showSelectedEventInTableOfEvents});
            m2 = uimenu(cm,'Text','puff', ...
                'MenuSelectedFcn', {@app.showSelectedEventInTableOfEvents});
            m3 = uimenu(cm,'Text','wave', ...
                'MenuSelectedFcn', {@app.showSelectedEventInTableOfEvents});
            m4 = uimenu(cm,'Text','NA', ...
                'MenuSelectedFcn', {@app.showSelectedEventInTableOfEvents});
            
            if createEventsHandlesInAllFrames
                finalTableOfEvents = app.data.detectedEvents;
                eventsInAllFrames = cell(size(app.data.imgData,3),1);
                % get patches of all events in current frame
                for f=1:size(app.data.imgData,3)
                    eventsInFrame = gobjects(0);
                    for i=1:height(finalTableOfEvents)
                        % check if event is present in selected frame
                        eventPresence = ismember( ...
                            (finalTableOfEvents.imgNum_s(i):finalTableOfEvents.imgNum_e(i)), f);
                        if any(eventPresence)
                            % find boundary of event in selected frame
                            % in case it propagates to multiple frames
                            idxBoundary = find(eventPresence, 1, 'first');
                            eventsInFrame = [ ...
                                eventsInFrame; ...
                                patch( ...
                                'Faces',(1:numel(finalTableOfEvents.boundary{i}{idxBoundary}(:,2))), ...
                                'Vertices',[finalTableOfEvents.boundary{i}{idxBoundary}(:,2), ...
                                finalTableOfEvents.boundary{i}{idxBoundary}(:,1)], ...
                                'FaceColor', finalTableOfEvents.color(i,:), ...
                                'FaceAlpha', 0, ...
                                'EdgeColor', finalTableOfEvents.color(i,:), ...
                                'EdgeAlpha', 0, ...
                                'Parent', app.UIAxes_img_events, ...
                                'LineWidth', 2, ...
                                'Tag', sprintf('%d', finalTableOfEvents.eventID(i)), ...
                                'Visible','off', ...
                                'ButtonDownFcn', {@app.showSelectedEventInTableOfEvents}, ...
                                'UIContextMenu', cm) ...
                                ];
                        end
                    end
                    eventsInAllFrames(f) = {eventsInFrame};
                end
                app.data.eventsInAllFrames = eventsInAllFrames;
            end
                        
            % show events in current/selected frame
            % check if to show detected events
            if ~isempty(app.data.detectedEvents) && ...
                    app.CheckBox_showevents_events.Value
                % keyboard
                % get boundaries and colors of evens in current frame
                showEvents = true;
                eventsInAllFrames = app.data.eventsInAllFrames;
                
                % check if to show mask
                if app.CheckBox_showmask_events.Value
                    showMaskOfEvents = true;
                else
                    showMaskOfEvents = false;
                end
                % and boundary
                if app.CheckBox_showBB_events.Value
                    showBBofEvents = true;
                else
                    showBBofEvents = false;
                end
            else
                showEvents = false;
            end

            % set invisible previous ones
            switch event.Source.Type             
                
                case 'uispinner'
                    previousFrame = event.PreviousValue;
                    set(eventsInAllFrames{previousFrame}, ...
                       'Visible','off', 'FaceAlpha',0, 'EdgeAlpha',0)
                    
                case 'uislider'
                    cellfun(@(x) set(x, ...
                       'Visible','off', 'FaceAlpha',0, 'EdgeAlpha',0), ...
                        eventsInAllFrames)                    
                otherwise
            end

            % show only the ones in frame of interest
            % check if to show all or only selected event
            if app.CheckBox_showSelectedEvent.Value
                m_selectedEvents = arrayfun( @(x) ...
                    strcmp(x.Tag, num2str(app.data.selectedEvent.eventID)), ...
                    eventsInAllFrames{curr_frame} );
            else
                m_selectedEvents = true(numel(eventsInAllFrames{curr_frame}),1);
            end
            % show only accepted events
            m_acceptedEvents = true(numel(eventsInAllFrames{curr_frame}),1);
            for e = 1:numel(eventsInAllFrames{curr_frame}) 
                m_acceptedEvents(e) = ...
                    app.UITable_events.Data.accepted( ...
                        str2double(eventsInAllFrames{curr_frame}(e).Tag)==...
                        app.UITable_events.Data.eventID );
            end
            m_selectedEvents = m_selectedEvents & m_acceptedEvents;
        
            if showEvents
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                   'Visible', 'on')
            
            else
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                   'Visible', 'off')
            end

            if showMaskOfEvents
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                    'FaceAlpha', 0.2)
            else
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                    'FaceAlpha', 0)
            end

            if showBBofEvents
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                    'EdgeAlpha', 1)
            else
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                    'EdgeAlpha', 0)
            end

        end


        function updateCellMask(app, hROI, event)
            % update cell mask with mask created from ROI
            ROImask = createMask(hROI, app.data.cellMaskCorr.cellMaskCorrected);
            prevCellMask = app.data.cellMaskCorr.cellMaskCorrected;
            switch hROI.Tag
                case 'removeMask'
                    % remove mask created by ROI
                    app.data.cellMaskCorr.cellMaskCorrected = ...
                        prevCellMask & ~(prevCellMask & ROImask);
                case 'addMask'
                    % add mask created by ROI
                    app.data.cellMaskCorr.cellMaskCorrected = ...
                        prevCellMask | ROImask;
            end
            % update image of mask in dummy axes
            app.data.cellMaskCorr.hImg_tempMaskCell.CData = ...
                app.data.cellMaskCorr.cellMaskCorrected;
            app.data.cellMaskCorr.hImg_tempMaskCell.AlphaData = ...
                double(app.data.cellMaskCorr.cellMaskCorrected) .* ...
                max(app.data.cellMaskCorr.hImg_tempMaskCell.AlphaData,[],'all');
        end
        
        
        function eventProfFits = ...
                getProfileAndParamsOfSelectedEvent(app, ...
                eventBoundary, imgNum_s, imgNum_e, ...
                eventType, tProfFitFun, smoothSpan, wP)
            % calculate profile and parameters of selected event
            % distinguish between [puff, spark] and wave
            % t-profile is calculated by averaging 1 um^2 around centre of event
            % x-profile is calculated by averaging of 5 ms around peak of
            % time profile
            % eventBoundary=boundary of event
            % imgNum_s=image where event starts
            % imgNum_e=image where event ends
            % eventType=type: puff or spark or wave
            if isempty(smoothSpan) 
                % used to smooth profile when looking for baseline 
                % and estimated knots in spline fitting
                smoothSpan = 50; % ms
            end
            if isempty(tProfFitFun)
                % t-profile fit function
                tProfFitFun = 'spline';
            end
            if isempty(wP)
                % weights power, used in 2D gauss fitting
                wP = 1;
            end
            
            % number of pixels in xy, to calculate t-profile
            n_px_xy = ceil(1/max(app.data.pxSz_x, app.data.pxSz_y)); % in um
            % make sure it is odd
            if mod(n_px_xy,2)==0
                n_px_xy = n_px_xy+1;
            end
            % radius around centre in xy
            r_xy = (n_px_xy - 1)/2;
            if r_xy < 1
                r_xy = 1;
            end
            % keyboard
            % number of pixels for x profile, in case of local signals
            n_px_t = ceil(5/app.data.pxSzT); % in ms
            if mod(n_px_t,2)==0
                n_px_t = n_px_t+1;
            end
            % radius around centre in t
            r_t = (n_px_t - 1)/2;
            if r_t < 1
                r_t = 1;
            end
            
            % get selected event masks from boundaries
            % also calculate centroids of event in individual frames
            centr_all_frames = zeros(numel(eventBoundary{1}), 2);
            for i = 1:numel(eventBoundary{1})
                % get mask of event in respective frame
                BW_event = poly2mask( ...
                    eventBoundary{1}{i}(:,2), ...
                    eventBoundary{1}{i}(:,1), ...
                    size(app.data.imgData, 1), ...
                    size(app.data.imgData, 2));
%                 % make sure it is not outside of cell
%                 BW_cell = app.dat.cellMask(:,:,imgNum_s+i-1);
%                 BW_event = BW_event & BW_cell;
                % get weighted centroid in respective frame
                eventProps = regionprops(BW_event, ...
                    app.data.imgData(:,:,imgNum_s+i-1), ...
                    'WeightedCentroid','Area');
                [~,idxMaxArea] = max([eventProps.Area]);
                % save centroids
                centr_all_frames(i,:) = eventProps(idxMaxArea).WeightedCentroid;
            end
            
            switch eventType{1}
                
                case {'spark', 'puff', 'NA'}
                    % event is local, centre is not moving much
                    % take average of centroids as center of event
                    centr_avrg = round(mean(centr_all_frames,1));
                    % check if starting or endig rows are not negative 
                    % or bigger than size of image
                    r_s = centr_avrg(2)-r_xy;
                    if r_s < 1
                        r_s = 1;
                    end
                    r_e = centr_avrg(2)+r_xy;
                    if r_e > size(app.data.imgData,1)
                        r_e = size(app.data.imgData,1);
                    end
                    % check if starting or endig columns are not negative 
                    % or bigger than size of image
                    c_s = centr_avrg(1)-r_xy;
                    if c_s < 1
                        c_s = 1;
                    end
                    c_e = centr_avrg(1)+r_xy;
                    if c_e > size(app.data.imgData,2)
                        c_e = size(app.data.imgData,2);
                    end
                    
                    % calculate t-profile of whole series
                    t_prof_all = zeros(1,size(app.data.imgData, 3));
                    for i = 1:size(app.data.imgData, 3)
                        t_prof_all(i) = ...
                            mean(app.data.imgData(r_s:r_e, c_s:c_e, i),'all');
                    end

                    % find resonable start and end of event, with some
                    % baseline
                    % add some baseline before and after detected event
                    % maximum 100 ms before and 300 ms after
                    maxDurOfBs = ceil(100/app.data.pxSzT);
                    % add baseline to event
                    s_evt_bs = double(imgNum_s - maxDurOfBs);
                    durOfBs_beforeEvnt = maxDurOfBs;
                    if s_evt_bs<1
                        s_evt_bs = 1;
                        durOfBs_beforeEvnt = imgNum_s-1;
                    end
                    e_evt_bs = double(imgNum_e + 3*maxDurOfBs);
                    if e_evt_bs>numel(t_prof_all)
                       e_evt_bs = numel(t_prof_all);
                    end
                    % check if there is overlap with some other events
                    % smooth event profile with added baseline
                    n_pts = ceil(smoothSpan/app.data.pxSzT);
                    t_prof_s = smooth( ...
                        t_prof_all(s_evt_bs:e_evt_bs), ...
                        n_pts/double(e_evt_bs-s_evt_bs), ...
                        'loess');
                    t_prof_s = t_prof_s(:);

                    % remove baseline from extended event profile 
                    % only events bigger than 50th percentile of detected
                    % event profile (calculated only from detected not extended profile)
                    % start and end of event in extended profile
                    sE_inProfWithBs = imgNum_s-s_evt_bs;
                    if sE_inProfWithBs<1
                        sE_inProfWithBs = 1;
                    end
                    eE_inProfWithBs = imgNum_e-s_evt_bs+1;
                    if eE_inProfWithBs<=sE_inProfWithBs
                        eE_inProfWithBs = sE_inProfWithBs+1;
                    end
                    if eE_inProfWithBs>numel(t_prof_s)
                        eE_inProfWithBs = numel(t_prof_s);
                    end
                    perc = prctile( ...
                        t_prof_s(sE_inProfWithBs:eE_inProfWithBs), ...
                        [25 50 75]);
                    
                    t_prof_s(t_prof_s<perc(2)) = perc(2);
                    % check if there is some event before
                    % move from detected start of event towards begining of
                    % extended event profile
                    bs_before_eventStart = t_prof_s(1:durOfBs_beforeEvnt);
                    % flip profile and calculate gradient
                    g_bs_before_eventStart = gradient(flipud(bs_before_eventStart));
                    
                    [st_e,~] = find((g_bs_before_eventStart>0),1,'first');
                    if ~isempty(st_e)
                        s_evt_bs = s_evt_bs + ...
                            numel(g_bs_before_eventStart) - st_e + 1;
                    end

                    % move from detected end of event towards end of
                    % profile
                    bs_after_eventEnd = t_prof_s( ...
                        numel(bs_before_eventStart)+imgNum_e-imgNum_s+1 : end);

                    % calculate gradient
                    g_bs_after_eventEnd = gradient(bs_after_eventEnd);
                    
                    [end_e,~] = find((g_bs_after_eventEnd>0),1,'first');
                    if ~isempty(end_e)
                        e_evt_bs = e_evt_bs - ...
                            (numel(g_bs_after_eventEnd) - end_e);
                    end
                    
                    % t-profile of event of interest with added baseline
                    event_prof_t = t_prof_all(s_evt_bs:e_evt_bs);
                    % start and end of event in profile with adjusted
                    % duration of baseline
                    sE_inProfWithAdjustedBs = ...
                        double(imgNum_s-s_evt_bs);
                    if sE_inProfWithAdjustedBs<1
                        sE_inProfWithAdjustedBs = 1;
                    end
                    eE_inProfWithAdjustedBs = ...
                        double(imgNum_e-s_evt_bs+1);
                    if eE_inProfWithAdjustedBs<=sE_inProfWithAdjustedBs
                        eE_inProfWithAdjustedBs = sE_inProfWithAdjustedBs+1;
                    end
                    if eE_inProfWithAdjustedBs>numel(t_prof_s)
                        eE_inProfWithAdjustedBs = numel(t_prof_s);
                    end
                    
                    % x-profile of event
                    % mask to find maximum in profile, 
                    % to be sure it is in part of detected event, 
                    % just in case if there is outlier in baseline, 
                    % which was smoothed out
                    m_event = -inf(size(event_prof_t));
                    m_event(sE_inProfWithAdjustedBs:...
                        eE_inProfWithAdjustedBs) = 1;
                    [~, max_pos_tprof] = max(event_prof_t.*m_event);
                    % frame to calculate FWHMs of event from
                    maxOfEventFrame = max_pos_tprof + s_evt_bs - 1;
                    ind_boundary = maxOfEventFrame-imgNum_s+1;
                    if ind_boundary<1
                        ind_boundary=1;
                    elseif ind_boundary>numel(eventBoundary{1})
                        ind_boundary=numel(eventBoundary{1});
                    end
                    
                    BW_event = poly2mask( ...
                        eventBoundary{1}{ind_boundary}(:,2), ...
                        eventBoundary{1}{ind_boundary}(:,1), ...
                        size(app.data.imgData, 1), ...
                        size(app.data.imgData, 2));
                    
                    % get max of event
                    imgE_frame = double(app.data.imgData(:,:,maxOfEventFrame));
                    maxEventVal = max(imgE_frame(BW_event));
                    %keyboard
                    thr_event = prctile(imgE_frame(BW_event), 50);
                    % expand mask of event by 1 um, to have decent baseline
                    BW_event_bs = imdilate(BW_event, ...
                        strel('disk', ceil(1/app.data.pxSz_x)));
                    % make sure it is not outside of cell
                    BW_cell = app.data.cellMask(:,:,maxOfEventFrame);
                    BW_event_bs = BW_event_bs & BW_cell;
                    % get image of expanded event
                    eventProps = regionprops(BW_event_bs, ...
                        app.data.imgData(:,:,maxOfEventFrame), ...
                        'SubarrayIdx','Image','Area');
                    [~,idxMaxArea] = max([eventProps.Area]); % just in case there are for some reson 2
                    eventProps = eventProps(idxMaxArea);
                    % get event data to fit with 2D gaussian; image of
                    % event in its maximum amplitude 
                    % (position taken from t-profile of event)
                    maxEventImgData = double(app.data.imgData( ...
                        eventProps.SubarrayIdx{1}, ...
                        eventProps.SubarrayIdx{2}, ...
                        maxOfEventFrame));     
                    
                    % check if there is anothother event 
                    % after expanding baseline area of selected event
%                     maxEventImgData_S = imgaussfilt( ...
%                         maxEventImgData, 1);
                    % thresholded image mask
                    maxEventImgData_S_thr_bw = ...
                        maxEventImgData>thr_event;
                    % maybe also do watershed, just in case there are
                    % events very close to each other
                    
                    % find thresholded regions    
                    eventProps_thr = regionprops(...
                        maxEventImgData_S_thr_bw, maxEventImgData, ...
                        'MaxIntensity','PixelIdxList','PixelList');   
                    
                    [~,ind_E] = min(abs([eventProps_thr.MaxIntensity]-maxEventVal));
                    % change values of thresholded regions to nan,
                    % except the one we want to analyze/fit
                    maxEventImgData_S_thr = double(maxEventImgData_S_thr_bw);
                    for r=1:numel(eventProps_thr)
                        if r==ind_E
                            continue
                        else
                            maxEventImgData_S_thr( ...
                                eventProps_thr(r).PixelIdxList) = nan;
                        end
                    end
                    
                    % fit t-profile of event
                    switch tProfFitFun
                        case {'EMG', 'mean'}
                            piecewiseFit = 'no';
                        otherwise
                            piecewiseFit = 'yes';
                    end
                    eventProfFit = app.fitEventProfile( ...
                        app.data.t(s_evt_bs:e_evt_bs), ...
                        event_prof_t, ...
                        sE_inProfWithAdjustedBs, ...
                        eE_inProfWithAdjustedBs, ...
                        piecewiseFit, tProfFitFun, smoothSpan);
 
                    % fit xy-profile of event
                    % do 2D Gaussian fit
                    eventMaxImgFit = app.fitEventImage( ...
                        maxEventImgData, ...
                        maxEventImgData_S_thr, ...
                        wP);
                    
                    % calculate parameters of event from t-profile
                    eP_tProf = ...
                        app.getEventParamsFromTimeProf( ...
                        eventProfFit.x_ups, ...
                        eventProfFit.yFit_ups, ...
                        eventProfFit.bs, ...
                        eventProfFit.t0, ...
                        true);
                    
                    waveSpeed = nan;
                    
                    
                case 'wave'
                    % global event, wave is traveling through the cell
                    % calculate wave speed using centroids (c) and also cross
                    % corelation (cc)
                    w_sp_c = zeros(1,numel(eventBoundary{1})-1);
                    w_sp_cc = zeros(1,numel(eventBoundary{1})-1);
                    for i = 1:numel(eventBoundary{1})-1
                        % from positions of centroids
                        d_centr = pdist2(centr_all_frames(i,:), ...
                            centr_all_frames(i+1,:))*app.data.pxSz_x;
                        w_sp_c(i) = (d_centr/app.data.pxSzT)*1000;  % in µm/s
                        
                    end
                    
                    % create kymograph and pseudo linescans
                    % mean of y position of centroids
                    line_y_pos = round(mean(centr_all_frames(:,2)));
                    line_x_pos = round(mean(centr_all_frames(:,1)));
                    kymograph_x = zeros(numel(eventBoundary{1}),size(app.data.imgData,2));
                    kymograph_y = zeros(numel(eventBoundary{1}),size(app.data.imgData,1));
                    m_event_x = false(numel(eventBoundary{1}),size(app.data.imgData,2));
                    m_event_y = false(numel(eventBoundary{1}),size(app.data.imgData,1));
                    pseudoLineScanOfEvent_xt = zeros(numel(eventBoundary{1}),size(app.data.imgData,2));
                    pseudoLineScanOfEvent_yt = zeros(numel(eventBoundary{1}),size(app.data.imgData,1));
                    for i = 1:numel(eventBoundary{1})
                        BW_cell = app.data.cellMask(:,:,imgNum_s+i-1);
                        BW_event = poly2mask( ...
                            eventBoundary{1}{i}(:,2), ...
                            eventBoundary{1}{i}(:,1), ...
                            size(app.data.imgData, 1), ...
                            size(app.data.imgData, 2));
                        %                         % estimate rotation of cell
                        %                         props = regionprops(BW_cell, "Orientation");
                        %                         % rotate frame
                        %                         img_rot = imrotate(app.data.imgData(:,:,imgNum_s+i-1), ...
                        %                             -props.Orientation);
                        %                         BW_cell_rot = imrotate(BW_cell, ...
%                             -props.Orientation);
                        
                        img = app.data.imgData(:,:,imgNum_s+i-1);
                        img(~BW_cell) = nan;
                        % calculate average
                        %mean(img,1,'omitnan')
                        
                        kymograph_x(i,:) = prctile(img,90,1);
                        kymograph_y(i,:) = prctile(img,90,2);
                        m_event_x(i,:) = max(BW_event,[],1);
                        m_event_y(i,:) = max(BW_event,[],2);
                        % create pseudo linescan
                        pseudoLineScanOfEvent_xt(i,:) = img(line_y_pos,:);
                        pseudoLineScanOfEvent_yt(i,:) = img(:,line_x_pos);
                    end
                    % take only part of selected event (event mask)
%                     regionprops(m_event_x,'SubarrayIdx')
%                     kymograph_x
%                     eventBoundary 
%                     centr_all_frames
%                     D = pdist(centr_all_frames)

%                     % take only part where event was detected
%                     figure
%                     subplot(4,1,1)
%                     imagesc(kymograph_x)
%                     subplot(4,1,2)
%                     imagesc(m_event_x)
%                     subplot(4,1,3)
%                     imagesc(pseudoLineScanOfEvent_xt)
%                     subplot(4,1,4)
%                     imagesc(m_event_y)
                    
                    s_evt_bs = imgNum_s;
                    e_evt_bs = imgNum_e;
                    
                    waveSpeed = mean(w_sp_c);
                    
                    eventProfFit.fitModel = 'mean';
                    eventProfFit.fitFun = @mean;
                    eventProfFit.smoothSpan = 50;
                    eventProfFit.coeff = 0;
                    eventProfFit.x = zeros(10,1);
                    eventProfFit.y = zeros(10,1);
                    eventProfFit.tauR = nan;
                    eventProfFit.tauD = nan;
                    
                    eventMaxImgFit.fitModel = 'none';
                    eventMaxImgFit.fitFun = @(p,x,y) p(1).*x.*y;
                    eventMaxImgFit.k_th_powerOfWeights = 1;
                    eventMaxImgFit.coeff = 0;
                    eventMaxImgFit.Z = zeros(10);
                    eventMaxImgFit.eventImgMask = zeros(10);
                    [eventMaxImgFit.X, eventMaxImgFit.Y] = meshgrid((1:10),(1:10));
                    eventMaxImgFit.bs = 0;
                    eventMaxImgFit.halfMax = 0;
                    eventMaxImgFit.FWHMx = 0;
                    eventMaxImgFit.FWHMy = 0;
                    
                    eP_tProf.bs_t = 0;
                    eP_tProf.t0 = 0;
                    eP_tProf.t_max = 0;
                    eP_tProf.v_max = 0;
                    eP_tProf.half_max_t = 0;
                    eP_tProf.half_max_t_1 = 0;
                    eP_tProf.half_max_t_2 = 0;
                    eP_tProf.amplitude = 0;
                    eP_tProf.TTP = 0;
                    eP_tProf.FDHM = 0;
                    
            end

            % output
            % t-profile fit
            tProfFit.fitModel = eventProfFit.fitModel;
            tProfFit.fitFun = eventProfFit.fitFun;
            tProfFit.smoothSpan = eventProfFit.smoothSpan;
            tProfFit.coeff = eventProfFit.coeff;
            tProfFit.y = eventProfFit.y;
            tProfFit.x = eventProfFit.x;
            tProfFit.s_evt_bs = s_evt_bs;  % start and end of events with baseline
            tProfFit.e_evt_bs = e_evt_bs;
            % event image fit
            eventMaxImg2DFit.fitModel = eventMaxImgFit.fitModel;
            eventMaxImg2DFit.fitFun = eventMaxImgFit.fitFun;
            eventMaxImg2DFit.k_th_powerOfWeights = eventMaxImgFit.k_th_powerOfWeights;
            eventMaxImg2DFit.coeff = eventMaxImgFit.coeff;
            eventMaxImg2DFit.eventImg = eventMaxImgFit.Z;  
            eventMaxImg2DFit.eventImgMask = eventMaxImgFit.eventImgMask;
            eventMaxImg2DFit.x = eventMaxImgFit.X(1,:);  
            eventMaxImg2DFit.y = eventMaxImgFit.Y(:,1);
            switch eventType{1}
                case 'wave'
                    eventMaxImg2DFit.event_bs_rows = nan;
                    eventMaxImg2DFit.event_bs_cols = nan;
                otherwise
                    eventMaxImg2DFit.event_bs_rows = eventProps.SubarrayIdx{1};
                    eventMaxImg2DFit.event_bs_cols = eventProps.SubarrayIdx{2};
            end
            % supporting parameters of event, used to draw parameters
            % in profile  
            eventSupportParams.bs_t = eP_tProf.bs_t;  
            eventSupportParams.t0 = eP_tProf.t0;    
            eventSupportParams.t_max = eP_tProf.t_max;
            eventSupportParams.v_max = eP_tProf.v_max;    
            eventSupportParams.half_max_t = eP_tProf.half_max_t;
            eventSupportParams.half_max_t_1 = eP_tProf.half_max_t_1;
            eventSupportParams.half_max_t_2 = eP_tProf.half_max_t_2;
            eventSupportParams.bs_x = eventMaxImgFit.bs;
            eventSupportParams.half_max_xy = eventMaxImgFit.halfMax;  
            % parameters of event
            eventParams.amplitude = eP_tProf.amplitude;
            eventParams.TTP = eP_tProf.TTP;
            eventParams.FDHM = eP_tProf.FDHM;
            eventParams.tauRise = eventProfFit.tauR;
            eventParams.tauDecay = eventProfFit.tauD;
            eventParams.FWHMx = eventMaxImgFit.FWHMx;
            eventParams.FWHMy = eventMaxImgFit.FWHMy;
            eventParams.waveSpeed = waveSpeed;

            eventProfFits = struct( ...
                'tProfFit', tProfFit, ...
                'eventMaxImg2DFit', eventMaxImg2DFit, ...
                'eventSupportParams', eventSupportParams, ...
                'eventParams', eventParams);
           
%             % convert event boundary in to event mask
%             [X,Y] = meshgrid(linspace(x0,x1),linspace(y0,y1)) ;
%             idx = inpolygon(X(:),Y(:),x(k),y(k)) ;


        end
        
        
        function showSelectedEventInTableOfEvents(app, src, event)
            % mouse button 1:
            % - show selected event in image axis in table of events
            % - remove previously highlighted row
            % mouse button 3:
            % - option to change type of calcium event
            switch src.Type
                case 'patch'
                    switch event.Button
                        case 1
                            % remove highlight in table
                            removeStyle(app.UITable_events)

                            idx = find(str2double(src.Tag) == ...
                                app.UITable_events.Data.eventID);
                            % highlight row with selected event
                            s = uistyle('BackgroundColor','yellow');
                            addStyle(app.UITable_events, s, 'row', idx)

                            % save selected event
                            app.data.selectedEvent = app.UITable_events.Data(idx,:);
                            % scroll to highlighted row
                            scroll(app.UITable_events,'Row',idx)

                            % show profile of selected event
                            app.showProfilesAndFitsOfSelectedEvent()

                        case 3
                    end

                case 'uimenu'
                    switch src.Parent.Tag
                        case 'changeEventType'
                            % remove highlight in table
                            removeStyle(app.UITable_events)

                            % handle to current object
                            hCurrentPatchObj = gco(app.UIFigure);

                            idx = find(str2double(hCurrentPatchObj.Tag) == ...
                                app.UITable_events.Data.eventID);

                            % change type of event in table and also in saved
                            % matrix
                            app.data.detectedEvents.eventType( ...
                                app.data.detectedEvents.eventID == ...
                                str2double(hCurrentPatchObj.Tag) ) = {src.Text};

                            app.UITable_events.Data.eventType(idx) = {src.Text};

                            % highlight row with selected event
                            s = uistyle('BackgroundColor','yellow');
                            addStyle(app.UITable_events, s, 'row', idx)

                            % scroll to highlighted row
                            scroll(app.UITable_events,'Row',idx)

                            % save selected event
                            app.data.selectedEvent = app.UITable_events.Data(idx,:);

                        case 'contexmenu_exportEvent'
                            % export selected event
                            idx = find(app.data.selectedEvent.eventID == ...
                                app.data.detectedEvents.eventID);
                            % event data
                            allEventData = app.data.detectedEvents(idx, :);
                            evnt_rows = allEventData.fits_params_event.eventMaxImg2DFit.event_bs_rows;
                            evnt_cols = allEventData.fits_params_event.eventMaxImg2DFit.event_bs_cols;
                            s_evnt = allEventData.fits_params_event.tProfFit.s_evt_bs;
                            e_evnt = allEventData.fits_params_event.tProfFit.e_evt_bs;
                            try
                                event_imgData = app.data.imgData(...
                                    evnt_rows, evnt_cols, s_evnt:e_evnt);
                            catch
                                % rows and colls are nans
                                event_imgData = app.data.imgData(...
                                    :, :, s_evnt:e_evnt);
                            end
                            % export it as movie with added alpha channel
                            % for events and cell boundaries
                            [p,n,e] = fileparts(app.data.imgPath);
                            switch src.Text
                                case 'export event as TIFF'
                                    filePath = fullfile(p, ...
                                        sprintf('%s_eventID-%d_%s.tif', ...
                                        n, allEventData.eventID, ...
                                        allEventData.eventType{1}) );
                                    % where to save
                                    % get path to image
                                    [fn, fp] = uiputfile( ...
                                        {'*.tif'}, ...
                                        'save event', ...
                                        filePath);
                                    drawnow;
                                    figure(app.UIFigure);
                                    if fp==0
                                        return
                                    end
                                    % save to tif file
                                    app.saveDataToTIFFfile(...
                                        event_imgData, [], fullfile(fp,fn))

                                case 'export event as GIF'
                                    % create animated GIF
                                    filePath = fullfile(p, ...
                                        sprintf('%s_eventID-%d_%s.gif', ...
                                        n, allEventData.eventID, ...
                                        allEventData.eventType{1}) );
                                    % where to save
                                    % get path to image
                                    [fn, fp] = uiputfile( ...
                                        {'*.gif'}, ...
                                        'save event', ...
                                        filePath);
                                    drawnow;
                                    figure(app.UIFigure);
                                    if fp==0
                                        return
                                    end
                                    % save to gif file
                                    % get colormap
                                    map = feval( ...
                                        app.DropDown_lookuptable_events.Value, ...
                                        256);
                                    % save gif file
                                    event_imgData = im2uint8(event_imgData);
                                    for i = 1:size(event_imgData,3)
                                        if i == 1
                                            imwrite(event_imgData(:,:,i), ...
                                                map, ...
                                                fullfile(fp,fn), ...
                                                'gif', ...
                                                'LoopCount',Inf, ...
                                                'DelayTime',1/app.EditField_framesPerSec_events.Value);
                                        else
                                            imwrite(event_imgData(:,:,i), ...
                                                map, ...
                                                fullfile(fp,fn), ...
                                                'gif', ...
                                                'WriteMode','append', ...
                                                'DelayTime',1/app.EditField_framesPerSec_events.Value);
                                        end
                                    end                                    
                            end
                    end
            end
        end % end function


        function combineDetectedEventsInTime(app, ...
                propsOfDetectedEvents, frame, detectedEventType)

            % check if there are some events in frame
            if isempty(propsOfDetectedEvents{frame})
                return
            end

            % get current eventID
            if isempty(app.data.eventsIDs_types)
                eventID = 0;
            else
                eventID = app.data.eventsIDs_types{end,1};
            end

            %%%%%
         
            % do it separately, problem with different types, return
            % eventsIDs for each type and after sum them or make 4D
            % eventsIDs matrix, now skipping events

            % in every image mask go through all events
            for event = 1:numel(propsOfDetectedEvents{frame})
                % for each event for through all image masks to see if
                % the events are connected
                % assigne ID to each event, if some event masks were
                % already processed, skip them
                maskOfEventInFrame = false(size(app.data.eventsIDs,[1,2]));
                maskOfEventInFrame(propsOfDetectedEvents{frame}(event).PixelIdxList) = true;

                idOfEventInFrame = app.data.eventsIDs(:,:,frame);
                % if roi already has a number assigned skip it
                if unique(idOfEventInFrame(maskOfEventInFrame))~=0
                    continue
                else
                    % update event ID and assign it to ROI
                    eventID = eventID+1;
                    idOfEventInFrame(maskOfEventInFrame) = eventID;
                    app.data.eventsIDs(:,:,frame) = idOfEventInFrame;

                    % save ID and type of event
                    if strcmp(app.DropDown_detectionMethod.Value, ...
                            'threshold')
                        detectedEventType = 'NA';
                    end
                    app.data.eventsIDs_types = [app.data.eventsIDs_types; ...
                        {eventID, detectedEventType}];
                end

                % check overlap of events in following frames of xyt series
                % with current event in current frame
                for nextFrame = frame+1:size(app.data.eventsIDs, 3)

                    % for the deeper frames update maskOfEventInFrame
                    if nextFrame > size(app.data.eventsIDs, 3)
                        continue
                    end

                    % previous frame and event index to compare with
                    if nextFrame == frame+1 || isnan(event_in_prevFrame)
                        prevEventIdx = event;
                        event_in_prevFrame = event;
                    end

                    maskOfEventInPrevFrame = false(size(app.data.eventsIDs,[1,2]));
                    maskOfEventInPrevFrame(propsOfDetectedEvents{nextFrame-1}(event_in_prevFrame).PixelIdxList) = true;

                    % check overlap of current event in frame with
                    % position nextFrame with events in next frame and repeat until no
                    % significant overlap or end of series
                    rois_iou = zeros(numel(propsOfDetectedEvents{nextFrame}),1);
                    centr_events_dist = zeros(numel(propsOfDetectedEvents{nextFrame}),1);
                    for event_in_nextFrame = 1:numel(propsOfDetectedEvents{nextFrame})
                        % initialize masks of events in previous and
                        maskOfEventInNextFrame = false(size(app.data.eventsIDs,[1,2]));
                        maskOfEventInNextFrame(...
                            propsOfDetectedEvents{nextFrame}(event_in_nextFrame).PixelIdxList) = true;
                        % check overlap
                        roi_intersection = maskOfEventInPrevFrame & maskOfEventInNextFrame;
                        roi_union = maskOfEventInPrevFrame | maskOfEventInNextFrame;
                        
                        rois_iou(event_in_nextFrame) = sum(roi_intersection(:)) / ...
                            sum(roi_union(:));
                        %min(sum(maskOfEventInPrevFrame(:)), sum(maskOfEventInNextFrame(:)));

                        % check also Euclidean distance of centroids of
                        % events in subsequent frames, in micrometers
                        centrOfEventInPrevFrame = ...
                            propsOfDetectedEvents{nextFrame-1}(event_in_prevFrame).WeightedCentroid;
                        centrOfEventInNextFrame = ...
                            propsOfDetectedEvents{nextFrame}(event_in_nextFrame).WeightedCentroid;

                        centr_events_dist(event_in_nextFrame) = ...
                            pdist([centrOfEventInPrevFrame; centrOfEventInNextFrame]) * ...
                            app.data.pxSz_x;
                    end

                    % check if any overlaps are bigger than 20%
                    if any( rois_iou > 0.20 )
                        % find idx of maximum overlap between events
                        [~, idx_maxOverlap] = max(rois_iou);
                        % take the biggest overlap
                        maskOfEventInNextFrame = false(size(app.data.eventsIDs,[1,2]));
                        maskOfEventInNextFrame(...
                            propsOfDetectedEvents{nextFrame}(idx_maxOverlap).PixelIdxList) = true;
                        % update eventsIDs matrix, merge overlapping events
                        idOfEventInNextFrame = app.data.eventsIDs(:,:,nextFrame);

                        idOfEventInNextFrame(maskOfEventInNextFrame) = eventID;
                        
                        app.data.eventsIDs(:,:,nextFrame) = idOfEventInNextFrame;
                        
%                         %%%%%%%%%%%%%%%%%%%%%%%
%                         %TEST; CHECK THIS, IT IS SLOW, TRY TO SOLVE
%                         test_array = zeros([64,512,500],"uint32");
%                         test_array = app.data.eventsIDs;
%                         test_matrix = ones([64,512],"uint32");
%                         pos = double(5);
%                         
%                         tic 
%                         test_array(:,:,pos) = test_matrix;
%                         
%                         toc
%                         
%                         %%%%%%%%%%%%%%%%%%%%%%%
                        
                        
                        event_in_prevFrame = idx_maxOverlap;
                    else
                        % no overlap, this event has ended
                        event_in_prevFrame = nan;
                        break
                    end
                end
            end
         

        end % end function


        function profFit = fitEventProfile(app, ...
                x, y, sE, eE, ...
                piecewise, model, smoothSpan)
            % data: x, y
            % start and end of event: sE, eE
            if sE<1, sE=1; end
            if eE>numel(x), eE=numel(x); end
       
            x = x(:);
            y = y(:);
            % step in x
            dx = mean(diff(x));
            % upscale with dt ~ 0.5 ms
            x_ups = linspace( ...
                min(x), max(x), numel(x)*round(dx/0.5));
            x_ups = x_ups(:);
            % max of event profile
            [maxV, maxP] = max(y);
            % options for fmincon
            opt = optimoptions( 'fmincon', ...
                'TolFun',1e-9, 'TolX',1e-9, 'TolCon',1e-9,...
                'MaxIter',1000, 'MaxFunEvals',3000 );
            
            % do fitting
            try
                % estimates of p0 = [t0, bs]
                p0 = [x(sE), mean(y(1:sE))];

                % fit rise of event in time profile
                optFit = optimoptions('lsqnonlin', ...
                    'TolFun',1e-9, ...
                    'TolX',1e-9, ...
                    'MaxIter',1000, ...
                    'MaxFunEvals',3000, ...
                    'Display','off');

                funR = @(p,xR,yR) ((xR>=p(1)).*((1-exp(-(xR-p(1))./p(2))).*(p(3)) + p(4)) + ...
                    (xR<p(1)).*(p(4)))-yR;

                funR_eval = @(p,xR) (xR>=p(1)).*((1-exp(-(xR-p(1))./p(2))).*(p(3)) + p(4)) + ...
                    (xR<p(1)).*(p(4));

                funD = @(p,xD,yD) ( p(1).*exp(-(xD-p(2))/p(3)) + p(4) ) - yD;
                funD_eval = @(p,xD) ( p(1).*exp(-(xD-p(2))/p(3)) + p(4) );

                % rise part
                xR = x(1:maxP);
                yR = y(1:maxP);

                % correct for start of mask of event, also x start form 0
                if p0(1) > x(maxP) - dx
                    p0(1) = (maxP+sE-1)*dx - dx - 3;
                end

                if p0(1) < 0, p0(1) = 1; end

                p0Rise = [p0(1) 3 maxV p0(2)];  % [t0 tauR A bs]
                % fit rise
                try
                    coeffRise = lsqnonlin( ...
                        @(p)funR(p,xR,yR),...
                        p0Rise,...
                        [x(1),0,0,min(yR)], ...
                        [x(maxP) inf inf inf], ...
                        optFit);
                    t0 = coeffRise(1);
                    bs = coeffRise(4);
                catch
                    t0 = p0(1);
                    bs = p0(2);
                end
                % baseline at the end of event profile
                bs_end = mean(y(end-2:end));

                % estimate tauR
                % t0 in points, adjust for start of event
                t0_p = floor((t0-x(1))/dx);
                if t0_p<1, t0_p=1; end

                % check if tauR make sense
                try
                    if coeffRise(2) > max(xR(t0_p:end))-min(xR(t0_p:end))
                        % recalculate tauR
                        [~,p_tauR] = ...
                            min( abs( yR(t0_p:end) - (0.66*(maxV-bs)+bs) ) );
                        tauR_est = p_tauR*dx;
                    else
                        tauR_est = coeffRise(2);
                    end
                catch
                    % recalculate tauR
                    [~,p_tauR] = min( abs( yR(t0_p:end) - (0.66*(maxV-bs)+bs) ) );
                    tauR_est = p_tauR*dx;
                end

                % estimate tauD
                xD = x(maxP+1:end);
                yD = y(maxP+1:end);
                [~,p_tauD] = min( abs( yD - (0.33*(maxV-bs_end)+bs_end) ) );
                tauD_est = p_tauD*dx;

                % fit decay
                try
                    p0Decay = [maxV xD(1) tauD_est bs_end];
                    coeffDecay = lsqnonlin( ...
                        @(p)funD(p,xD,yD), ...
                        p0Decay,...
                        zeros(1,4), ...
                        ones(1,4).*inf,...
                        optFit);
                    tauDFitExp = coeffDecay(3);
                catch
                    tauDFitExp = tauD_est;
                end

                if isempty(tauDFitExp)
                    tauDFitExp = nan;
                end
                if isempty(tauR_est)
                    tauR_est = nan;
                end

                p0(1) = t0;
                p0(2) = bs;

                switch piecewise

                    case 'yes'

                        switch model
                            % here we used only spline, add more
                            case 'spline'
                                % coefficients: [t0, F01, spline coefficients]
                                nK = ceil( (max(x(x>p0(1))) - min(x(x>p0(1))))/(smoothSpan/2) );
                                splOrd = 4; % spline order, poly3
                                spl0  = spap2(nK, splOrd, x(x>p0(1)), y(x>p0(1)));  % first guess of spline coefficients
                                % newknt for a possibly better knot distribution
                                knots = newknt(spl0);
                                spl0  = spap2(knots, splOrd, x(x>p0(1)), y(x>p0(1)));
                                p0 = [p0(1), p0(2), spl0.coefs];
                                fitFun = @(p,x) (x<=p(1)).*p(2) + ...
                                    (x>p(1)).*fnval(spmak(spl0.knots,p(3:end)),x);
                                % coefficients
                                coef = p0;
                                tauD = tauDFitExp;
                                tauR = tauR_est;
                                % upscaled fit
                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = pchip(x,yFit,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end
                                bs = coef(2);
                                t0 = coef(1);

                            case '1expR1expD'
                                % Lacampagne et al, 1999
                                % coefficients: [t0, F01, tauR, A, t1, tauD, F02]
                                % fitFun = @(p,x) (x<p(1)).*p(2) + ...
                                %                 (x>=p(1) & x<=p(5)).*( p(2)+(1-exp(-(x-p(1))./p(3))).*p(4) ) + ...
                                %                 (x>p(5)).*( p(4).*(1-exp(-(p(5)-p(1))./p(3))).*exp(-(x-p(5))./p(6))+p(7) );
                                
                                % maybe add plato - Ardo's function
                                p0 = [t0, bs, tauR_est, ...
                                    maxV-bs, x(maxP), tauD_est, ...
                                    mean(y(end-2:end))];
                                
                                fitFun = @(p,x) (x<p(1)).*p(2) + ...
                                    (x>=p(1) & x<p(5)).*( p(2)+(1-exp(-(x-p(1))./p(3))).* (p(4)-p(2))) + ...
                                    (x>=p(5)).*( (p(4)-p(7)).*(1-exp(-(p(5)-p(1))./p(3))).*exp(-(x-p(5))./p(6))+p(7) );

                                % create function which will be optimize, return scalar
                                fitFunSum = @(p,x,y) sum( (y-fitFun(p,x)).^2 );

                                % refit with no bound
                                lb = zeros(size(p0));
                                ub = inf(size(p0));
                                coef = fmincon(@(p)fitFunSum(p,x,y),p0,[],[],[],[],lb,ub,[],opt);
                                
                                % upscaled fit
                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = fitFun(coef,x_ups);
                                    %yFit_ups = pchip(x,yFit,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end
                                
                                t0 = coef(1);
                                bs = coef(2);
                                tauR = coef(3);
                                tauD = coef(6);
                                
                        end

                          
                    case 'no'

                        switch  model

                            case 'Gauss'
                                % fit with gaussian
                                % coefficients: [F0, A, w, xc]
                                % F0-baseline, A-amplitude, w-width of gaussian, xc-center
                                % estimates of p0
                                p0 = [mean(y(1:sE)) maxV std(x(sE:eE)) x(maxP)];
                                % function definition
                                fitFun = @(p,x) p(1) + ...
                                    (p(2)./(p(3).*sqrt(pi/2))) .* exp( (-2.*(x-p(4)).^2)./(p(3).^2) );
                                % do fitting
                                % create function which will be optimize, return scalar
                                fitFunSum = @(p,x,y) sum( (y-fitFun(p,x)).^2 );
                                % fit
                                coef = fmincon( @(p)fitFunSum(p,x,y), ...
                                    p0, [], [], [], [], ...
                                    [min(y) min(y) dx min(x)],...
                                    [inf inf max(x)-min(x) max(x)], ...
                                    [], opt );
                                % coeff to save
                                bs = coef(1);
                                tauD = tauDFitExp;
                                tauR = tauR_est;
                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = fitFun(coef,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end


                            case 'EMG'
                                % fit with exponentially modified gaussian
                                % coefficients: [A, m, sd, tau, F0]
                                % estimates of p0
                                % baseline at the end of event profile
                                bs_end = mean(y(end-2:end));
                                [~,p_tauD] = min(abs(y(maxP+1:end)-(0.33*(maxV-bs_end)+bs_end)));
                                tauD_est = p_tauD*dx;
                                p0 = [maxV x(maxP) std(x(sE:eE)) tauD_est mean(y(1:sE))];
                                % function definition
                                fitFun = @(p,x) p(1).*exp(p(2)/p(4) + p(3)^2/(2*p(4)^2) - x./p(4)) .* ...
                                    cdf('Normal',x,p(2)+(p(3)^2/p(4)),p(3)) + p(5);
                                fitFunSum = @(p,x,y) sum( (y-fitFun(p,x)).^2 );
                                % fit
                                coef = fmincon(@(p)fitFunSum(p,x,y),p0,...
                                    [],[],[],[],zeros(size(p0)),ones(size(p0)).*inf,[],opt);
                                bs = coef(5);
                                tauD = coef(4);
                                tauR = tauR_est;
                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = fitFun(coef,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end


                            case 'spline'
                                % fit with spline

                                nK = ceil( (max(x)-min(x))/(dx*3) ); % fit every 1 um
                                splOrd = 3; % spline order, poly3
                                spl0  = spap2(nK, splOrd, x, y);  % first guess of spline coefficients
                                % newknt for a possibly better knot distribution
                                knots = newknt(spl0);
                                spl0  = spap2(knots, splOrd, x, y);

                                fitFun = @(p,x) fnval(spmak(spl0.knots,spl0.coefs),x);
                                coef = spl0.coefs;

                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = fitFun(coef,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end

                                bs = nan;
                                tauD = tauDFitExp;
                                tauR = tauR_est;


                            case 'mean'
                                fitFun = @mean;
                                coef = mean(y);
                                bs = mean(y);
                                tauD = nan;
                                tauR = nan;
                                yFit = ones(size(x)).*coef;
                                yFit_ups = ones(size(x_ups)).*coef;

                        end

                        t0 = nan;

                end

            catch ME
                % if fit could not be done, just do mean
                model = 'mean';
                fitFun = @mean;
                coef = mean(y);
                bs = mean(y);
                t0 = nan;
                tauD = nan;
                tauR = nan;
                yFit = ones(size(x)).*coef;
                yFit_ups = ones(size(x_ups)).*coef;
            end

            % create output structure
            profFit = struct( ...
                'fitModel',model,...
                'fitFun', fitFun, ...
                'smoothSpan', smoothSpan, ...
                'coeff',coef,...
                'bs',bs,...
                't0',t0,...
                'tauD',tauD,...
                'tauR',tauR,...
                'x',x,'y',y,...
                'yFit',yFit(:),...
                'x_ups',x_ups(:),...
                'yFit_ups',yFit_ups(:) ...
                );
        end % end function
        
        
        function imgEventFit = fitEventImage(app, imgE, m_imgE, wP)
            % fit elliptical Gaussian to event image
            % https://en.wikipedia.org/wiki/Gaussian_function
            % parameters - p: 
            % [1=A, 2=mu_x, 3=sd_x, 4=mu_y, 5=sd_y, 6=rot, 7=F0]
            % rotation in degrees
            fitFun = @(p,x,y) p(1) .* exp(-( ...
                ((cosd(p(6))^2)/(2*p(3)^2)+(sind(p(6))^2)/(2*p(5)^2)).*(x-p(2)).^2 + ...
                2.*(-sind(2*p(6))/(4*p(3)^2)+sind(2*p(6))/(4*p(5)^2)).*(x-p(2)).*(y-p(4)) + ...
                ((sind(p(6))^2)/(2*p(3)^2)+(cosd(p(6))^2)/(2*p(5)^2)).*(y-p(4)).^2 )) ...
                + p(7);
            
            % imgE = event matrix to fit
            % m_imgE = mask of event; 
            % 1=baseline, >1=event to fit, 0=areas not to fit 
            % pixel size in x and y is the same
            % estimation of initial parameters of 2D gauss
            % baseline
            % set weights for fitting with 2D gauss = wE
            % weights are powered to wP = (1,2,3,...)
            % baseline=1, regions not to fit=0, event=1+event data points

            wE = m_imgE.*imgE;
            wE = wE+1;
            wE(isnan(wE)) = 0;
            wE = wE.^wP;

            bs = prctile(imgE(wE==1),25);

            % maximum
            maxImg = max(imgE(wE>1),[],'all');
            [max_r, max_c] = find(imgE==maxImg, 1, 'first');
            % get rows and columns of event to estimate std_x and std_y
            [r,c] = find(wE>1);
            % initial params
            % amplitude
            A = maxImg - bs;
            mu_x = max_c*app.data.pxSz_x;
            std_x = std(unique(c))*app.data.pxSz_x;
            mu_y = max_r*app.data.pxSz_y;
            std_y = std(unique(r))*app.data.pxSz_y;
            rot_gauss = 0; % rotation in degrees
            
            p0 = [A mu_x std_x mu_y std_y rot_gauss bs];
            % lower and upper bounds
            lb = zeros(size(p0));
            lb(1) = min(imgE,[],'all');
            lb(6) = -inf;
            lb(7) = min(imgE,[],'all');
            ub = inf(size(p0));
            % grids in micrometers
            [X,Y] = meshgrid((0:size(imgE,2)-1).*app.data.pxSz_x, ...
                (0:size(imgE,1)-1).*app.data.pxSz_y);
            
            % sum of squares, scalar
            fitFunSum = @(p,X,Y,D,wE) sum( sum( wE.*(D - fitFun(p,X,Y)).^2 ) );
            
            % options for fmincon
            opt = optimoptions('fmincon', ...
                'TolFun',1e-9, ...
                'TolX',1e-9, ...
                'TolCon',1e-9,...
                'MaxIter',1000, ...
                'MaxFunEvals',3000, ...
                'UseParallel',false);
            try
                coef2DGauss = fmincon(@(p)fitFunSum(p,X,Y,imgE,wE), ...
                    p0,[],[],[],[],lb,ub,[],opt);
            catch
                % estimate FWHM from p0
                coef2DGauss = p0;
            end

            % CHECK IF IT IS CORRECT
            % get FWHM parameter; in um
            FWHMx = 2*sqrt(2*log(2))*coef2DGauss(3); 
            FWHMy = 2*sqrt(2*log(2))*coef2DGauss(5); 
            if FWHMx>(max(X,[],'all')-min(X,[],'all'))
                FWHMx = 2*sqrt(2*log(2))*std_x;
            end
            if FWHMy>(max(Y,[],'all')-min(Y,[],'all'))
                FWHMy = 2*sqrt(2*log(2))*std_y; 
            end
            
            halfMax = coef2DGauss(7) + ...
                (max(fitFun(coef2DGauss,X,Y),[],'all') - coef2DGauss(7))/2;

%             F = fitFun(coef2DGauss,X,Y);
%             figure
%             mesh(X,Y,imgE,'EdgeColor','k','FaceColor','none')
%             hold on
%             surf(X,Y,F,'EdgeColor','none','FaceAlpha',0.75)
%             [M,c] = contour3(X, Y, F, ...
%                 [halfMax,halfMax], ...
%                 'LineWidth',2, ...
%                 'LineColor','k');
            
            % save results
            imgEventFit = struct( ...
                'fitModel', 'ellipticalGaussian', ...
                'fitFun', fitFun, ...
                'k_th_powerOfWeights', wP, ...
                'coeff', coef2DGauss,...
                'bs', coef2DGauss(7),...
                'halfMax', halfMax, ...
                'FWHMx', FWHMx, ...
                'FWHMy', FWHMy, ...
                'X', X, ...
                'Y', Y, ...
                'Z', imgE, ...
                'eventImgMask', wE, ...
                'gaussFit', fitFun(coef2DGauss,X,Y)...
                );

        end % end function


        function eventParams = getEventParamsFromTimeProf(app, ...
                t, t_prof, bs_t, t0, normalized)
            % bs = baseline
            % t0 = start of spark
            % normalized = true or false
            % profile should not be normalized

            t_indx = (1:1:length(t));
            t_indx = t_indx(:);
            
            if normalized
                Ampl = max(t_prof)-bs_t;
            else
                % (F-F0)/(F0-blank)
                Ampl = (max(t_prof)-bs_t)/(bs_t-blank);
            end

            %% time parameters
            % search for peak
            try
                %[val_t,pos_t] = max(t_prof);
                [pks_t,locs_t,w_t,~] = findpeaks(t_prof,t,...
                    'SortStr','descend');
                val_t = pks_t(1);
                pos_t = round((locs_t(1)-t(1))/mean(diff(t)));
            catch
                pks_t = t_prof(1);
                locs_t = 1;
                w_t = 0;
                val_t = t_prof(1);
                pos_t = 1;
            end

            try
                % half maximum value
                half_max_t = (val_t - bs_t)*0.5 + bs_t;
                max_t_25perc = (val_t - bs_t)*0.25 + bs_t;
                max_t_75perc = (val_t - bs_t)*0.75 + bs_t;

                % parts of profiles before and after maximum of event
                y_t1 = t_prof(1:pos_t-1);
                y_t2 = [ zeros(pos_t-1,1); t_prof(pos_t:end) ];

                % find position of first half max position
                y_t1_r = flipud(y_t1);
                d_y_t1 = gradient(y_t1_r);

                if all(d_y_t1==0)
                    pos_25 = numel(d_y_t1);
                    pos_75 = numel(d_y_t1);
                    pos_50_1 = numel(d_y_t1);
                else
                    indDer1 = find( d_y_t1<=0 & y_t1_r<max_t_25perc,1,'first');

                    if ~isempty(indDer1)
                        y_t1_r(indDer1:end) = 0;
                    end

                    y_t1 = flipud(y_t1_r);

                    [~,pos_50_1] = min(abs(y_t1 - half_max_t));
                    % find also positions of 25 and 75% of amplitude
                    [~,pos_25] = min(abs(y_t1 - max_t_25perc));
                    [~,pos_75] = min(abs(y_t1 - max_t_75perc));
                end

                % find position of second half max position
                d_y_t2 = gradient(y_t2);

                indDer2 = find( d_y_t2>=0 & y_t2<half_max_t & t_indx(1:end)>pos_t,1,'first');
                if ~isempty(indDer2)
                    y_t2(indDer2:end) = 0;
                end

                [~,pos_50_2] = min(abs(y_t2 - half_max_t));

                % positions in time
                half_max_t_1 = t(pos_50_1);
                half_max_t_2 = t(pos_50_2);

                FDHM = half_max_t_2 - half_max_t_1;

            catch
                FDHM = w_t(1);
                half_max_t = nan;
                half_max_t_1 = nan;
                half_max_t_2 = nan;
                pos_25 = nan;
                pos_75 = nan;
            end

            % find t0 (start of spark) as a cross section of 0 and line fit of part
            % of event with amplitudes between 25 and 75 % of max amplitude

            if pos_25 == pos_75
                pos_25 = pos_25 - 1;
            end

            if isempty(t0) || isnan(t0)
                try
                    f_line25_75 = fit(t(pos_25:pos_75),t_prof(pos_25:pos_75),'poly1');
                    t_fit = t(find(t_prof>bs_t,1,'first'):pos_t);
                    line25_75 = feval(f_line25_75,t_fit);
                    t0 = t_fit(find(line25_75<bs_t,1,'last'));
                catch
                    % find t0, start of spark, 0.05 set manually, not really nice
                    t0 = t( find(t_prof > ((val_t-bs_t)/50 + bs_t), 1, 'first'));
                end

                if isempty(t0)
                    % find t0, start of spark, 0.05 set manually, not really nice
                    t0 = t( find(t_prof > ((val_t-bs_t)/50 + bs_t),1,'first'));
                end

            end

            % get time to peak
            TTP = t(pos_t) - t0;
            if TTP<0
                TTP=0;
            elseif isempty(TTP)
                TTP=0;
            end

            %calculating spark mass
            %sparkMass = Ampl*1.206*FWHM^3;

            eventParams.amplitude = Ampl;
            eventParams.TTP = TTP;
            eventParams.FDHM = FDHM;
            eventParams.bs_t = bs_t;
            eventParams.t0 = t0;
            eventParams.t_max = t(pos_t);
            eventParams.v_max = val_t;
            eventParams.half_max_t = half_max_t;
            eventParams.half_max_t_1 = half_max_t_1;
            eventParams.half_max_t_2 = half_max_t_2;
            
        end % end function
        
        

        function showProfilesAndFitsOfSelectedEvent(app)
            % get selected event from uitable of events
            selectedEvent = app.data.selectedEvent;
            % get data and fits
            idx = find(selectedEvent.eventID == ...
                app.data.detectedEvents.eventID);
            selectedEventAll = app.data.detectedEvents(idx,:);
            eventFits_data = selectedEventAll.fits_params_event;
  
            % data
            % t-profile
            tProf = eventFits_data.tProfFit.y;
            t = eventFits_data.tProfFit.x;
            % upscale with dt ~ 0.5 ms
            t_ups = linspace( ...
                min(t), max(t), numel(t)*round(mean(diff(t))/0.5));
            t_ups = t_ups(:);
            if numel(t) == 1
                t_ups = t(1);
            end
            try
                switch eventFits_data.tProfFit.fitModel
                    case 'spline'
                        % first create on recorded time
                        tProfFit = eventFits_data.tProfFit.fitFun( ...
                            eventFits_data.tProfFit.coeff, t);
                        % upscaled fit, use pchip to avoid some problems
                        % around t0
                        tProfFit = pchip(t,tProfFit,t_ups);
                    otherwise
                        tProfFit = eventFits_data.tProfFit.fitFun( ...
                            eventFits_data.tProfFit.coeff, t_ups);
                end
            catch
                % vector of size t_ups, same values of mean of data
                tProfFit = ones(size(t_ups)) .* ...
                    eventFits_data.tProfFit.coeff;
            end
          
            % 2D event max image fit
            eventImg = eventFits_data.eventMaxImg2DFit.eventImg;
            [X,Y] = meshgrid( ...
                eventFits_data.eventMaxImg2DFit.x, ...
                eventFits_data.eventMaxImg2DFit.y);           
            eventImgFit = ...
                eventFits_data.eventMaxImg2DFit.fitFun( ...
                eventFits_data.eventMaxImg2DFit.coeff, X, Y);
            % event support parameters
            bs_t = eventFits_data.eventSupportParams.bs_t;
            t0 = eventFits_data.eventSupportParams.t0;
            t_max = eventFits_data.eventSupportParams.t_max;
            v_max = eventFits_data.eventSupportParams.v_max;
            half_max_t = eventFits_data.eventSupportParams.half_max_t;
            half_max_t_1 = eventFits_data.eventSupportParams.half_max_t_1;
            half_max_t_2 = eventFits_data.eventSupportParams.half_max_t_2;
            bs_x = eventFits_data.eventSupportParams.bs_x;
            half_max_xy = eventFits_data.eventSupportParams.half_max_xy;
            % event parameters
            tauR = selectedEventAll.tauRise;
            tauD = selectedEventAll.tauDecay;
            amplitude = selectedEventAll.amplitude;
            TTP = selectedEventAll.TTP;
            FDHM = selectedEventAll.FDHM;
            FWHMx = selectedEventAll.FWHMx;
            FWHMy = selectedEventAll.FWHMy;
            waveSpeed = selectedEventAll.waveSpeed;
  
            % show profiles with fits
            % clear axes
            cla(app.UIAxes_eventTprof)
            cla(app.UIAxes_eventXYprof)

            % show t-profile with fit and params
            line(app.UIAxes_eventTprof, ...
                t, tProf, ...
                'Color', 'k', 'LineStyle', 'none', ...
                'Marker', 'o', 'Display','data')
            line(app.UIAxes_eventTprof, ...
                t_ups, tProfFit, ...
                'Color', 'r', 'LineStyle', '-', ...
                'Display','fit')
            line(app.UIAxes_eventTprof, ...
                [half_max_t_1 half_max_t_2], ...
                [half_max_t half_max_t],...
                'Color','b', 'LineWidth',2,...
                'Display','FDHM')
            line(app.UIAxes_eventTprof, ...
                [t_max t_max], ...
                [bs_t v_max], ...
                'Color','g', 'LineWidth',2, ...
                'Display','amplitude')
            line(app.UIAxes_eventTprof, ...
                [t0 t_max], ...
                [bs_t bs_t], ...
                'Color','m', 'LineWidth',2, ...
                'Display','TTP')
            % set up axis limits and labels
            hleg = legend( ...
                app.UIAxes_eventTprof,'show');
            hleg.Location = 'best';
            hleg.FontSize = 12;
            try
            set(app.UIAxes_eventTprof, ...
                'XLim', [min(t), max(t)], ...
                'YLim', [min([tProf(:);tProfFit(:)])*0.95, ...
                max([tProf(:);tProfFit(:)])*1.05]);
            catch
            end
            app.UIAxes_eventTprof.Title.String = ...
                sprintf('t-profile; %s; ID=%d', ...
                selectedEvent.eventType{1}, ...
                selectedEvent.eventID);

            % show xy profiles and elliptical Gaussian fit
            mesh(app.UIAxes_eventXYprof, ...
                X, Y, eventImg, ...
                'EdgeColor','k', ...
                'FaceColor','none', ...
                'Display','data')
            hold(app.UIAxes_eventXYprof,'on')
            surf(app.UIAxes_eventXYprof, ...
                X, Y, eventImgFit, ...
                'EdgeColor','none', ...
                'FaceAlpha',0.7, ...
                'Display','Gaussian fit')
            colormap(app.UIAxes_eventXYprof, 'parula')
            % show ellipse, FWHM
            contour3(app.UIAxes_eventXYprof, ...
                X, Y, eventImgFit, ...
                [half_max_xy, half_max_xy], ...
                'LineWidth',2, ...
                'LineColor','r', ...
                'Display','FWHM ellipse')
            % set view
            view(app.UIAxes_eventXYprof,20,-20)
            % legend
            hleg2 = legend( ...
                app.UIAxes_eventXYprof,'show');
            hleg2.Location = 'best';
            hleg2.FontSize = 10;

            % show parameters in table in gui
            app.UITable_eventParams.Data = ...
                [amplitude, ...
                TTP, FDHM, ...
                tauR, tauD, ...
                FWHMx, FWHMy, ...
                waveSpeed];
         
            % show values used in refit panel
            app.DropDown_fitFunSelection.Value = ...
                eventFits_data.tProfFit.fitModel;
            app.EditField_smoothParam.Value = ...
                eventFits_data.tProfFit.smoothSpan;
            app.EditField_weightsPower2DGaussFit.Value = ...
                eventFits_data.eventMaxImg2DFit.k_th_powerOfWeights;
            
        end
        
        
        function saveSelectedAxesToFiles(app, ...
                ~, ~, hf, h_cbx, h_dm, h_ax, dm_res, dm_contentType)
            % h_cbx = array of checkboxes
            % h_dm = array of dropdownmenus
            % h_ax = array of axes
            % dm_res = resolution of output
            % dm_contentType = content of axes

            % ask in which folder to save
            [imgPath,imgName,~] = fileparts(app.data.imgPath);
            dirPath = uigetdir(imgPath);

            % show progress
            progressBar_h = uiprogressdlg(hf, ...
                'Title', 'Please Wait',...
                'Message', 'Saving contents of axes in to files...', ...
                'Indeterminate', 'off');
            % save selected axes
            for i = 1:numel(h_ax)
                if h_cbx(i).Value
                    % update value of progress bar
                    progressBar_h.Value = i/numel(h_ax);
                    progressBar_h.Message = ...
                        sprintf('Saving contents of the axes #%d',i);

                    % export axes to file with selected format
                    fileToSave = fullfile( ...
                        dirPath, ...
                        [imgName, '_', h_ax(i).Tag, h_dm(i).Value]);
                    if exist(fileToSave, 'file')
                        delete(fileToSave)
                    end
                    % save
                    exportgraphics(h_ax(i), ...
                        fileToSave, ...
                        'Resolution',str2double(dm_res.Value), ...
                        'ContentType',dm_contentType.Value)
                end
            end
            % after saving delete figure with selection
            delete(hf)
        end
        
        
        function c = cellMaskPatchColor(app)
            % return RGB color for patch object, based on used colormap
            % find out which tab is selected and get colormap of image
            % series
            switch app.TabGroup.SelectedTab.Tag
                case 'detectionTab'
                    cmap = app.DropDown_lookuptable.Value;
                case  'analysisTab'
                    cmap = app.DropDown_lookuptable_events.Value;
            end
            
            switch cmap
                case {'jet', 'parula', 'turbo'}
                    c = [1 1 1];
                case 'hot'
                    c = [0 1 1];
                case 'gray'
                    c = [0 1 0];
            end
        end

        
        function positionAndSizeOfcellMaskROI(app, hO, event)
            % get position of cursor in figure relatively to axes
            curPos = get(app.UIAxes_img, 'CurrentPoint');
            % handle to roi
            if isgraphics(app.data.cellMaskCorr.cellMaskCorrectionROI)
                cellMaskROI = app.data.cellMaskCorr.cellMaskCorrectionROI;
            else
                return
            end
            % check if cursor is above the axes
            xCurPos = curPos(1,1);
            yCurPos = curPos(1,2);
            if (xCurPos<=app.UIAxes_img.XLim(2)) && ...
                    (xCurPos>=app.UIAxes_img.XLim(1)) && ...
                    (yCurPos<=app.UIAxes_img.YLim(2)) && ...
                    (yCurPos>=app.UIAxes_img.YLim(1))
                % update roi position or size
                switch event.EventName
                    case 'WindowMouseMotion'
                        cellMaskROI.Position = [...
                            xCurPos-cellMaskROI.Position(3)/2,...
                            yCurPos-cellMaskROI.Position(4)/2,...
                            cellMaskROI.Position(3),...
                            cellMaskROI.Position(4)
                            ];
                    
                    case 'WindowScrollWheel'
                        szROI = cellMaskROI.Position(3)+event.VerticalScrollCount;
                        if szROI<3
                            szROI=3;
                        elseif szROI>min(app.UIAxes_img.YLim(2),app.UIAxes_img.XLim(2))
                            szROI = min(app.UIAxes_img.YLim(2),app.UIAxes_img.XLim(2));
                        end
                        % set ROI size
                        cellMaskROI.Position = [...
                            xCurPos-cellMaskROI.Position(3)/2,...
                            yCurPos-cellMaskROI.Position(4)/2,...
                            szROI,...
                            szROI
                            ];

                end
                % add pause, not to overload
                pause(0.001)
            end

        end

        
        function setMaskCorrectionMode(app, selectedItem, event)
            % change color of mask corrections ROI
            % red color = remove mask
            switch selectedItem.Text
                case 'add mask'
                    app.data.cellMaskCorr.cellMaskCorrectionROI.Color = ...
                        app.cellMaskPatchColor;
                    app.data.cellMaskCorr.cellMaskCorrectionROI.Tag = 'addMask';

                case 'remove mask'
                    app.data.cellMaskCorr.cellMaskCorrectionROI.Color = ...
                        [1 0 0];
                    app.data.cellMaskCorr.cellMaskCorrectionROI.Tag = 'removeMask';

            end
        end

        
        function analyseImgSeriesInBatchMode(app, hObj, event, ...
                fig_batch, tbl_imgSeries_batch)
            % get data from uitable_batch
            dataTbl = tbl_imgSeries_batch.Data;
            dataTbl(~dataTbl.selected,:) = [];
            % delete fig_batch uifigure
            delete(fig_batch)
            % set flag for batch analysis
            app.data.batchMode = true;
            % add progress bar for batch mode,
            % add some time estimation as before, with hours
            % minutes and seconds
            batchProgressBar = uiprogressdlg(app.UIFigure, ...
                'Title','Processing selected image series in batch mode.', ...
                'Message','remaining time: ...', 'Cancelable','on', ...
                'ShowPercentage','on');
            % do batch analysis
            numOfImgSeries = height(dataTbl);
            % durations of iterations
            tIter = nan(numOfImgSeries,1);
            for i=1:height(dataTbl)
                % start counting time per iteration
                tStartIter = tic;
                % Check for Cancel button press
                if batchProgressBar.CancelRequested
                    close(batchProgressBar)
                    % set flag for batch analysis
                    app.data.batchMode = false;
                    app.data = rmfield(app.data, 'imgMetaDataInBatchMode');
                    % clear app content
                    clearAppContent(app, 'all')
                    return
                end

                % load image series
                app.data.imgPath = dataTbl.("image series path"){i};
                % save metadata of image; might be changed by user, so not
                % to reload it again with app.openImg function
                t = (dataTbl.("pxSz_t (ms)")(i) : ...
                    dataTbl.("pxSz_t (ms)")(i) : ...
                    dataTbl.("# images")(i)*dataTbl.("pxSz_t (ms)")(i)) - ...
                    dataTbl.("pxSz_t (ms)")(i);
                app.data.imgMetaDataInBatchMode.t = t(:);
                app.data.imgMetaDataInBatchMode.n_ch = dataTbl.("# channels")(i);
                app.data.imgMetaDataInBatchMode.pxSzT = dataTbl.("pxSz_t (ms)")(i);
                app.data.imgMetaDataInBatchMode.pxSz_x = dataTbl.("pxSz_x (um)")(i);
                app.data.imgMetaDataInBatchMode.pxSz_y = dataTbl.("pxSz_y (um)")(i);
                % simulate event structure after button click
                simButtonEvent = struct('Source', struct('Tag', 'menu_openImg'));
                app.openImg(simButtonEvent)

                % check if calculate cell mask and self ratio
                if app.CheckBox_findCellMaskInBatch.Value
                    % detect cell edge with checked parameters
                    app.findCellMask(simButtonEvent)
                end
                simButtonEvent = struct('Source', struct('Tag', 'detectEvents'));
                if app.CheckBox_calcSelfRatioInBatch.Value
                    app.calculate_selfRatio(simButtonEvent)
                end
                
                % detect calcium evens using selected method
                app.detectCalciumEvents(simButtonEvent)
                
                % save parameters of detected events
                app.saveAnalysis()
                
                % update progress bar
                batchProgressBar.Value = i/height(dataTbl);
                % get time per iteration, in seconds
                tIter(i) = toc(tStartIter);
                estimTimeOfFinish = mean(tIter,'omitnan')*(numOfImgSeries-i);
                % in hours
                estimTimeOfFinish_hour = [floor(estimTimeOfFinish/3600), ...
                    round(mod(estimTimeOfFinish,3600)/60)];
                % in minutes
                estimTimeOfFinish_min = [floor(estimTimeOfFinish/60), ...
                    round(mod(estimTimeOfFinish,60))];
                % in seconds
                estimTimeOfFinish_sec = round(estimTimeOfFinish);
                % update remaining time estimate
                if estimTimeOfFinish_hour(1) >= 1
                    batchProgressBar.Message = ...
                        sprintf('remaining time: %d hours and %d minutes', ...
                            estimTimeOfFinish_hour(1), ...
                            estimTimeOfFinish_hour(2));
                else
                    if estimTimeOfFinish_min(1) >= 1
                        batchProgressBar.Message = ...
                            sprintf('remaining time: %d minutes and %d seconds', ...
                            estimTimeOfFinish_min(1), ...
                            estimTimeOfFinish_min(2)); 
                    else
                        batchProgressBar.Message = ...
                            sprintf('remaining time: %d seconds', ...
                            estimTimeOfFinish_sec);
                    end
                end

            end
            % delete progress bar
            close(batchProgressBar)
            % set flag for batch analysis
            app.data.batchMode = false;
            app.data = rmfield(app.data, 'imgMetaDataInBatchMode');
            % clear app content
            clearAppContent(app, 'all')
        end
        

        function imgMetadata = getImgMetadataFromImgDescrStr(app, ...
                imgDescrStr, N_imgs)            
            % try to get image series metadata
            try
                pixels_meta = regexp(imgDescrStr, '(?<=Pixels).*?(?=</Pixels>)', 'match');
                pixels_meta = split(pixels_meta,{'<','>'});
                % remove lines without = sign
                pixels_meta = pixels_meta(~cellfun(@isempty,regexp(pixels_meta,'=')));
                % remove lines with TiffData and UUID
                pixels_meta = pixels_meta(cellfun(@isempty,regexp(pixels_meta,'TiffData|UUID')));

                % find line with PhysicalSizeX
                indx_pxSz = find(~cellfun(@isempty,regexp(pixels_meta,'PhysicalSizeX'))==1,1,'first');
                pixelSz_str = pixels_meta{indx_pxSz,1};
                % remove "
                pixelSz_str = erase(pixelSz_str,'"');
                pixelSz_str = split(pixelSz_str);
                % remove empty lines
                pixelSz_str = pixelSz_str(~cellfun(@isempty,pixelSz_str));
                % create structure
                pixelSz_struct = split(pixelSz_str, '=');
                pixelSz_struct = cell2struct(pixelSz_struct(:,2), pixelSz_struct(:,1));

                % number of channels
                imgMetadata.n_ch = str2double(pixelSz_struct.SizeC);

                % get time from images, delta T
                imgs_meta = pixels_meta(~cellfun(@isempty,regexp(pixels_meta,'Plane')));
                % remove " and /
                imgs_meta = erase(imgs_meta,'"');
                imgs_meta = erase(imgs_meta,'/');
                % split
                imgs_meta = split(imgs_meta);
                % remove empty lines
                imgs_meta = imgs_meta(all(~cellfun(@isempty,imgs_meta),2), :);
                % remove plane column
                imgs_meta(:,1) = [];

                % get time of images
                imgs_meta = split(imgs_meta, '=');
                idx_deltaT = find(strcmp(imgs_meta(1,:,1), 'DeltaT'));
                t = cellfun(@(x) str2double(x), imgs_meta(:,idx_deltaT,2));
                t = t - t(1);
                t = t(:);
                dt = mean(diff(t));
                if numel(t) ~= N_imgs
                    t = (dt:dt:N_imgs*dt) - dt;
                    t = t(:);
                end
                % in ms
                imgMetadata.t = t.*1000;
                imgMetadata.pxSzT = dt*1000;
                
                % dont do it in batch mode
                if ~app.data.batchMode
                    % check if dt is the same at least in range of miliseconds
                    % round on milisecond and use unique, if more than one throw
                    % a warning
                    timeSteps = unique( round(diff(t)*1000) );
                    if numel(timeSteps) > 1
                        uialert(app.UIFigure, ...
                            {'different duration of time steps (time rounded to nearest millisecond)', ...
                            sprintf( 'unique time steps: %s (ms)', ...
                            char(join( arrayfun(@(x) num2str(x), timeSteps, 'UniformOutput', 0), ', ' )))}, ...
                            'Warning', 'Icon', 'warning')
                    end
                end
                % get pixel size
                imgMetadata.pxSz_x = str2double(pixelSz_struct.PhysicalSizeX);
                imgMetadata.pxSz_y = str2double(pixelSz_struct.PhysicalSizeY);

            catch
                try
                    % split image description string in to lines
                    pixels_meta = splitlines(imgDescrStr);
                    % remove empty lines
                    pixels_meta( ...
                        cellfun(@(x) isempty(x), pixels_meta)) = [];
                    % find line with description of columns
                    % Frame Time ...
                    idx_frame = find( ...
                        cellfun(@(x) ~isempty(regexpi(x,'Frame', 'once')), ...
                        pixels_meta)) + 1;
                    pixels_meta_frames = pixels_meta(idx_frame:end);

                    t = zeros(numel(pixels_meta_frames),1);
                    for i=1:numel(pixels_meta_frames)
                        % process individual lines of metadata
                        pixels_meta_frame = regexpi( ...
                            pixels_meta_frames{i}, '\t', 'split');
                        t(i) = str2double(pixels_meta_frame{2});
                    end
                    t = t - t(1);
                    t = t(:);
                    dt = mean(diff(t));
                    if numel(t) ~= N_imgs
                        t = (dt:dt:N_imgs*dt) - dt;
                        t = t(:);
                    end
                    % in ms
                    imgMetadata.t = t.*1000;
                    imgMetadata.pxSzT = dt*1000;

                    % check info about pixel size
                    idx_pxSz = find( ...
                        cellfun(@(x) ~isempty(regexpi(x,'microns', 'once')), ...
                        pixels_meta));
                    if contains(pixels_meta(idx_pxSz), ...
                            'not calibrated')
                        imgMetadata.pxSz_x = 1;
                        imgMetadata.pxSz_y = 1;
                    else
                        keyboard
                        imgMetadata.pxSz_x = 1;
                        imgMetadata.pxSz_y = 1;
                    end

                    imgMetadata.n_ch = 1;

                catch
                    dt = 1;
                    t = (dt:dt:N_imgs*dt) - dt;
                    t = t(:);

                    imgMetadata.n_ch = 1;

                    imgMetadata.t = t;
                    imgMetadata.pxSzT = dt;

                    imgMetadata.pxSz_x = 1;
                    imgMetadata.pxSz_y = 1;
                end
            end


        end
        

        function changeValueOfMultipleSelectedCells(app, tbl, event)
            % change value of last selected cell to value of previous one
            % only in columns
            if size(event.Selection,1)>1 && numel(unique(event.Selection(:,2)))<2
                oldSelectionVal = tbl.Data{event.PreviousSelection(1,1), ...
                    event.PreviousSelection(1,2)};
                idxNewSelectedCell = ...
                    ~ismember(event.Selection, event.PreviousSelection, 'rows');
                tbl.Data{event.Selection(idxNewSelectedCell,1), ...
                    event.Selection(idxNewSelectedCell,2)} = oldSelectionVal;
            end
           
        end

        
        function clearAppContent(app, flag)
            % clear content in app
            if ~exist('flag', 'var')
                flag = '';
            end
            cla(app.UIAxes_img_events)
            cla(app.UIAxes_eventTprof)
            cla(app.UIAxes_eventXYprof)
            cla(app.UIAxes_F0)
            cla(app.UIAxes_avrgCellSignal)

            % remove previously highlighted row
            removeStyle(app.UITable_events)
            % set checkbox to show all detected events
            app.CheckBox_showSelectedEvent.Value = false;
            app.UITable_eventParams.Data = {};
            app.UITable_events.Data = {};

            if strcmp(flag, 'all')
                cla(app.UIAxes_img)
                app.UITable_imgMeta.Data = {};
            end
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow
        end


        function [mask_sparks, mask_puffs, mask_waves] = ...
                detectEvents_threshold(app)
            % Miguel's approach
            % get data
            imgData = double(app.data.imgData);
            cellMask = app.data.cellMask;
            n_imgs = size(imgData,3);
            x = (1:n_imgs);
            x = x(:);
            % filter data
            H = fspecial('average',[3 3]);
            imgDataFilt = imgData;
            for i=1:size(imgData,3)
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
            end

            thr_1 = zeros(n_imgs,1);
            for i=1:n_imgs
                img = imgDataFilt(:,:,i);
                % first threshold for individual frames
                thr_1(i) = median(img(cellMask(:,:,i))) + ...
                    2.5*mad(img(cellMask(:,:,i)), 1);
            end

            % fit with polynomial model to avoid outliers
            thr_1_fit = feval( fit(x, thr_1, 'poly5', ...
                'Normalize','on', 'Robust','Bisquare'), (1:n_imgs));

            %                     figure
            %                     plot(thr_1, 'ok')
            %                     hold on
            %                     plot(thr_1_fit,'r')

            % exclude areas in cell higher than threshold, update
            % mask of cell
            cellMask_thr_1 = false(size(cellMask));
            for i=1:n_imgs
                cellMask_thr_1(:,:,i) = cellMask(:,:,i) & ...
                    (imgDataFilt(:,:,i)<thr_1_fit(i));
            end

            % repeat calculation of threshold from cell without
            % areas higher than threshold 1
            thr_2 = zeros(n_imgs,1);
            for i=1:n_imgs
                img = imgDataFilt(:,:,i);
                % first threshold for individual frames
                thr_2(i) = mean(img(cellMask_thr_1(:,:,i))) + ...
                    2.5*std(img(cellMask_thr_1(:,:,i)));
                if isnan(thr_2(i))
                    % take previous if possible
                    try
                        thr_2(i) = thr_2(i-1);
                        thr_2(i) = max(max(imgDataFilt(:,:,i)));
                    catch
                        % take maximum of image, should not detect
                        % anything
                        thr_2(i) = max(max(imgDataFilt(:,:,i)));
                    end

                end

            end

            % fit again with polynomial model to avoid outliers
            thr_2_fit = feval( fit(x, thr_2, 'poly5', ...
                'Normalize','on', 'Robust','Bisquare'), (1:n_imgs));

            %                     figure
            %                     plot(thr_2, 'ok')
            %                     hold on
            %                     plot(thr_2_fit,'r')

            % find masks of events, areas with signal higher than thr_2_fit
            % save mask as only mask sparks, this method does not
            % provide type of event
            mask_sparks = false(size(cellMask));
            for i=1:n_imgs
                mask_sparks(:,:,i) = cellMask(:,:,i) & ...
                    (imgDataFilt(:,:,i)>=thr_2_fit(i));
            end
            mask_puffs = false(size(imgDataFilt));
            mask_waves = false(size(imgDataFilt));
            %                     figure
            %                     subplot(3,1,1)
            %                     imagesc(imgData(:,:,30))
            %                     subplot(3,1,2)
            %                     imagesc(cellMask_thr_1(:,:,30))
            %                     subplot(3,1,3)
            %                     imagesc(mask_sparks(:,:,30))
        end


        function [mask_sparks, mask_puffs, mask_waves] = ...
                detectEvents_unet(app)
            % get data
            imgData = app.data.imgData;
            cellMask = app.data.cellMask;
            n_imgs = size(imgData,3);
            try
                % check system
                if ismac
                    % Code to run on Mac platform
                    % path to conda environment
                    pythonPath = fullfile(...
                        app.EditField_pathToConda.Value, ...
                        'bin','python');
                    % adding the folder with libraries to PATH
                    folder_with_lib = fullfile(...
                        app.EditField_pathToConda.Value, ...
                        'lib');
                elseif isunix
                    % Code to run on Linux platform
                    
                elseif ispc
                    % Code to run on Windows platform
                    % path to conda environment
                    pythonPath = fullfile(...
                        app.EditField_pathToConda.Value, ...
                        'pythonw.exe');
                    % adding the folder with the DLLs to PATH
                    folder_with_lib = fullfile(...
                        app.EditField_pathToConda.Value, ...
                        'Library', 'bin');
                else
                    uialert(app.UIFigure, ...
                        'Platform not supported!', ...
                        'error')
                    return
                end

                % username = getenv('username');
                setenv('PYTHONUNBUFFERED','1'); % doesn't change much, but just-in-case
                % Setting PYTHONUNBUFFERED to a non empty value ensures
                % that the python output is sent straight to terminal (e.g. your container log)
                % without being first buffered and that you can see the output of your application (e.g. django logs) in real time.
                % This also ensures that no partial output is held in a buffer somewhere and never written in case the python application crashes.
                setenv('path',[folder_with_lib, pathsep, getenv('path')]);
                % set python environment
                % check if selected conda environment is xytCaSignals
                [~, env_n, ~] = fileparts(app.EditField_pathToConda.Value);
                if ~strcmp(env_n,'xytCaSignals')
                    uialert(app.UIFigure, ...
                        'Wrong conda environmen!', ...
                        'Wrong Path')
                else
                    % might be due to difference between PC and mac
                    try
                        pe = pyenv('Version',pythonPath);
                    catch
                        uialert(app.UIFigure, ...
                            'Wrong conda environmen!', ...
                            'Wrong Path')
                    end
                end

                % get path to base folder with all files and folders
                appFile = mfilename('fullpath');
                [baseDir,~,~] = fileparts(appFile);

                % check in which mode app is running
                if isdeployed
                    % stand alone mode
                    % [d, result] = system('set PATH');
                    % currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    % look at it just in case there are some
                    % problems with paths to files
                    %                             uialert(app.UIFigure, ...
                    %                                 {ctfroot,getenv('path')}, ...
                    %                                 'path')
                else
                    % running in Matlab
                    % add path to folder with ML model
                    % does not work in standalone mode
                    addpath(genpath([baseDir, filesep, 'ML_model']))
                end

                % path to predict.py, python file which will detect
                % calcium events
                filePath = fullfile( ...
                    baseDir, 'ML_model', 'sparks', 'predict.py');

            catch ME
                uialert(app.UIFigure, ...
                    ME.message, ...
                    'error')
                return
            end

            % maybe save cropped file (add it to cropp function) and load that for AI
            % detection, discuss with Prisca & Miguel
            [status, output] = system( ...
                sprintf('%s %s "%s"', ...  % quotation in case there are some spaces in name or path
                pythonPath, filePath, app.data.imgPath), ...
                '-echo');
            % check status
            if status~=0
                uialert(app.UIFigure, ...
                    output, ...
                    'error')
                return
            end
  
            % load predicted masks of events
            [~,img_fn,~] = fileparts(app.data.imgPath);
            % paths to output files
            p_mask_sparks = fullfile(baseDir, 'ML_model', 'sparks', 'output', sprintf('%s_sparks.tif',img_fn));
            p_mask_puffs = fullfile(baseDir, 'ML_model', 'sparks', 'output', sprintf('%s_puffs.tif',img_fn));
            p_mask_waves = fullfile(baseDir, 'ML_model', 'sparks', 'output', sprintf('%s_waves.tif',img_fn));

            % load masks / read output imgs
            mask_sparks = false(size(imgData));
            mask_puffs = false(size(imgData));
            mask_waves = false(size(imgData));
            %mask_events_types = zeros(size(imgData), 'uint8');
            % make sure is inside cell
            for i=1:n_imgs
                mask_sparks(:,:,i) = ...
                    (imread(p_mask_sparks, i) > app.EditField_spark_thr.Value) & cellMask(:,:,i);
                mask_puffs(:,:,i) = ...
                    (imread(p_mask_puffs, i) > app.EditField_puff_thr.Value) & cellMask(:,:,i);
                mask_waves(:,:,i) = ...
                    (imread(p_mask_waves, i) > app.EditField_wave_thr.Value) & cellMask(:,:,i);

                % mask of all events together, also different types
                % BG = 0; spark = 1; puff = 2; wave = 3
                % mask_events_types(:,:,i) = mask_events_types(:,:,i) + uint8(mask_sparks(:,:,i));
                % mask_events_types(:,:,i) = mask_events_types(:,:,i) + uint8(mask_puffs(:,:,i)).*2;
                % mask_events_types(:,:,i) = mask_events_types(:,:,i) + uint8(mask_waves(:,:,i)).*3;
            end

            % delete image series created by U-Net
            allOutputFiles = dir(fileparts(p_mask_sparks));
            for i=1:numel(allOutputFiles)
                if contains(allOutputFiles(i).name, img_fn)
                    delete(fullfile( ...
                        allOutputFiles(i).folder, ...
                        allOutputFiles(i).name ))
                end
            end

        end


        function saveDataToTIFFfile(app, data, dataLabels, filePath)
            % check extension
            [path,fn,ext] = fileparts(filePath);
            % check in which format to save masks of events
            if ~contains(ext, 'tif')
                ext = '.tif';
            end
            % check size of data to save
            s = whos('data');
            if s.bytes > 2^32-1
                access_mode = 'w8';
            else
                access_mode = 'w';
            end
            % check if to save also some labels
            if isempty(dataLabels)
                samplesPerPixel = 1;
            else
                samplesPerPixel = 2;
            end
            % data dimensions
            [H, W, D] = size(data,(1:3));
            % create tiff object to access tiff file
            tif_obj = Tiff( ...
                fullfile(path, [fn, ext]), ...
                access_mode);
            % set tags values
            switch s.class
                case 'logical'
                    bitsPerSample = 1;
                    sampleFormat = Tiff.SampleFormat.UInt;
                    photometric = Tiff.Photometric.Mask;
                case 'int8'
                bitsPerSample = 8;
                    sampleFormat = Tiff.SampleFormat.Int;
                    photometric = Tiff.Photometric.Mask;
                case 'uint8'
                    bitsPerSample = 8;
                    sampleFormat = Tiff.SampleFormat.UInt;
                    photometric = Tiff.Photometric.Mask;
                case 'int16'
                    bitsPerSample = 16;
                    sampleFormat = Tiff.SampleFormat.Int;
                    photometric = Tiff.Photometric.Mask;
                case 'uint16'
                    bitsPerSample = 16;
                    sampleFormat = Tiff.SampleFormat.UInt;
                    photometric = Tiff.Photometric.MinIsBlack;
                case 'int32'
                    bitsPerSample = 32;
                    sampleFormat = Tiff.SampleFormat.Int;
                    photometric = Tiff.Photometric.Mask;
                case 'uint32'
                    bitsPerSample = 32;
                    sampleFormat = Tiff.SampleFormat.UInt;
                    photometric = Tiff.Photometric.Mask;
                case 'single'
                    bitsPerSample = 32;
                    sampleFormat = Tiff.SampleFormat.IEEEFP;
                    photometric = Tiff.Photometric.Mask;
                case 'double'
                    bitsPerSample = 64;
                    sampleFormat = Tiff.SampleFormat.IEEEFP;
                    photometric = Tiff.Photometric.Mask;
            end
            tagstruct.ImageLength = H;
            tagstruct.ImageWidth = W;
            tagstruct.Photometric = photometric;
            tagstruct.SampleFormat = sampleFormat;
            tagstruct.BitsPerSample = bitsPerSample;
            tagstruct.SamplesPerPixel = samplesPerPixel;
            tagstruct.RowsPerStrip = H;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.Software = 'MATLAB';
            % write data to tif file
            % imwrite(im2,fullfile(img_path, sprintf('%s_sparks.tif',img_fn)),'WriteMode','append')
            for f=1:D
                % write sparks mask
                setTag(tif_obj,tagstruct)
                write(tif_obj, data(:,:,f));
                % creates a new image file directory (IFD)
                % and makes it the current IFD
                if f ~= D
                    writeDirectory(tif_obj);
                end
            end
            % close tiff object
            close(tif_obj);
        end


        function filterDataWithButterworthFilter(app, hO, E,...
            uiFig, ax_prof, ax_spectrum)
                % clean axes 
            cla(ax_prof)
            cla(ax_spectrum)
            % get data
            butterOrder = ...
                findobj(uiFig,'Type','uinumericeditfield','Tag','butterOrder').Value;
            widthOfBandStop = ...
                findobj(uiFig,'Type','uinumericeditfield','Tag','widthOfBandStop').Value;
            RMS_multipl = ...
                findobj(uiFig,'Type','uinumericeditfield','Tag','peakPromin').Value;
            minFreq = ...
                findobj(uiFig,'Type','uinumericeditfield','Tag','minFreq').Value;
            % sampling period in s
            T = app.data.pxSzT/1000;
            % sampling frequency (Hz)
            Fs = 1/T;
            % length of signal
            L = size(app.data.imgData,3);
            % time vector
            t = (0:L-1)*T;
            % get average time profile of image series
            avrg_prof = zeros(1,size(app.data.imgData,3));
            for i=1:size(app.data.imgData,3)
                avrg_prof(i) = mean(app.data.imgData(:,:,i),'all');
            end
            % get frequencies of noise
            Y = fft(avrg_prof);
            P2 = abs(Y/L);
            P1 = P2(1:floor(L/2)+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            
            % show average signal
            line(ax_prof, t, avrg_prof, 'Color','b', 'LineWidth',1, ...
                'DisplayName','profile')
            set(ax_prof,'FontSize',14, 'XLim',[min(t) max(t)])
            xlabel(ax_prof, 'time (ms)', 'FontSize',16, 'FontWeight','bold')
            ylabel(ax_prof, 'average signal', 'FontSize',16, 'FontWeight','bold')
            title(ax_prof, 'Average Time Profile of XYT Image Series', ...
                'FontSize',18, 'FontWeight','bold')
            % show single sided amplitude spectrum of signal
            hl_spctr = line(ax_spectrum, f, P1, 'Color','k', 'LineWidth',1, ...
                'DisplayName','amplitude spectrum of data');
            set(ax_spectrum,'FontSize',14, 'XLim',[min(f) max(f)], ...
                'YLim',[min(P1) max(P1(f>=minFreq))])
            xlabel(ax_spectrum, 'f (Hz)', 'FontSize',16, 'FontWeight','bold')
            ylabel(ax_spectrum, '|P1(f)|', 'FontSize',16, 'FontWeight','bold')
            title(ax_spectrum, ...
                'Single-Sided Amplitude Spectrum of Average Profile from Unfiltered Data(t)', ...
                'FontSize',18, 'FontWeight','bold')         
            % remove trends in signal
            [LT,ST,R] = trenddecomp(P1(:));
            % calculate rms of signal to estimate noise in signal
            peakPromin = RMS_multipl*rms(R(f>=minFreq)); % maybe check SD
            
            % find frequencies which will be filtered
            [pks,locs] = findpeaks(P1(f>=minFreq), f(f>=minFreq), ...
                'MinPeakProminence', peakPromin);
            % merge overlaping regions
            bands = [locs-widthOfBandStop/2 ;locs+widthOfBandStop/2]';
            try
                merged_bands = bands;
                % check which regions overlap
                m_overlaps = merged_bands(:,1)<circshift(merged_bands(:,2),1);
                m_overlaps(1) = false;
                intersectingRegions = any(m_overlaps);
                while intersectingRegions
                    idx_start = find(m_overlaps,1,'first')-1;
                    idx_end = find(m_overlaps(idx_start+1:end)==0,1,'first')+idx_start-1;
                    if isempty(idx_end), idx_end = numel(m_overlaps); end
                    % merge overlaping bands
                    merged_bands = [
                        merged_bands(1:idx_start-1,:); ...
                        [merged_bands(idx_start,1),merged_bands(idx_end,2)]; ...
                        merged_bands(idx_end+1:end,:)
                        ];
                    % check if there is still something overlapping
                    m_overlaps = merged_bands(:,1)<circshift(merged_bands(:,2),1);
                    m_overlaps(1) = false;
                    intersectingRegions = any(m_overlaps);
                end
                bands = merged_bands;
            catch
            end
            if ~isempty(bands)
                % make sure that nothing is ouiside of interval (0,max(f))
                bands(bands(:,1)<0,1) = 0;
                bands(bands(:,2)>max(f),2) = max(f)-mean(diff(f))/1000;
                % show found peaks, filtered bands and mergerd filtered bands
                lp = line(ax_spectrum, locs, pks, 'LineStyle','none', ...
                    'Marker','.', 'MarkerSize',25, 'Color','r',...
                    'DisplayName','cutoff frequencies');
                lbands = line(ax_spectrum, ...
                    [[locs-widthOfBandStop/2;locs-widthOfBandStop/2], ...
                    [locs+widthOfBandStop/2;locs+widthOfBandStop/2]], ...
                    [min(P1);max(P1)], 'LineStyle',':', ...
                    'LineWidth',1, 'Color','r',...
                    'DisplayName','filtered bands borders');
                for i=1:size(bands,1)
                    % rectangles
                    vert = [
                        bands(i,1) min(P1); ...
                        bands(i,2) min(P1); ...
                        bands(i,2) max(P1); ...
                        bands(i,1) max(P1)
                        ];
                    fcs = [1 2 3 4];
                    hp=patch(ax_spectrum, 'Faces',fcs, 'Vertices',vert, ...
                        'FaceColor','red', 'FaceAlpha',0.2, ...
                        'EdgeColor', 'none', 'DisplayName','merged filtered bands');
                end
                % show legend
                legend(ax_prof,'Location','best')
                try
                    legend(ax_spectrum,[hl_spctr,lp,lbands(1),hp],'Location','best')
                catch
                    legend(ax_spectrum,[hl_spctr,lp],'Location','best')
                end
            end
            % check if filter data or accept already filtered data
            if ~isempty(hO) && strcmp(hO.Type,'uibutton')
                switch hO.Text
                    case 'filter data'
                        % filter data with Butterworth filter
                        % filter type
                        ftype = 'stop';
                        % add progressbar
                        h_dlg = uiprogressdlg(uiFig, ...
                            'Indeterminate','on', ...
                            'Message', 'Filtering image series with Butterworth filter.', ...
                            'Title', 'Filtering data ...');
                        % filter data
                        imgDataFilt = shiftdim(double(app.data.imgData),2);
                        for i=1:size(bands,1)
                            Wn = bands(i,:) ./ max(f);
                            % Transfer Function design
                            % [b,a] = butter(butterOrder, Wn, ftype);
                            % Zero-Pole-Gain design, numericaly more stable
                            [z,p,k] = butter(butterOrder, Wn, ftype);
                            [sos,g] = zp2sos(z,p,k);
                            % Display and compare results
                            % hfvt = fvtool(b,a,sos,'FrequencyScale','log');
                            % legend(hfvt,'TF Design','ZPK Design')
                            % imgDataFilt = filtfilt(b,a,imgDataFilt);
                            imgDataFilt = filtfilt(sos,g,imgDataFilt);
                        end
                        imgDataFilt = shiftdim(imgDataFilt,4);
                        % calculate average from filtered data
                        avrg_prof_filt = zeros(size(avrg_prof));
                        for i=1:size(app.data.imgData,3)
                            avrg_prof_filt(i) = mean(imgDataFilt(:,:,i),"all");
                        end
                        % show filtered average profile in axes
                        line(ax_prof, t, avrg_prof_filt, 'Color','r', 'LineWidth',1, ...
                            'DisplayName','filtered profile')
                        % save filtered data
                        filteredData = struct('filterName','Butterworth', ...
                            'ftype', ftype, 'order', butterOrder, ...
                            'widthOfBandStop', widthOfBandStop, ...
                            'filteredFreq', locs, ...
                            'imgDataFilt', imgDataFilt);
                        setappdata(uiFig,'filteredData',filteredData)
                        % close progressbar
                        close(h_dlg)

                    case 'accept'
                        % check if there are data to save
                        if isfield(getappdata(uiFig),'filteredData')
                            filteredData = getappdata(uiFig,'filteredData');
                            % save parameters of butterworth filter to
                            % filters table
                            % check if there is a butterworth filter in
                            % table
                            if any(app.UITable_filters.Data.filter == ...
                                    "Butterworth filter")
                                % modify parameters of already added
                                % butterworth filter
                                ind_f = find(app.UITable_filters.Data.filter == ...
                                    "Butterworth filter");
                                app.UITable_filters.Data{ind_f,"p#1 value"} = ... 
                                    butterOrder;
                                app.UITable_filters.Data{ind_f,"p#2 value"} = ...
                                    widthOfBandStop;
                                app.UITable_filters.Data{ind_f,"p#3 value"} = ...
                                    RMS_multipl;
                                app.UITable_filters.Data{ind_f,"p#4 value"} = ...
                                    minFreq;

                            else
                                % add new filter
                                % remove all none rows
                                app.UITable_filters.Data( ...
                                    app.UITable_filters.Data.filter == "none", :) = [];
                                % add new butterworth filter and one empty row at the end
                                app.UITable_filters.Data = [ 
                                    app.UITable_filters.Data; ...
                                    table(categorical({'Butterworth filter';'none'}, app.filterNames), ...
                                        {'order of filter';''},[butterOrder;0], ...
                                        {'width of bandstop (Hz)';''},[widthOfBandStop;0], ...
                                        {'RMS noise multiplier';''},[RMS_multipl;0], ...
                                        {'freq filtered (Hz) >=';''},[minFreq;0], ...   
                                        'VariableNames',{'filter', ...
                                             'parameter #1','p#1 value', ...
                                             'parameter #2','p#2 value', ...
                                             'parameter #3','p#3 value', ...
                                             'parameter #4','p#4 value'}) 
                                             ];
                            end
                            % close Butterworth filter figure
                            close(uiFig)
                        else
                            uialert(uiFig,'First filter data!')
                            return
                        end
                end
            end

        end


        function setFiltersSettingsInTable(app, hO, E, uifig)
            % handle of filter table
            filters_uitbl = findobj(uifig, 'Type','uitable', ...
                'Tag','filtersSelectionTbl');
            % check inputs
            switch hO.Type
                case 'uitable'
                    % if filter is change, change parameters names and vals
                    if E.Indices(2)==1
                        switch E.NewData
                            case 'none'
                                hO.Data(E.Indices(1),"parameter #1") = {''};
                                hO.Data{E.Indices(1),"p#1 value"} = 0;
                                hO.Data(E.Indices(1),"parameter #2") = {''};
                                hO.Data{E.Indices(1),"p#2 value"} = 0;
                                hO.Data(E.Indices(1),"parameter #3") = {''};
                                hO.Data{E.Indices(1),"p#3 value"} = 0;
                                hO.Data(E.Indices(1),"parameter #4") = {''};
                                hO.Data{E.Indices(1),"p#4 value"} = 0;
                                hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                            case 'gaussian'
                                hO.Data(E.Indices(1),"parameter #1") = {'sigma xy'};
                                hO.Data{E.Indices(1),"p#1 value"} = 1;
                                hO.Data(E.Indices(1),"parameter #2") = {'sigma t'};
                                hO.Data{E.Indices(1),"p#2 value"} = 0.5;
                                hO.Data(E.Indices(1),"parameter #3") = {''};
                                hO.Data{E.Indices(1),"p#3 value"} = 0;
                                hO.Data(E.Indices(1),"parameter #4") = {''};
                                hO.Data{E.Indices(1),"p#4 value"} = 0;
                                hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                            case 'anisotropic diffusion'
                                hO.Data(E.Indices(1),"parameter #1") = {'# of iter'};
                                hO.Data{E.Indices(1),"p#1 value"} = 5;
                                hO.Data(E.Indices(1),"parameter #2") = {''};
                                hO.Data{E.Indices(1),"p#2 value"} = 0;
                                hO.Data(E.Indices(1),"parameter #3") = {''};
                                hO.Data{E.Indices(1),"p#3 value"} = 0;
                                hO.Data(E.Indices(1),"parameter #4") = {''};
                                hO.Data{E.Indices(1),"p#4 value"} = 0;
                                hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                            case 'PURE-let'
                                hO.Data(E.Indices(1),"parameter #1") = {'# of cycles'};
                                hO.Data{E.Indices(1),"p#1 value"} = 5;
                                hO.Data(E.Indices(1),"parameter #2") = {'# of frames'};
                                hO.Data{E.Indices(1),"p#2 value"} = 5;
                                hO.Data(E.Indices(1),"parameter #3") = {''};
                                hO.Data{E.Indices(1),"p#3 value"} = 0;
                                hO.Data(E.Indices(1),"parameter #4") = {''};
                                hO.Data{E.Indices(1),"p#4 value"} = 0;
                                hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                            case 'Butterworth filter'
                                hO.Data(E.Indices(1),"parameter #1") = {'order of filter'};
                                hO.Data{E.Indices(1),"p#1 value"} = 4;
                                hO.Data(E.Indices(1),"parameter #2") = {'width of bandstop (Hz)'};
                                hO.Data{E.Indices(1),"p#2 value"} = 1;
                                hO.Data(E.Indices(1),"parameter #3") = {'RMS noise multiplier'};
                                hO.Data{E.Indices(1),"p#3 value"} = 3;
                                hO.Data(E.Indices(1),"parameter #4") = {'freq filtered (Hz) >='};
                                hO.Data{E.Indices(1),"p#4 value"} = 7;
                                hO.ColumnEditable = logical([1 0 1 0 1 0 1 0 1]);
                            otherwise
                                hO.Data(E.Indices(1),"parameter #1") = {'kernel_sz xy'};
                                hO.Data{E.Indices(1),"p#1 value"} = 3;
                                hO.Data(E.Indices(1),"parameter #2") = {'kernel_sz t'};
                                hO.Data{E.Indices(1),"p#2 value"} = 3;
                                hO.Data(E.Indices(1),"parameter #3") = {''};
                                hO.Data{E.Indices(1),"p#3 value"} = 0;
                                hO.Data(E.Indices(1),"parameter #4") = {''};
                                hO.Data{E.Indices(1),"p#4 value"} = 0;
                                hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                        end
                        % remove all none rows
                        hO.Data(hO.Data.filter == "none", :) = [];
                        % add one empty row at the end
                        hO.Data = [ hO.Data; ...
                            table(categorical({'none'}, app.filterNames), ...
                                {''},0, {''},0, {''},0, {''},0, ...
                                'VariableNames',{'filter', ...
                                'parameter #1','p#1 value', ...
                                'parameter #2','p#2 value', ...
                                'parameter #3','p#3 value', ...
                                'parameter #4','p#4 value'}) ];
                        
                    else
                        % if parametres of filter are changed
                        % validate inputs
                        try
                            % filters selecion table
                            switch hO.Data{E.Indices(1),1}
                                case 'anisotropic diffusion'
                                    % check kernel size, has to positive integer
                                    if E.Indices(2)==3
                                        % param 1
                                        validateattributes(...
                                            hO.Data{E.Indices(1),E.Indices(2)}, ...
                                            'numeric', {'integer','>',0})
                                    end
                                case 'gaussian'
                                    validateattributes(...
                                        hO.Data{E.Indices(1),E.Indices(2)}, ...
                                        'numeric', {'>=',0})
                                case 'PURE-let'
                                    if E.Indices(2)==3
                                        validateattributes(...
                                            hO.Data{E.Indices(1),E.Indices(2)}, ...
                                            'numeric', {'integer','>',0})
                                    else
                                        validateattributes(...
                                            hO.Data{E.Indices(1),E.Indices(2)}, ...
                                            'numeric', {'integer','odd','>',0})
                                    end
                                case 'Butterworth filter'
                                    if E.Indices(2)==3
                                        validateattributes(...
                                            hO.Data{E.Indices(1),E.Indices(2)}, ...
                                            'numeric', {'integer','>',0})
                                    else
                                        validateattributes(...
                                            hO.Data{E.Indices(1),E.Indices(2)}, ...
                                            'numeric', {'>',0})
                                    end
                                otherwise
                                    % check input, has to be odd and min 3
                                    validateattributes(...
                                        hO.Data{E.Indices(1),E.Indices(2)}, ...
                                        'numeric', {'integer','odd','>=',1})
                            end
                        catch ME
                            % set back to previous correct values
                            hO.Data{E.Indices(1),E.Indices(2)} = E.PreviousData;
                            errordlg(ME.message)
                            return
                        end
                    end
      
                case 'uibutton'
                    % save state of filters used during image series
                    % loading
                    app.data.defaultFiltersUsed.filters = filters_uitbl.Data;
                    % also save path to conda environment
                    editField_h = findobj(uifig, 'Type','uieditfield');
                    app.data.condaEnvPath = editField_h.Value;
                    % close settings window
                    close(uifig)
            end

        end


        function imgDataFilt = filterImgSeries(app, ...
                imgData, filterData, h_progressdlg)
            % filtered data
            imgDataFilt = zeros(size(imgData));
            filterName = filterData.filter;
            % apply filter
            switch filterName
                case 'none'
                    imgDataFilt = imgData;

                case 'anisotropic diffusion'
                    if ~isempty(h_progressdlg)
                        h_progressdlg.Message = ...
                            'Filtering image series using anisotropic diffusion.';
                    end
                    imgDataFilt = imdiffusefilt(imgData, ...
                        'NumberOfIterations', filterData.("p#1 value"));

                case 'gaussian'
                    if ~isempty(h_progressdlg)
                        h_progressdlg.Message = ...
                            'Filtering image series using gaussian filter.';
                    end
                    drawnow
                    % first filter each frame in spatial dimension
                    if filterData.("p#1 value")>0
                        imgDataFilt = imgaussfilt(imgData, ...
                            filterData.("p#1 value"), ...
                            'Padding','symmetric');
                    else
                        imgDataFilt = imgData;
                    end
                    % then filter each pixel profile in time dimension
                    if filterData.("p#2 value")>0
                        imgDataFilt = imgaussfilt3(imgDataFilt, ...
                            filterData.("p#1 value"), ...
                            'FilterSize',[1, 1, 2*ceil(2*filterData.("p#1 value"))+1], ...
                            'Padding','symmetric');
                    end

                case 'PURE-let'
                    % http://bigwww.epfl.ch/algorithms/denoise/
                    % parameters of denoising
                    % * Constructor of the class Denoising.
                    % * @param input         input data to be denoised (3D ImageWare object)
                    % * @param Alpha         double array containing the estimated detector gain
                    % *                      for each frame/slice.
                    % * @param Delta         double array containing the estimated detector of-
                    % *                      fset for each frame/slice.
                    % * @param Sigma         double array containing the estimated AWGN standard
                    % *                      deviation for each frame/slice.
                    % * @param FRAMEWISE     true->Framewise noise parameters estimation
                    % *                      false->Global noise parameters estimation.
                    % * @param CYCLESPIN     number of cycle-spins (CS>0). A high value of CS
                    % *                      yields a high-quality denoising result, but the
                    % *                      computation time linearly increases with CS.
                    % * @param MULTIFRAME    number of adjacent frames/slices to be considered
                    % *                      for multi-frame/slices denoising. MF>0 must be odd.
                    % 
                    h_progressdlg.Message = ...
                        {'Filtering image series using PureDenoise.', ...
                        '(denoising of multidimensional images corrupted by mixed Poisson-Gaussian noise)', ...
                        'This might take several minutes.'}; 
                    % set up path to java packages
                    appFile = mfilename('fullpath');
                    [baseDir,~,~] = fileparts(appFile);
                    % check in which mode app is running
                    if isdeployed
                        % stand alone mode
                        % [d, result] = system('set PATH');
                        % currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                        % look at it just in case there are some
                        % problems with paths to files
%                     uialert(app.UIFigure, ...
%                         {ctfroot,getenv('path')}, ...
%                         'path','Icon','warning')
%                         javaaddpath(fullfile(baseDir,'pure_let/PureDenoise_.jar'))
%                         javaaddpath(fullfile(baseDir,'pure_let/ij.jar'))
                    else
                        % running in Matlab
                        % add path to folder with ML model
                        % does not work in standalone mode
                        % add java packages to the path
                        javaaddpath(fullfile(baseDir,'pure_let/PureDenoise_.jar'))
                        javaaddpath(fullfile(baseDir,'pure_let/ij.jar'))
                    end
                
                    % create java image object
                    original_img = imageware.Builder.create(imgData);
                    % dimensions of image series
                    nx = original_img.getSizeX();
                    ny = original_img.getSizeY();
                    nz = original_img.getSizeZ();
                    % minimum size of image
                    Nmin = 16;
                    Ext = [0,0];
                    if nx<=Nmin || ny<=Nmin
                        errordlg('Minimum image height and width is 17!') 
                        return
                    end
                    nxe = ceil(nx / Nmin) * Nmin;
                    nye = ceil(ny / Nmin) * Nmin;
                    if (nxe ~= nx || nye ~= ny)
                        original_img = ...
                            denoise.Operations.symextend2D(original_img, nxe, nye, Ext);
                        Ext = [(nxe-nx)/2,(nye-ny)/2];
                    else
                        Ext = [0,0];
                    end
                    % init params
                    AlphaHat = zeros(nz,1);
                    DeltaHat = zeros(nz,1);
                    SigmaHat = zeros(nz,1);
                    CS = filterData.("p#1 value");
                    NBFRAME = filterData.("p#2 value");
                    FRAMEWISE = false;
                    % create denoising object
                    denoising = denoise.Denoising( ...
                        original_img, AlphaHat, DeltaHat, SigmaHat, ...
                        FRAMEWISE, CS, NBFRAME);
                    denoising.setLog(false);
                    % estimate parameters
                    denoising.estimateNoiseParameters();
                    % denoise
                    denoising.perform();
                    % get output
                    output = denoising.getOutput();
                    if (nxe ~= nx || nye ~= ny)
                        output = denoise.Operations.crop2D(output, nx, ny, Ext);
                    end
%                     % display
%                     methods(ij.IJ,"-full")
%                     ij.IJ.showStatus("Denoising in progress...");
%                     PureDenoise_.display()
                    % get parameters of denoising
                    alpha=denoising.getAlpha();
                    delta=denoising.getDelta();
                    sigma=denoising.getSigma();
                    % create denoised image
                    imgDataFilt = zeros(nx, ny, nz);
                    for i = 1:nz
                        imgDataFilt(:,:,i) = ...
                            reshape(output.getSliceDouble(i-1), nx, ny);
                    end

                case 'butterworth'
                    h_progressdlg.Message = ...
                        'Filtering image series using Butterworth filter.'; 
                    drawnow
                    % get filter parameters
                    butterOrder = filterData.("p#1 value");
                    widthOfBandStop = filterData.("p#2 value");
                    RMS_multipl = filterData.("p#3 value");
                    minFreq = filterData.("p#4 value");
                    % sampling period in s
                    T = app.data.pxSzT/1000;
                    % sampling frequency (Hz)
                    Fs = 1/T;
                    if minFreq>Fs/2
                        minFreq = Fs/2;
                    end
                    % length of signal
                    L = size(imgData,3);
                    % time vector
                    t = (0:L-1)*T;
                    % get average time profile of image series
                    avrg_prof = zeros(1,size(imgData,3));
                    for i=1:size(imgData,3)
                        avrg_prof(i) = mean(imgData(:,:,i),'all');
                    end
                    % get frequencies of noise
                    Y = fft(avrg_prof);
                    P2 = abs(Y/L);
                    P1 = P2(1:floor(L/2)+1);
                    P1(2:end-1) = 2*P1(2:end-1);
                    f = Fs*(0:(L/2))/L;
                    % remove trends in signal
                    [LT,ST,R] = trenddecomp(P1(:));
                    % calculate rms of signal to estimate noise in signal
                    peakPromin = RMS_multipl*rms(R(f>=minFreq));
                    % find frequencies which will be filtered
                    [pks,locs] = findpeaks(P1(f>=minFreq), f(f>=minFreq), ...
                        'MinPeakProminence', peakPromin);
                    % merge overlaping regions
                    bands = [locs-widthOfBandStop/2 ;locs+widthOfBandStop/2]';
                    try
                        merged_bands = bands;
                        % check which regions overlap
                        m_overlaps = merged_bands(:,1)<circshift(merged_bands(:,2),1);
                        m_overlaps(1) = false;
                        intersectingRegions = any(m_overlaps);
                        while intersectingRegions
                            idx_start = find(m_overlaps,1,'first')-1;
                            idx_end = find(m_overlaps(idx_start+1:end)==0,1,'first')+idx_start-1;
                            if isempty(idx_end), idx_end = numel(m_overlaps); end
                            % merge overlaping bands
                            merged_bands = [
                                merged_bands(1:idx_start-1,:); ...
                                [merged_bands(idx_start,1),merged_bands(idx_end,2)]; ...
                                merged_bands(idx_end+1:end,:)
                                ];
                            % check if there is still something overlapping
                            m_overlaps = merged_bands(:,1)<circshift(merged_bands(:,2),1);
                            m_overlaps(1) = false;
                            intersectingRegions = any(m_overlaps);
                        end
                        bands = merged_bands;
                    catch
                    end
                    % make sure that nothing is ouiside of interval (0,max(f))
                    bands(bands(:,1)<0,1) = 0;
                    bands(bands(:,2)>max(f),2) = max(f)-mean(diff(f))/1000;
                    % filter data with Butterworth filter
                    % filter type
                    ftype = 'stop';
                    % filter data
                    imgDataFilt = shiftdim(double(imgData),2);
                    for i=1:size(bands,1)
                        Wn = bands(i,:) ./ max(f);
                        % Transfer Function design
                        % [b,a] = butter(butterOrder, Wn, ftype);
                        % Zero-Pole-Gain design, numericaly more stable
                        [z,p,k] = butter(butterOrder, Wn, ftype);
                        [sos,g] = zp2sos(z,p,k);
                        % Display and compare results
                        % hfvt = fvtool(b,a,sos,'FrequencyScale','log');
                        % legend(hfvt,'TF Design','ZPK Design')
                        % imgDataFilt = filtfilt(b,a,imgDataFilt);
                        imgDataFilt = filtfilt(sos,g,imgDataFilt);
                    end
                    imgDataFilt = shiftdim(imgDataFilt,4);

                otherwise
                    h_progressdlg.Message = sprintf(...
                        'Filtering image series using %s filter.',filterName); 
                    drawnow

                    switch filterName
                        case 'median'
                            % first filter spatial dimension
                            if filterData.("p#1 value") > 1
                                for i=1:size(imgData,3)
                                    imgDataFilt(:,:,i) = medfilt2( ...
                                        imgData(:,:,i), ...
                                        [filterData.("p#1 value") filterData.("p#1 value")], ...
                                        'symmetric');
                                end
                            else
                                imgDataFilt = imgData;
                            end
                            % then filter time dimension
                            if filterData.("p#2 value") > 1
                                imgDataFilt = medfilt1(double(imgDataFilt), ...
                                    filterData.("p#2 value"), [], 3, 'truncate');
                            end
                        
                        case 'average'
                            % first filter spatial dimension
                            if filterData.("p#1 value") > 1
                                imgDataFilt = imboxfilt(imgData, ...
                                    [filterData.("p#1 value") filterData.("p#1 value")], ...
                                    'Padding','symmetric');
                            else
                                imgDataFilt = imgData;
                            end
                            % then filter time dimension
                            if filterData.("p#2 value") > 1
                                imgDataFilt = imboxfilt3(imgDataFilt, ...
                                    [1,1,filterData.("p#2 value")], ...
                                    'Padding','symmetric');
                            end
                    end       
            end
            
            
        end
        

        function createCondaEnv(app, hO, E, hf)
            % ask if continue
            keyboard
            editField_h = findobj(hf, 'Type','uieditfield');
            createCondaEnv = uiconfirm(app.UIFigure, ...
               'Do you want to create conda evironment?', ...
               'new Anaconda environment', ...
               'Options', {'Continue', 'Cancel'}, ...
               'DefaultOption', 2);
            
            switch createCondaEnv
                case 'Continue'
                    % ask about path to conda instalation
                    condaInstalled = uiconfirm(app.UIFigure, ...
                        {'Is Anaconda installed?', ...
                        '(e.g. /Users/rjanicek/opt/anaconda3)'}, ...
                        'path to Anaconda folder', ...
                        'Options', {'Yes', 'No'}, ...
                        'DefaultOption', 1);
                    
                    switch condaInstalled
                        case 'Yes'
                            % select conda root directory
                            condaPath = uigetdir( ...
                                app.EditField_pathToConda.Value, ...
                                'Select directory where Anaconda was installed.');
                            drawnow;
                            figure(app.UIFigure);

                        case 'No'
%                             uialert(app.UIFigure, ...
%                                 'https://www.anaconda.com/', ...
%                                 'install Anaconda',...
%                                 'Icon', 'info');
                            w = 400;
                            h = 100;
                            posFig = [round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w/2), ...
                                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h/2), ...
                                w, h];
                            uifig = uifigure( ...
                                'WindowStyle','modal',...
                                'Units', 'pixels', ...
                                'Position', posFig, ...
                                'Name', 'install Anaconda');
                            uiax = uiaxes(uifig, ...
                                'Position',[0 0 uifig.Position(3:4)]);
                            uiax.Toolbar.Visible = 'off';
                            axis(uiax,'off')
                            % add hyperlink text
                            th = text(uiax, 0.5, 0.75, ...
                                '$\underline{https://www.anaconda.com/}$', ...
                                'color',[0 0 1], 'FontSize',20, ...
                                'Interpreter','latex', ...
                                'HorizontalAlignment','center');
                            % open the website
                            th.ButtonDownFcn = @(~,~)web('https://www.anaconda.com/'); 
                            % Add OK button to close figure
                            uibutton(uifig, 'Text', 'OK', ...
                                'Position', [round(w/2-75/2), 10, 75, 30], ...
                                'ButtonPushedFcn', @(h,~)delete(h.Parent))
                            
                            return
                    end
                                        
                case 'Cancel'
                    return
            end
            
            % full path to conda executable file
            conda_path = fullfile(condaPath, ...
                filesep, 'condabin', filesep, 'conda');
            % check the list of environments already created
            [status,envs] = system(sprintf('%s env list', conda_path));
            if status~=0
                % conda has to be installed
                uialert(app.UIFigure, ...
                    {'Please provide correct path to directory where Anaconda is installed!', ...
                    '(e.g. /Users/rjanicek/opt/anaconda3)'}, ...
                    'Error',...
                    'Icon', 'error');
                return
            end
            envs = strsplit(envs,'\n');
            m_envs = true(1,numel(envs));
            envs_names = cell(size(envs));
            envs_paths = cell(size(envs));
            for i = 1:numel(envs)
                env = strrep(envs{i}, '*', ' ');
                if isempty(env) || contains(env, '#')
                    m_envs(i) = false;
                    continue
                end
                env = strsplit(env);
                envs_names(i) = env(1);
                envs_paths(i) = env(2);
            end
            envs_names = envs_names(m_envs);
            envs_paths = envs_paths(m_envs);
            
            new_env_name = 'xytCaSignals';

            % status = system(sprintf('%s init zsh', conda_path));
            
            % check if spark_puff environment is created
            if any(strcmp(envs_names, new_env_name))
                % conda env already created
                uialert(app.UIFigure, ...
                    sprintf('Conda environment with this name (%s) is already created.', ...
                    new_env_name), ...
                    'Environment with the same name already created.',...
                    'Icon', 'warning');
            else
                % create conda environment xytCaSignals from environment
                % file
                appFile = mfilename('fullpath');
                [baseDir,~,~] = fileparts(appFile);
                % check in which mode app is running
                if isdeployed
                    % stand alone mode
                    % [d, result] = system('set PATH');
                    % currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    % look at it just in case there are some
                    % problems with paths to files
%                     uialert(app.UIFigure, ...
%                         {ctfroot,getenv('path')}, ...
%                         'path','Icon','warning')
                else
                    % running in Matlab
                    % add path to folder with ML model
                    % does not work in standalone mode
                    addpath(genpath([baseDir, filesep, 'ML_model']))
                end
                
                % add progressbar
                h_dlg = uiprogressdlg(app.UIFigure, ...
                    'Indeterminate','on', ...
                    'Message', sprintf('creating conda environment with name: %s',new_env_name), ...
                    'Title', 'New conda environment.');
                env_file_path = fullfile( ...
                    baseDir, 'ML_model', 'xytCaSignals.yml');
                try
                    status = system( ...
                        sprintf('%s env create --file %s', ...
                        conda_path, env_file_path));
                    if status==0
                        % call function to set the path to conda
                        % environment
                        h_dlg.Message = sprintf('Select path to %s conda environment.',new_env_name);
                        app.getCondaEnvDirPath()
                        close(h_dlg)
                    else
                        uialert(app.UIFigure, ...
                            {'Could not create new conda environment', ...
                            'Try to install it manually in command line and provide path to it.'}, ...
                            'error')
                    end
                catch ME
                    uialert(app.UIFigure, ...
                        ME.message, ...
                        'error')
                    close(h_dlg)
                end
            end
        end
        

        function getCondaEnvDirPath(app, hO, E, hf)
            % get path to directory of required conda environment
            editField_h = findobj(hf, 'Type','uieditfield');
            selpath = uigetdir(editField_h.Value);
            drawnow;
            figure(app.UIFigure);
            
            if selpath == 0
                return
            else            
                editField_h.Value = selpath;
            end
        end
    end % end methods section

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function start_up_fcn(app)
            % load saved state            
            if exist(fullfile(fileparts(mfilename('fullpath')),'previousAppState.mat'), ...
                    'file')
                app.data.previousAppState = load('previousAppState.mat');
                % set up path to last used conda environment
                app.data.condaEnvPath = ...
                    app.data.previousAppState.previousCondaEnvPath;
                app.data.defaultFiltersUsed = ...
                    app.data.previousAppState.filtersUsedDuringImgSeriesLoading;
            else
                % set default parameters of filters during image series
                % loading
                % filters sequence
                filterNamesTbl = categorical({'median';'none';'none'}, ...
                    app.filterNames);
                param1_n = {'kernel_sz xy'; ''; '';};
                param1_val = [3; 0; 0];
                param2_n = {'kernel_sz t'; ''; ''};
                param2_val = [3; 0; 0];
                param3_n = {''; ''; ''};
                param3_val = [0; 0; 0];
                param4_n = {''; ''; ''};
                param4_val = [0; 0; 0];
                tdata_filters = table( ...
                    filterNamesTbl, ...
                    param1_n, param1_val, param2_n, param2_val, ...
                    param3_n, param3_val, param4_n, param4_val, ...
                    'VariableNames',{'filter', ...
                    'parameter #1','p#1 value', 'parameter #2','p#2 value', ...
                    'parameter #3','p#3 value', 'parameter #4','p#4 value'});
                % save data
                app.data.defaultFiltersUsed.filters = tdata_filters;
            end
            % set position of figure in the middle of the screen
            scSz = app.UIFigure.Parent.ScreenSize;
            x_pos_fig = round((scSz(3)-app.UIFigure.Position(3))/2);
            w_fig = app.UIFigure.Position(3);
            if x_pos_fig<1
                w_fig = app.UIFigure.Position(3)-(scSz(3)-app.UIFigure.Position(3));
                x_pos_fig = 1; 
            end
            y_pos_fig = round((scSz(4)-app.UIFigure.Position(4))/2);
            h_fig = app.UIFigure.Position(4);
            if y_pos_fig<1
                h_fig = app.UIFigure.Position(4)-(scSz(4)-app.UIFigure.Position(4));
                y_pos_fig = 1;
            end
            app.UIFigure.Position = [x_pos_fig y_pos_fig w_fig h_fig];
            
            % save original layout of components for image axes resizing
            % first tab
            app.data.orgCompPos.axImg = app.UIAxes_img.InnerPosition;
            % second tab
            app.data.orgCompPos.axImg_events = app.UIAxes_img_events.InnerPosition;
            
        end

        % Menu selected function: Menu_openImg
        function openImg(app, event)
            
            % change tab
            app.TabGroup.SelectedTab = app.Tab_CaEventsDetection;
            % delete data from previous analysis
            app.data.detectedEvents = table();
            app.data.selectedEvent = [];
            app.data.pxSz_x = [];
            app.data.pxSz_y = [];
            app.data.pxSzT = [];
            app.data.t = [];
            app.data.imgDataAll = [];
            app.data.imgDataRaw = [];
            app.data.imgData = [];
            app.data.cellMask = [];
            app.data.crop_s_frame = [];
            app.data.crop_e_frame = [];
            app.data.crop_roi_xy_pos = [];
            % delete fields
            try
                fields = {'F0','img_blank', 'imgAnalysisData', ...
                    'eventsIDs','eventsInAllFrames', 'eventsIDs_types'};
                % try ismember and delete only those, which are there
                app.data = rmfield(app.data,fields(ismember(fields, fieldnames(app.data))));
            catch
            end
            
            % check if there is path to previously loaded image
            if isfield(app.data.previousAppState, 'previousImgPath')
                prevImgPath = app.data.previousAppState.previousImgPath;     
            else
                prevImgPath = '';
            end
            
            % check if app is in batch mode
            if app.data.batchMode
                [p,n,e] = fileparts(app.data.imgPath);
                filePath = p;
                fileName = [n,e];
            else
                % get path to image
                [fileName, filePath] = uigetfile( ...
                    {'*.tif'}, ...
                    'Load image', ...
                    prevImgPath, ...
                    'Multiselect','off');
                drawnow;
                figure(app.UIFigure);
            end

            if ~iscell(fileName)
                fileName = {fileName};
            end
            
            if fileName{1}==0
                return
            end
                
            % show state
            app.stateLampLabel.Text = 'busy';
            app.Lamp_state.Color = [1 0 0];
            drawnow
           % keyboard
            % get metadata of image, number of images in tiff file
%             img_info = imfinfo(fullfile(filePath, fileName{1}));
%             imgDescrStr = img_info(1).ImageDescription;
%             N_imgs = size(img_info, 1);
             % use  TIFF class
            img_TIFF_obj = Tiff(fullfile(filePath, fileName{1}));
            imgDescrStr = img_TIFF_obj.getTag('ImageDescription');
            % save path
            app.data.imgPath = fullfile(filePath, fileName{1});

            % read images
            % imgData = zeros( img_info(1).Height, img_info(1).Width, N_imgs, ...
            %   sprintf('uint%d', img_info(1).BitDepth) );
%             imgData = zeros(img_info(1).Height, img_info(1).Width, N_imgs);
%             for i=1:N_imgs
%                imgData(:,:,i) = imread(fullfile(filePath, fileName{1}), ...
%                                 i, 'Info', img_info);
%             end
            imgData = img_TIFF_obj.read();
            processNext = true;
            while processNext
                try
                    img_TIFF_obj.nextDirectory()
                    imgData(:,:,end+1) = img_TIFF_obj.read();
                    if img_TIFF_obj.lastDirectory()
                        break
                    end
                catch
                    processNext = false;
                end
            end
            % size of image series
            img_height = img_TIFF_obj.getTag('ImageLength');
            img_width = img_TIFF_obj.getTag('ImageWidth');
            N_imgs = size(imgData, 3);
            
            % close TIFF object
            close(img_TIFF_obj)

            % get metadata, only if it is not in batch mode
            if app.data.batchMode
                imgMetadata = app.data.imgMetaDataInBatchMode;   
            elseif app.data.loadAnalyzedImgSeriesFlag
                % load previous analysis of selected image series
                % check if file exists
                [p,n,e] = fileparts(fullfile(filePath, fileName{1}));
                if exist(fullfile(p, sprintf('%s_results.mat',n)), 'file')
                    imgSeriesAnalysis = load(fullfile(p, sprintf('%s_results.mat',n)));
                    app.data.imgAnalysisData = imgSeriesAnalysis.imgAnalysisData;
                    imgMetadata.pxSzT = app.data.imgAnalysisData.pxSzT;
                    imgMetadata.pxSz_x = app.data.imgAnalysisData.pxSz_x;
                    imgMetadata.pxSz_y = app.data.imgAnalysisData.pxSz_y;
                    imgMetadata.n_ch = app.data.imgAnalysisData.nCh;
                    % recalculate time in case image series was cropped 
                    if isempty(app.data.imgAnalysisData.crop_roi_pos)
                        imgMetadata.t = app.data.imgAnalysisData.t;
                    else
                        dt = imgMetadata.pxSzT;
                        t = (dt:dt:N_imgs*dt) - dt;
                        imgMetadata.t = t(:);
                    end
                else
                    uialert(app.UIFigure, ...
                        'Analysis file related to selected TIFF image series was not found.', ...
                        'Analysis file not found!', 'Icon','error')
                    app.data.loadAnalyzedImgSeriesFlag = false;
                    return
                end
            else
                imgMetadata = app.getImgMetadataFromImgDescrStr(...
                    imgDescrStr, N_imgs);
            end

            % save metadata to app data
            app.data.t = imgMetadata.t;
            app.data.pxSzT = imgMetadata.pxSzT;

            app.data.pxSz_x = imgMetadata.pxSz_x;
            app.data.pxSz_y = imgMetadata.pxSz_y;

            % fill metadata in table
            app.UITable_imgMeta.Data = {fullfile(filePath, fileName{1}), ...
                                imgMetadata.n_ch, ...
                                N_imgs, ...
                                img_width, ...
                                img_height, ...
                                app.data.pxSz_x, ...
                                app.data.pxSz_y, ...
                                app.data.pxSzT};
            
            % filter image data based on filters defined in settings
            % add progressbar
            h_dlg = uiprogressdlg(app.UIFigure, ...
                'Indeterminate','on', ...
                'Message', '', ...
                'Title', 'Filtering image series.');
            % get filters names and params
            filtersSeq = app.data.defaultFiltersUsed.filters;
            filtersSeq(filtersSeq.filter=='none',:) = [];
            imgDataFilt = imgData;
            for idx = 1:height(filtersSeq)
                imgDataFilt = app.filterImgSeries(imgDataFilt, ...
                    filtersSeq(idx,:), h_dlg);
            end
            close(h_dlg)
            
            % show used filters in uitable filters applied to image series
            app.UITable_filters.Data = app.data.defaultFiltersUsed.filters;

            % save data
            app.data.imgDataAll = imgData;
            app.data.imgData = imgDataFilt;
            app.data.imgDataRaw = imgData;
            % create cell mask, all true
            app.data.cellMask = true(size(imgData));

            % rescale axes and respective uicontrols 
            % based on size of image
            showImgAndRescaleAxes(app, event)
            
            % clear events tab content
            clearAppContent(app)

            showFilteredImgWithCellMask(app, event, true)
            
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow
            
        end

        % Callback function: Slider_imgFrame, Slider_imgFrame, 
        % ...and 5 other components
        function showImgInSeriesSelectedWithSlider(app, event)
            % check if there are some data
            if isempty(app.data.imgData)
                return
            end
            
            % new current frame
            new_val = round(event.Source.Value);

            % check from which tab is it called
            if contains(event.Source.Tag, 'events')
                % set up time, slider and spinner
                app.EditField_imgTime_events.Value = app.data.t(new_val);
                app.Slider_imgFrame_events.Value = new_val;
                app.Spinner_imgFrame_events.Value = new_val;
                
                % show selected frame from image series with events
                showFilteredImgWithEventsBoundaries(app, event, false, false)

            else
                % set up time, slider and spinner
                app.EditField_imgTime.Value = app.data.t(new_val);
                app.Slider_imgFrame.Value = new_val;
                app.Spinner_imgFrame.Value = new_val;
            
                % show selected frame from image series 
                % with cell mask if calculated
                showFilteredImgWithCellMask(app, event, false)
            end
             
        end

        % Value changed function: DropDown_lookuptable, 
        % ...and 5 other components
        function changeColormap(app, event)
            % check if there are some data
            if isempty(app.data.imgData)
                return
            end
            % check from which tab is it called
            if contains(event.Source.Tag, 'events')
                edit_min_val = app.EditField_setglobalmin_events;
                edit_max_val = app.EditField_setglobalmax_events;
                ax = app.UIAxes_img_events;
                lookup_table = app.DropDown_lookuptable_events;

            else
                edit_min_val = app.EditField_setglobalmin;
                edit_max_val = app.EditField_setglobalmax;
                ax = app.UIAxes_img;
                lookup_table = app.DropDown_lookuptable;
            end

            % set caxis limits for image, if were changend
            if edit_min_val.Value >= edit_max_val.Value
                edit_min_val.Value = edit_max_val.Value - 1;
            end

            ax.CLim = [edit_min_val.Value edit_max_val.Value];

            % set new colormap
            colormap(ax, lookup_table.Value);

            % change color of patch representing cell mask
            cellMaskPatch_h = findobj(ax, ...
                'Type', 'Patch', '-and', 'Tag', 'cellMask');
            if ~isempty(cellMaskPatch_h)
                % select new color for patch
                patch_color = app.cellMaskPatchColor;
                cellMaskPatch_h.FaceColor = patch_color;
                cellMaskPatch_h.EdgeColor = patch_color;
            end
           
        end

        % Button pushed function: Button_detectevents
        function detectCalciumEvents(app, event)
            
            % show state
            app.stateLampLabel.Text = 'busy';
            app.Lamp_state.Color = [1 0 0];
            drawnow
            % get data
            imgData = double(app.data.imgData);
            n_imgs = size(imgData,3);
            % check if cell mask is calculated
            if ~isfield(app.data, 'cellMask')
                if app.data.batchMode
                    % set whole cellMask as true
                    app.data.cellMask = true(size(imgData));
                else
                    % % ask if continue without cell mask
                    choice = uiconfirm(app.UIFigure, ...
                        'Continue without cell mask?', ...
                        'No cell mask!', ...
                        'Options',{'Continue', 'Cancel'}, ...
                        'DefaultOption',2);
                    switch choice
                        case 'Continue'
                            app.data.cellMask = true(size(imgData));
                        case 'Cancel'
                            % show state
                            app.stateLampLabel.Text = 'ready';
                            app.Lamp_state.Color = [0 1 0];
                            drawnow
                            return
                    end
                end
            end

            % if in batch mode, save analysis data
            % save data of analysis
            if app.data.batchMode || app.CheckBox_saveDetection.Value
                [img_path_matFile,img_fn_matFile,~] = fileparts(app.data.imgPath);
                % data to save
                imgAnalysisData.imgAnalysisData_filePath = ...
                    fullfile(img_path_matFile, sprintf('%s_results.mat',img_fn_matFile));
                imgAnalysisData.imgSeriesPath = app.data.imgPath;
                imgAnalysisData.cellMask = app.data.cellMask;
                % blank and F0
                try
                    imgAnalysisData.F0 = app.data.F0;
                    imgAnalysisData.img_blank = app.data.img_blank;
                catch
                    imgAnalysisData.F0 = [];
                    imgAnalysisData.img_blank = [];
                end
                imgAnalysisData.calcF0ForEachFrame = app.CheckBox_F0ForEachFrame.Value;
                % add crop position
                try
                    imgAnalysisData.crop_s_frame = app.data.crop_s_frame;
                    imgAnalysisData.crop_e_frame = app.data.crop_e_frame;
                    imgAnalysisData.crop_roi_pos = app.data.crop_roi_xy_pos;
                catch
                    imgAnalysisData.crop_s_frame = [];
                    imgAnalysisData.crop_e_frame = [];
                    imgAnalysisData.crop_roi_pos = [];
                end
                % img meta data
                imgAnalysisData.pxSz_x = app.data.pxSz_x;
                imgAnalysisData.pxSz_y = app.data.pxSz_y;
                imgAnalysisData.pxSzT = app.data.pxSzT;
                imgAnalysisData.t = app.data.t;
                imgAnalysisData.nCh = app.UITable_imgMeta.Data{2};
            end

            % detect calcium events with selected method
            progressBar_h = uiprogressdlg(app.UIFigure, ...
                'Title', 'Please Wait',...
                'Message', 'detecting calcium events (this might take several minutes)...', ...
                'Indeterminate', 'on');
            try
                switch app.DropDown_detectionMethod.Value
                    case 'U-Net'
                        [mask_sparks, mask_puffs, mask_waves] = ...
                            app.detectEvents_unet;
                        
                    case 'threshold'
                        [mask_sparks, mask_puffs, mask_waves] = ...
                            app.detectEvents_threshold;      
                end 
            catch
                % Close progressbar
                close(progressBar_h)
                return
            end
            
            % save events masks
            if app.data.batchMode || app.CheckBox_saveDetection.Value
                imgAnalysisData.maskOfDetected_sparks = mask_sparks;
                imgAnalysisData.maskOfDetected_puffs = mask_puffs;
                imgAnalysisData.maskOfDetected_waves = mask_waves;
            end

            % check if continue with analysis of detected events in batch
            % mode
            if app.data.batchMode && ...
                    strcmp(app.DropDown_typeOfAnalysisInBatch.Value, ...
                    'detect events')
                % save data of analysis
                imgAnalysisData.detectedEvents = [];
                % delete analysis file if exist
                if exist(imgAnalysisData.imgAnalysisData_filePath, 'file')
                    delete(imgAnalysisData.imgAnalysisData_filePath)
                end
                save(imgAnalysisData.imgAnalysisData_filePath, ...
                    'imgAnalysisData')
                % close progress bar
                close(progressBar_h)
                % show state
                app.stateLampLabel.Text = 'ready';
                app.Lamp_state.Color = [0 1 0];
                drawnow
                return
            end

            % filter detected events, do not take very small ones
            % min area of event 
            minEvntArea = 50;
            
            % get individual events in BW masks of frames
            detectedEvents = table('Size',[n_imgs,7], ...
                'VariableTypes', { 'uint32', 'cell', 'cell',...
                'cell', 'cell', 'cell', 'cell'}, ...
                'VariableNames', {'imgNum', ...
                'maskOfEvents_sparks', 'propsOfEvents_sparks', ...
                'maskOfEvents_puffs', 'propsOfEvents_puffs',...
                'maskOfEvents_waves', 'propsOfEvents_waves'});
            for i=1:n_imgs
                % process masks of sparks, puffs and waves
                % sparks
                CC = bwconncomp(imfill(mask_sparks(:,:,i), 8, 'holes'), 8);
                propsOfEvents = regionprops(CC, imgData(:,:,i), ...
                    'Area','MeanIntensity','WeightedCentroid','PixelIdxList');
                % filter out small events
                propsOfEvents = propsOfEvents([propsOfEvents.Area]>minEvntArea);
                detectedEvents.imgNum(i) = i;
                detectedEvents.maskOfEvents_sparks(i) = {mask_sparks(:,:,i)};
                detectedEvents.propsOfEvents_sparks(i) = {propsOfEvents};
                
                % puffs
                CC = bwconncomp(imfill(mask_puffs(:,:,i), 8, 'holes'), 8);
                propsOfEvents = regionprops(CC, imgData(:,:,i), ...
                    'Area','MeanIntensity','WeightedCentroid','PixelIdxList');
                % filter out small events
                propsOfEvents = propsOfEvents([propsOfEvents.Area]>minEvntArea);
                detectedEvents.imgNum(i) = i;
                detectedEvents.maskOfEvents_puffs(i) = {mask_puffs(:,:,i)};
                detectedEvents.propsOfEvents_puffs(i) = {propsOfEvents};
                
                % waves
                CC = bwconncomp(imfill(mask_waves(:,:,i), 8, 'holes'), 8);
                propsOfEvents = regionprops(CC, imgData(:,:,i), ...
                    'Area','MeanIntensity','WeightedCentroid','PixelIdxList');
                % filter out small events
                propsOfEvents = propsOfEvents([propsOfEvents.Area]>minEvntArea);
                detectedEvents.imgNum(i) = i;
                detectedEvents.maskOfEvents_waves(i) = {mask_waves(:,:,i)};
                detectedEvents.propsOfEvents_waves(i) = {propsOfEvents};
                
            end
        
            % allocate matrix for eventIDs
            % save mask of IDs of events
            app.data.eventsIDs = zeros(size(imgData) ,'uint32');
            % cell array with eventsIds and respective detected type of
            % events
            app.data.eventsIDs_types = {};

            % go through all image masks
            % set up progress bar
            progressBar_h.Message = ...
                {'Combining detected events in time dimension.', ...
                'Processing image #1'};
            progressBar_h.Indeterminate = 'off';

            for i = 1:n_imgs
                % update value of progress bar
                progressBar_h.Value = i/n_imgs;
                progressBar_h.Message = ...
                    {'Combining detected events in time dimension.', ...
                    sprintf('Processing image #%d',i)};
    
                % process detected sparks in frame,
                % update eventsIDs matrix and next used eventID number
                combineDetectedEventsInTime(app, ...
                    detectedEvents.propsOfEvents_sparks, ...
                    i, 'spark');
                
                % process detected puffs in frame,
                % update eventsIDs matrix and next used eventID number
                combineDetectedEventsInTime(app, ...
                    detectedEvents.propsOfEvents_puffs, ...
                    i, 'puff');
                
                % process detected waves in frame,
                % update eventsIDs matrix and next used eventID number
                combineDetectedEventsInTime(app, ...
                    detectedEvents.propsOfEvents_waves, ...
                    i, 'wave');
            end

            % update progressbar message
            progressBar_h.Message = ...
                {'Creating final table of detected events.', ...
                'Processing event #1'};
            progressBar_h.Indeterminate = 'off';
            
            % total number of events, take it from matrix, just in case
            % some events were merged 
            eIDs = unique(app.data.eventsIDs(:));
            eIDs(eIDs==0) = [];

            % final table of events groupped 
            finalTableOfEvents = table( ...
                'Size', [numel(eIDs), 16], ...
                'VariableTypes', ...
                { 'uint32', 'uint32', 'uint32', ...
                'cell', 'double', 'cell', ...
                'double', 'double', 'double', ...
                'double', 'double', 'double', 'double', ...
                'double', ...
                'logical', 'struct'}, ...
                'VariableNames', ...
                {'eventID','imgNum_s', 'imgNum_e', ...
                'boundary', 'color', 'eventType', ...
                'amplitude', 'TTP', 'FDHM', ...
                'tauRise', 'tauDecay', 'FWHMx', 'FWHMy', ...
                'waveSpeed', ...
                'accepted', 'fits_params_event'});
            for e = 1:numel(eIDs)
                % update value of progress bar
                progressBar_h.Value = e/numel(eIDs);
                progressBar_h.Message = ...
                    {'Creating final table of detected events.', ...
                    sprintf('Processing event #%d',e)};
                
                % get position of detected and merged events
                [I1,I2,I3] = ind2sub(size(app.data.eventsIDs), find(app.data.eventsIDs == eIDs(e)));
                % event boundaries, one for each frame
                eventB = cell(max(I3)-min(I3)+1, 1);
                for f = min(I3):max(I3)
                    eventB(f-min(I3)+1) = bwboundaries(app.data.eventsIDs(:,:,f)==eIDs(e), 8, 'noholes');
                end
                
                % mark as accepted only events with duration more than 2
                % frames
                if (max(I3)-min(I3)+1)>2
                    finalTableOfEvents.accepted(e) = true;
                else
                    % skip event 
                    continue
                    %finalTableOfEvents.accepted(e) = false;
                end
                % save data to table
                finalTableOfEvents.eventID(e) = eIDs(e);
                finalTableOfEvents.imgNum_s(e) = min(I3);
                finalTableOfEvents.imgNum_e(e) = max(I3);
                finalTableOfEvents.boundary(e) = {eventB};
                % find type of event
                idx_type_evnt = find([app.data.eventsIDs_types{:,1}] == eIDs(e));
                finalTableOfEvents.eventType(e) = app.data.eventsIDs_types(idx_type_evnt,2);
  
                % fit event and get its parameters
                eventFits = app.getProfileAndParamsOfSelectedEvent(...
                    {eventB}, min(I3), max(I3), ...
                    app.data.eventsIDs_types(idx_type_evnt,2), ...
                    [], [], []);
                % save fits and parameters of event in final table of
                % events
                finalTableOfEvents.fits_params_event(e).tProfFit = ...
                    eventFits.tProfFit;
                finalTableOfEvents.fits_params_event(e).eventMaxImg2DFit = ...
                    eventFits.eventMaxImg2DFit;
                % supporting parameters of event, used to draw parameters
                % in profile
                finalTableOfEvents.fits_params_event(e).eventSupportParams = ...
                    eventFits.eventSupportParams;
                % parameters of event
                finalTableOfEvents.amplitude(e) = eventFits.eventParams.amplitude;
                finalTableOfEvents.TTP(e) = eventFits.eventParams.TTP;
                finalTableOfEvents.FDHM(e) = eventFits.eventParams.FDHM;
                finalTableOfEvents.tauRise(e) = eventFits.eventParams.tauRise;
                finalTableOfEvents.tauDecay(e) = eventFits.eventParams.tauDecay;
                finalTableOfEvents.FWHMx(e) = eventFits.eventParams.FWHMx;
                finalTableOfEvents.FWHMy(e) = eventFits.eventParams.FWHMy;
                finalTableOfEvents.waveSpeed(e) = eventFits.eventParams.waveSpeed;
            end
            
            % setup variable units in table
            finalTableOfEvents.Properties.VariableUnits = ...
                {'','','','','','', ...
                'deltaF/F0', 'ms', 'ms', 'ms', 'ms', ...
                'µm', 'µm', 'µm/s', '', ''};
            
            % remove events with ID = zero
            finalTableOfEvents(...
                finalTableOfEvents.eventID==0,:)=[];
            
            % add color for individual ROIs
            finalTableOfEvents.color = hsv(height(finalTableOfEvents));
            
            % save table
            app.data.detectedEvents = finalTableOfEvents;
       
            % Close progressbar
            close(progressBar_h)
            
            % save anlysis data to app.data
            app.data.imgAnalysisData = imgAnalysisData;

            % if in batch mode do not show detected events, just save table
            % of detected events
            if app.data.batchMode
                % save analysis data
                save(imgAnalysisData.imgAnalysisData_filePath, ...
                        'imgAnalysisData')
                return
            end
            
            % rescale axes and respective uicontrols
            % based on size of image
            showImgAndRescaleAxes(app, event)
            % show detected events with image
            showFilteredImgWithEventsBoundaries(app, event, false, true)
            
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow

        end

        % Button pushed function: Button_play, Button_play_events
        function playWholeImgSeries(app, event)
            % data
            imgData = app.data.imgData;
            % check if there are some data
            if isempty(imgData)
                return
            end
            
            % profile on
            % check from which tab it is called and select appropriate
            % elements
            switch event.Source.Tag
                case 'play_imgSeries'
                    ax = app.UIAxes_img;
                    edit_frameRate = app.EditField_framesPerSec;
                    spinner = app.Spinner_imgFrame;
                    stopButton = app.Button_stop;
                    playInLoop = app.CheckBox_playInLoop;
                    edit_imgTime = app.EditField_imgTime;
                    slider_imgFrame = app.Slider_imgFrame;
                    whereToShow = 'imgSeries';
                    
                case 'play_imgSeries_events'
                    ax = app.UIAxes_img_events;
                    edit_frameRate = app.EditField_framesPerSec_events;
                    spinner = app.Spinner_imgFrame_events;
                    stopButton = app.Button_stop_events;
                    playInLoop = app.CheckBox_playInLoop_events;
                    edit_imgTime = app.EditField_imgTime_events;
                    slider_imgFrame = app.Slider_imgFrame_events;
                    whereToShow = 'imgSeries_events';
            end
                  
            % get handle to image shown in axes
            img_series_h = findobj(ax, 'Type', 'Image');
            % get speed of playing
            frames_s = edit_frameRate.Value;
            if frames_s > 50
                frames_s = 50;
                edit_frameRate.Value = frames_s;
            end
            
            % get current position of slider or spinner
            curr_frame = spinner.Value;
            if curr_frame==size(imgData,3)
                curr_frame = 1;
            end
            % set stop button
            stopButton.UserData.cliked = false;
            % remove previous cell mask patch object
            delete( findobj(ax, 'Type','Patch', 'Tag','cellMask') )
         
            % patch color
            patchColor = app.cellMaskPatchColor;
            % calculate cell boundary for each frame
            cellBoundary = cell(size(app.data.cellMask,3),1);
            for f=1:size(app.data.cellMask, 3)
                cellBoundary(f) = ...
                    bwboundaries(app.data.cellMask(:,:,f), 8, 'noholes');
            end

            % choose what to show
            switch whereToShow
                case 'imgSeries'
                    % check if to show cell mask
                    if app.CheckBox_cellMask_show.Value
                        
                        % show cell mask
                        showCellMask = true;
                        cell_boundary_h = patch( ...
                            'Faces',(1:numel(cellBoundary{curr_frame}(:,2))), ...
                            'Vertices',[cellBoundary{curr_frame}(:,2), ...
                            cellBoundary{curr_frame}(:,1)], ...
                            'FaceColor',patchColor, 'FaceAlpha',0.2, ...
                            'EdgeColor',patchColor, 'EdgeAlpha',1, ...
                            'Parent', ax, ...
                            'LineWidth',1, 'Tag', 'cellMask');
                    else
                        showCellMask = false;
                    end

                case 'imgSeries_events'
                    % check if to show cell mask in image with detected
                    % events
                    if app.CheckBox_showCellMask_events.Value
                        showCellMask = true;
                        cell_boundary_h = patch( ...
                            'Faces',(1:numel(cellBoundary{curr_frame}(:,2))), ...
                            'Vertices',[cellBoundary{curr_frame}(:,2), ...
                            cellBoundary{curr_frame}(:,1)], ...
                            'FaceColor',patchColor, 'FaceAlpha',0, ...
                            'EdgeColor',patchColor, 'EdgeAlpha',1, ...
                            'Parent', ax, ...
                            'LineWidth',1, 'Tag', 'cellMask');
                    else
                        showCellMask = false;
                    end
                    
                    % check if to show detected events
                    if ~isempty(app.data.detectedEvents) && ...
                            app.CheckBox_showevents_events.Value
                        % keyboard
                        % get boundaries and colors of evens in current frame
                        showEvents = true;
                        eventsInAllFrames = app.data.eventsInAllFrames;
                       
                        % check if to show mask
                        if app.CheckBox_showmask_events.Value
                            showMaskOfEvents = true;
                        else
                            showMaskOfEvents = false;
                        end
                        % and boundary
                        if app.CheckBox_showBB_events.Value
                            showBBofEvents = true;
                        else
                            showBBofEvents = false;
                        end     
                    else
                        showEvents = false;
                    end
            end
            
            switch event.Source.Text
                case 'play'
                    if playInLoop.Value
                        i=curr_frame;
                        while ~stopButton.UserData.cliked
                            % once finish circle, set i=1
                            if i > size(imgData,3)
                                i = 1;
                            end
                            edit_imgTime.Value = app.data.t(i);
                            slider_imgFrame.Value = i;
                            spinner.Value = i;

                            % image
                            img_series_h.CData = imgData(:,:,i);
                            % check if to show cell mask and events
                            switch whereToShow
                                case 'imgSeries'
                                    % cell mask
                                    if showCellMask
                                        cell_boundary_h.Faces = ...
                                            (1:numel(cellBoundary{i}(:,2)));
                                        cell_boundary_h.Vertices = ...
                                            [cellBoundary{i}(:,2), ...
                                            cellBoundary{i}(:,1)];
                                    end
                                
                                case 'imgSeries_events'
                                    % cell mask
                                    if showCellMask
                                        cell_boundary_h.Faces = ...
                                            (1:numel(cellBoundary{i}(:,2)));
                                        cell_boundary_h.Vertices = ...
                                            [cellBoundary{i}(:,2), ...
                                            cellBoundary{i}(:,1)];
                                    end
                                    % events visualisation
                                    % set events from previous frame to invisible
                                 
                                    if i>1
                                        set(eventsInAllFrames{i-1}, 'Visible', 'off')
                                        set(eventsInAllFrames{i-1}, 'FaceAlpha', 0)
                                        set(eventsInAllFrames{i-1}, 'EdgeAlpha', 0)
                                    else
                                        set(eventsInAllFrames{end}, 'Visible', 'off')
                                        set(eventsInAllFrames{end}, 'FaceAlpha', 0)
                                        set(eventsInAllFrames{end}, 'EdgeAlpha', 0)
                                    end

                                    % show only the ones in frame of interest
                                    % check if to show all or only selected event
                                    if app.CheckBox_showSelectedEvent.Value
                                        m_selectedEvents = arrayfun( @(x) ...
                                            strcmp(x.Tag, num2str(app.data.selectedEvent.eventID)), ...
                                            eventsInAllFrames{i} );
                                    else
                                        m_selectedEvents = true(numel(eventsInAllFrames{i}),1);
                                    end
                                    % show only accepted events
                                    m_acceptedEvents = true(numel(eventsInAllFrames{i}),1);
                                    for e = 1:numel(eventsInAllFrames{i})
                                        m_acceptedEvents(e) = ...
                                            app.UITable_events.Data.accepted( ...
                                            str2double(eventsInAllFrames{i}(e).Tag)==...
                                            app.UITable_events.Data.eventID );
                                    end
                                    m_selectedEvents = m_selectedEvents & m_acceptedEvents;

                                    % show only the ones in frame of
                                    % interest
                                    if showEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'Visible','on')
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'Visible','off')
                                    end
                                    
                                    if showMaskOfEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'FaceAlpha',0.2)
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'FaceAlpha',0)
                                    end
                                    
                                    if showBBofEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'EdgeAlpha',1)
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'EdgeAlpha',0)
                                    end
                                    
                            end
                            
                            i = i+1;
                            %drawnow
                            pause(1/frames_s)
                        end
                        
                    else
                        for i=curr_frame:size(imgData,3)
                            %tic
                            edit_imgTime.Value = app.data.t(i);
                            slider_imgFrame.Value = i;
                            spinner.Value = i;
                            
                            % show image
                            img_series_h.CData = imgData(:,:,i);
                            % check if to show cell mask and events
                            switch whereToShow
                                case 'imgSeries'
                                    % cell mask
                                    if showCellMask
                                        cell_boundary_h.Faces = ...
                                            (1:numel(cellBoundary{i}(:,2)));
                                        cell_boundary_h.Vertices = ...
                                            [cellBoundary{i}(:,2), ...
                                            cellBoundary{i}(:,1)];
                                    end
                                case 'imgSeries_events'
                                    % cell mask
                                    if showCellMask
                                        cell_boundary_h.Faces = ...
                                            (1:numel(cellBoundary{i}(:,2)));
                                        cell_boundary_h.Vertices = ...
                                            [cellBoundary{i}(:,2), ...
                                            cellBoundary{i}(:,1)];
                                    end
                                    % events visualisation
                                    % set events from previous frame to invisible
                                    if i>1
                                        set(eventsInAllFrames{i-1}, 'Visible', 'off')
                                        set(eventsInAllFrames{i-1}, 'FaceAlpha', 0)
                                        set(eventsInAllFrames{i-1}, 'EdgeAlpha', 0)
                                    else
                                        % set events in last frame as invisible, in
                                        % case there are still visible from previous
                                        % runs
                                        set(eventsInAllFrames{end}, 'Visible', 'off')
                                        set(eventsInAllFrames{end}, 'FaceAlpha', 0)
                                        set(eventsInAllFrames{end}, 'EdgeAlpha', 0)
                                    end
                              
                                    % show only the ones in frame of interest
                                    % check if to show all or only selected event
                                    if app.CheckBox_showSelectedEvent.Value
                                        m_selectedEvents = arrayfun( @(x) ...
                                            strcmp(x.Tag, num2str(app.data.selectedEvent.eventID)), ...
                                            eventsInAllFrames{i} );
                                    else
                                        m_selectedEvents = true(numel(eventsInAllFrames{i}),1);
                                    end
                                    % show only accepted events
                                    m_acceptedEvents = true(numel(eventsInAllFrames{i}),1);
                                    for e = 1:numel(eventsInAllFrames{i})
                                        m_acceptedEvents(e) = ...
                                            app.UITable_events.Data.accepted( ...
                                            str2double(eventsInAllFrames{i}(e).Tag)==...
                                            app.UITable_events.Data.eventID );
                                    end
                                    m_selectedEvents = m_selectedEvents & m_acceptedEvents;
                                    
                                    
                                    % show only the ones in frame of
                                    % interest
                                    if showEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'Visible', 'on')
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'Visible', 'off')
                                    end
                                    
                                    if showMaskOfEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'FaceAlpha', 0.25)
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'FaceAlpha', 0)
                                    end
                                    
                                    if showBBofEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'EdgeAlpha', 1)
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'EdgeAlpha', 0)
                                    end
                                    
                            end

                            % drawnow
                             pause(1/frames_s)
                            %T(i)= toc;
                            % stop playing
                            if stopButton.UserData.cliked
                                break
                            end
                        end
                    end
            end
            
            
 %            profile viewer  % view the timing report
%             profile off     % to stop the timer 

        end

        % Button pushed function: Button_stop, Button_stop_events
        function stopPlayingImgSeries(app, event)
            event.Source.UserData.cliked = true;
        end

        % Button pushed function: Button_crop
        function cropImgSeries(app, event)
            % get data
            imgData = app.data.imgData;
            
            % check if there are some data
            if isempty(imgData)
                return
            end
          
            switch event.Source.Text

                case 'select xy area to crop'
                    % delete previous crop roi                   
                    delete( findobj(app.UIAxes_img, ...
                        'Type', 'images.roi.rectangle', ...
                        'Tag', 'cropROIxy') )
                    
                    % suggest size of cropping rectangle
                    % get 25th percentile from max xy image - estimation of background
                    if ~isempty(imgData)
                        imgData_max = mean(imgData,3);
                  
                        bg_est = double(prctile(imgData_max(:), 25));
                        mImg = imgData_max - bg_est*1.05;
                        mImg(mImg>0) = 1;
                        mImg(mImg<=0) = 0;
                        try
                            % find biggest rectangle, which is in image area
                            img_CC_event = bwconncomp(logical(mImg),8);

                            %calculate properties of all CC (regions)
                            statEvents = regionprops(img_CC_event,imgData_max, ...
                                'SubarrayIdx', 'Area','BoundingBox');
                            [~,indMaxArea] = max([statEvents.Area]);
                            suggested_pos = statEvents(indMaxArea).BoundingBox;

                            % create crop ROI
                            crop_roi_xy = drawrectangle( ...
                                app.UIAxes_img, 'Color',[1 0 0],...
                                'Position', suggested_pos, ...
                                'Tag', 'cropROIxy');

                        catch
                            % create crop ROI
                            crop_roi_xy = drawrectangle( ...
                                app.UIAxes_img, 'Color',[1 0 0],...
                                'Position', [10, 10, size(imgData,2)-20, size(imgData,1)-20], ...
                                'Tag', 'cropROIxy');
                        end
                    else
                            % create crop ROI
                            crop_roi_xy = drawrectangle( ...
                                app.UIAxes_img, 'Color',[1 0 0],...
                                'Position', [10, 10, size(imgData,2)-20, size(imgData,1)-20], ...
                                'Tag', 'cropROIxy');
                    end
                    
                    % set up name of pushbutton
                    app.Button_crop.Text = 'crop';
                    app.Button_crop.FontWeight = 'bold';
                    
                    
                case 'crop'
                    % get start and end frames
                    if app.data.loadAnalyzedImgSeriesFlag
                        % get position from saved image analysis data
                        s_frame = app.data.imgAnalysisData.crop_s_frame;
                        e_frame = app.data.imgAnalysisData.crop_e_frame;
                        ROI_pos = app.data.imgAnalysisData.crop_roi_pos;
                    else
                        % get position from ROI
                        s_frame = round(app.EditField_cropStartFrame.Value);
                        e_frame = round(app.EditField_cropEndFrame.Value);
                        if s_frame < 1
                            s_frame = 1;
                        end
                        if e_frame > size(app.data.imgData,3)
                            e_frame = size(app.data.imgData,3);
                        end

                        % get position if xy roi
                        crop_roi_xy = findobj(app.UIAxes_img, ...
                            'Type', 'images.roi.rectangle', ...
                            'Tag', 'cropROIxy');
                        ROI_pos = round(crop_roi_xy.Position);
                        ROI_pos(ROI_pos<1) = 1;
                        if ROI_pos(1)+ROI_pos(3) > size(app.data.imgData,2)
                            if ROI_pos(1)==1
                                ROI_pos(3) = size(app.data.imgData,2)-1;
                            else
                                ROI_pos(3) = ROI_pos(3) - abs(size(app.data.imgData,2)-ROI_pos(3));
                            end
                        end
                        if ROI_pos(2)+ROI_pos(4) > size(app.data.imgData,1)
                            if ROI_pos(2)==1
                                ROI_pos(4) = size(app.data.imgData,1)-1;
                            else
                                ROI_pos(4) = ROI_pos(4) - abs(size(app.data.imgData,1)-ROI_pos(4));
                            end
                        end
                        % delete crop ROI
                        delete(crop_roi_xy)
                    end

                    % crop data
                    app.data.imgData = app.data.imgData( ...
                        ROI_pos(2):ROI_pos(2)+ROI_pos(4), ...
                        ROI_pos(1):ROI_pos(1)+ROI_pos(3), ...
                        s_frame:e_frame);
                    app.data.imgDataRaw = app.data.imgDataRaw( ...
                        ROI_pos(2):ROI_pos(2)+ROI_pos(4), ...
                        ROI_pos(1):ROI_pos(1)+ROI_pos(3), ...
                        s_frame:e_frame);
                    app.data.cellMask = app.data.cellMask( ...
                        ROI_pos(2):ROI_pos(2)+ROI_pos(4), ...
                        ROI_pos(1):ROI_pos(1)+ROI_pos(3), ...
                        s_frame:e_frame);
                    
                    % recalculate new t axis
                    app.data.t = app.data.t(s_frame:e_frame);
                    % save where it was cropped
                    if ~isempty(app.data.crop_s_frame)
                        app.data.crop_s_frame = app.data.crop_s_frame + s_frame;
                        app.data.crop_e_frame = app.data.crop_s_frame + (e_frame-s_frame);
                        app.data.crop_roi_xy_pos = [...
                            app.data.crop_roi_xy_pos(1)+ROI_pos(1), ...
                            app.data.crop_roi_xy_pos(2)+ROI_pos(2), ...
                            ROI_pos(3), ROI_pos(4) ];
                    else
                        app.data.crop_s_frame = s_frame;
                        app.data.crop_e_frame = e_frame;
                        app.data.crop_roi_xy_pos = ROI_pos;
                    end

                    % show cropped image series
                    showImgAndRescaleAxes(app, event)
                    showFilteredImgWithCellMask(app, event, true)

                    % set up pushbutton for cropping
                    event.Source.Text = 'select xy area to crop';
                    event.Source.FontWeight = 'normal';

            end         
        end

        % Callback function
        function selectFilter(app, event)
            % check if there are some data
           
            
            % get filter type
            filterName = app.DropDown_imgFilter.Value;
            % enable to change filter parameters
            app.EditField_filterParam1.Enable = 'on';
            app.EditField_filterParam2.Enable = 'on';
            app.Label_filterParam1.Tooltip = '';
            app.Label_filterParam2.Tooltip = '';
            % when changing filter with dropdown menu suggest default
            % values of filters parameters; 
            % if changing parameters then validate inputs
            switch event.Source.Type
                case 'uidropdown'
                    % default values of filters
                    switch filterName
                        case 'none'
                            app.EditField_filterParam1.Enable = 'off';
                            app.EditField_filterParam2.Enable = 'off';
                            app.Label_filterParam1.Text = 'none';
                            app.Label_filterParam2.Text = 'none';
                            app.EditField_filterParam1.Value = 0;
                            app.EditField_filterParam2.Value = 0;
                            drawnow

                        case 'anisotropic diffusion'
                            app.Label_filterParam1.Text = '# of iter';
                            app.Label_filterParam1.Tooltip = 'number of iterations';
                            app.Label_filterParam2.Text = 'none';
                            app.EditField_filterParam2.Enable = 'off';
                            app.EditField_filterParam1.Value = 5;
                            app.EditField_filterParam2.Value = 0;
                            drawnow

                        case 'gaussian'
                            app.Label_filterParam1.Text = 'sigma xy';
                            app.Label_filterParam2.Text = 'sigma t';
                            app.Label_filterParam1.Tooltip = ...
                                {'filter size is 2*ceil(2*sigma)+1, square', ...
                                'if sigma is 0 no filtering'};
                            app.Label_filterParam2.Tooltip = ...
                                {'filter size is 2*ceil(2*sigma)+1, [1,1,n]', ...
                                'if sigma is 0 no filtering'};
                            app.EditField_filterParam1.Value = 1;
                            app.EditField_filterParam2.Value = 0.5;
                            drawnow
                            
                        case 'PURE-let'
                            app.Label_filterParam1.Text = '# of cycles';
                            app.Label_filterParam2.Text = '# of frames';
                            app.Label_filterParam1.Tooltip = ...
                                ['number of cycle-spins (CS>0). ' ...
                                'A high value of CS yields a high-quality denoising result.' ...
                                'Computation time linearly increases with CS.'];
                            app.Label_filterParam2.Tooltip = ...
                                ['number of adjacent frames/slices to be considered' ...
                                'for multi-frame/slices denoising. MF>0 must be odd'];                          
                            % # of cycles
                            app.EditField_filterParam1.Value = 5;
                            % # of frames considered, take 50 ms
                            n_frames = round(50/app.data.pxSzT);
                            if mod(n_frames,2)==0
                                n_frames = n_frames+1;
                            end
                            if n_frames<3
                                n_frames = 3;
                            end
                            app.EditField_filterParam2.Value = n_frames;
                            drawnow

                        otherwise
                            app.Label_filterParam1.Text = 'kernel_sz xy';
                            app.Label_filterParam2.Text = 'kernel_sz t';
                            app.Label_filterParam1.Tooltip = ...
                                'if kernel size is 1, no filtering';
                            app.Label_filterParam2.Tooltip = ...
                                'if kernel size is 1, no filtering';
                            h_dlg.Message = sprintf(...
                                'Filtering image series using %s filter.',filterName);
                            app.EditField_filterParam1.Value = 5;
                            app.EditField_filterParam2.Value = 3;
                            drawnow
                    end

                case 'uinumericeditfield'
                    % validate inputs
                    try
                        switch filterName
                            case 'anisotropic diffusion'
                                % check kernel size, has to positive integer
                                validateattributes(...
                                    app.EditField_filterParam1.Value, ...
                                    'numeric', {'integer','>',0})
                            case 'gaussian'
                                validateattributes(...
                                    app.EditField_filterParam1.Value, ...
                                    'numeric', {'>=',0})
                                validateattributes(...
                                    app.EditField_filterParam2.Value, ...
                                    'numeric', {'>=',0})
                            case 'PURE-let'
                                validateattributes(...
                                    app.EditField_filterParam1.Value, ...
                                    'numeric', {'integer','>',0})
                                validateattributes(...
                                    app.EditField_filterParam2.Value, ...
                                    'numeric', {'integer','odd','>',0})
                            otherwise
                                % check input, has to be odd and min 3
                                validateattributes(...
                                    app.EditField_filterParam1.Value, ...
                                    'numeric', {'integer','odd','>=',1})
                                validateattributes(...
                                    app.EditField_filterParam2.Value, ...
                                    'numeric', {'integer','odd','>=',1})
                        end
                    catch ME
                        errordlg(ME.message)
                        % show state
                        stateLampLabel.Text = 'ready';
                        stateLamp.Color = [0 1 0];
                        drawnow
                        % close progressbar
                        close(h_dlg)
                        return
                    end
            end

            % get filter parameters
            filterParam1 = app.EditField_filterParam1.Value;
            filterParam2 = app.EditField_filterParam2.Value;
            % apply filter
            imgDataFilt = filterImgSeries(app, ...
                app.data.imgDataRaw, filterName, ...
                filterParam1, filterParam2, h_dlg);
            
            % save caxis limits
            app.data.imgDataCaxisLimits = prctile(imgDataFilt(:),[1 99]);

            % save filtered data
            app.data.imgData = imgDataFilt;
            
            % show selected frame from image series in respective tab
            switch event.Source.Tag
                case 'filter_img' 
                    % with cell mask if calculated
                    showFilteredImgWithCellMask(app, event, true)
                case 'filter_img_events'
                    % with detected events 
                    showFilteredImgWithEventsBoundaries(app, event, true, false)
            end
            % close progress bar
            close(h_dlg)
            % show state
            stateLampLabel.Text = 'ready';
            stateLamp.Color = [0 1 0];
            drawnow
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

%             % ask if to close
%             selection = uiconfirm(app.UIFigure, ...
%                 'Close app?', ...
%                 'Confirm app close...',...
%                 'Options',{'Yes','No'},...
%                 'DefaultOption',2);
%             switch selection
%                 case 'Yes'
                    % save state
                    % get path of app
                    appFile = mfilename('fullpath');
                    [baseDir,~,~] = fileparts(appFile);
                    % prepare sturucture to save
                    previousAppState = struct( ...
                        'previousImgPath', app.data.imgPath, ...
                        'previousCondaEnvPath', app.data.condaEnvPath, ...
                        'filtersUsedDuringImgSeriesLoading', app.data.defaultFiltersUsed);
                    % save file
                    save(fullfile(baseDir,'previousAppState.mat'), ...
                        '-struct', 'previousAppState')
%                     % close app
%                     if isdeployed
%                         quit
%                     else
%                         delete(app.UI)
%                     end
%                 case 'no'
%                     return
%             end
             delete(app.UIFigure)
                
        end

        % Button pushed function: Button_cellMask
        function findCellMask(app, event)

            % check if there are some data
            if isempty(app.data.imgData)
                return
            end

            % show state
            app.stateLampLabel.Text = 'busy';
            app.Lamp_state.Color = [1 0 0];
            drawnow

            % get kernel size used in filtering of image series during finding cell
            % mask procedure
            kernel_sz = app.EditField_kernelSz_cellMask.Value;
            % check kernel size, has to be odd and min 3
            try
                validateattributes(kernel_sz,'numeric',{"integer",'odd','>=',3})
            catch ME
                uialert(app.UIFigure, ME.message, ...
                    'Wrong kernel size input!', 'Icon','error')
                % show state
                app.stateLampLabel.Text = 'ready';
                app.Lamp_state.Color = [0 1 0];
                drawnow
                return
            end

            % get data, first filter them
            %filterImgForAnalysis(app, 'cellEdge')
            imgData = app.data.imgData;
            
            % pre-allocate
            cellMask = false(size(imgData));

            % check if calculate one cell mask for all frames in image series
            % or for each image frame individualy
            if app.CheckBox_cellMaskForEachFrame.Value
                N_edges = size(imgData,3);
            else
                N_edges = 1;
            end
            
            % find cell mask in whole series
            for i=1:N_edges
                % get single image frame
                if app.CheckBox_cellMaskForEachFrame.Value
                    img = imgData(:,:,i);
                else
                    img = prctile(imgData, 99, 3);
                end
                % filter image
                imgFilt = medfilt2(img, [kernel_sz kernel_sz], 'symmetric');
                imgFilt = wiener2(imgFilt, [kernel_sz kernel_sz]);
                H = fspecial('average',[kernel_sz kernel_sz]);
                imgFilt = imfilter(imgFilt, H, 'symmetric');
                imgFilt = imfilter(imgFilt, H, 'symmetric');
                imgFilt = imfilter(imgFilt, H, 'symmetric');

                imgFilt = imcomplement(imgFilt);
                imgFilt = ordfilt2(imgFilt, ...
                    kernel_sz^2, true(kernel_sz), 'symmetric');
                imgFilt = imcomplement(imgFilt);
                % Otsu%s method threshold
                % first normalize to range [0, 1]
                imgFilt = im2double(imgFilt);
                
                mCell = imbinarize(imgFilt./ max(imgFilt(:)),'global');
                % mCell = imbinarize(imgFilt, 'adaptive', 'Sensitivity',0.5);

                temp_filt_img = imgaussfilt(imgFilt,3);
                imgFilt(mCell) = min(temp_filt_img(mCell));

                % Otsu%s method threshold
                imgFilt = imgFilt ./ max(imgFilt(:));
                mCell = imbinarize(imgFilt,'global');

                % fill holes if any
                mCell = imfill(mCell,'holes');
                % take only the biggest object
                cellMask(:,:,i) = bwareafilt(mCell, 1, 8);
                %cellMask = bwareafilt(mCell, 1, 8);
            end

            % save data
            % replicate cell masks and boundaries to have it as many as
            % number of frames
            if ~app.CheckBox_cellMaskForEachFrame.Value
                cellMask = repmat(cellMask(:,:,1),1,1,size(imgData,3));
            end
            app.data.cellMask = cellMask;
            
            % show selected frame from image series with cell mask if calculated
            showFilteredImgWithCellMask(app, event, false)
            
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow
            
        end

        % Button pushed function: Button_cellMask_manualCorrection
        function cellMask_manualCorrection(app, event)
            % cell mask has to be calculated
            if ~isfield(app.data, 'cellMask')
                uialert(app.UIFigure, ...
                    'Please first use an automatic procedure to find cell mask!', ...
                    'cell mask detection')
                return
            else
                if isempty(app.data.cellMask)
                    uialert(app.UIFigure, ...
                        'Please first use an automatic procedure to find cell mask!', ...
                        'cell mask detection')
                    return
                end
            end
            % get color of cell mask patch 
            cellMaskColor = app.cellMaskPatchColor;
            % choose operation based on pushbutton text
            pb = event.Source;
            switch pb.Tag
                case 'start corrections'
                    % fix value of checkbox, whether to aplly corrections 
                    % to all cell masks in all frames or only in selected one
                    app.CheckBox_cellMask_manualCorrection_toAll.Enable = 'off';
                    % check if mask correction applies to all frames/mask or only
                    % to selected one
                    if app.CheckBox_cellMask_manualCorrection_toAll.Value
                        % correct cell mask in maximum or average intensity projection image
                        % imgData_proj = max(imgData, [], 3);
                        % imgData_proj = mean(imgData, 3);
                        imgData_proj = prctile(app.data.imgData, 99, 3);
                        % show max intensity projection
                        img_series_h = findobj(app.UIAxes_img, 'Type', 'Image');
                        img_series_h.CData = imgData_proj;

                        % set up caxis as 1-99 percentiles
                        img_caxis_lims = [min(imgData_proj(:)), max(imgData_proj(:))];
                        app.UIAxes_img.CLim = [img_caxis_lims(1) img_caxis_lims(2)];
                        app.UIAxes_img.Title.String = 'average intensity projection';

                        app.EditField_setglobalmin.Value = double(img_caxis_lims(1));
                        app.EditField_setglobalmax.Value = double(img_caxis_lims(2));
                    end

                    % delete previous cell mask
                    delete(findobj(app.UIAxes_img, 'Type', 'patch', ...
                        'Tag', 'cellMask'))
                    % create temporal dummy axes with the same dimensions as image axes
                    % only for selecting mask
                    ax_maskROI = copyobj(app.UIAxes_img, app.UIAxes_img.Parent);
                    delete(ax_maskROI.Children)
                    colormap(ax_maskROI, [0 0 0; cellMaskColor])
                    set(ax_maskROI, 'XTick',[], 'YTick',[], ...
                        'Color','none', 'Tag','tempROIaxes', ...
                        'XLabel', [], 'YLabel', [], 'CLim',[0 1], ...
                        'Title', [])
                    % disable zooming, moving by mouse in axes
                    disableDefaultInteractivity(ax_maskROI)
                    ax_maskROI.Toolbar.Visible = 'off';
                    % create temporal transparent cell mask, which will be
                    % updated/corrected
                    cellMaskCorrected = ...
                        app.data.cellMask(:,:,app.Spinner_imgFrame.Value);
                    cellMaskCorrected_alpha = double(cellMaskCorrected).*0.25;

                    hImg_tempMaskCell = image(ax_maskROI, ...
                        'CData',cellMaskCorrected,...
                        'YData',[1 size(cellMaskCorrected,1)], ...
                        'XData',[1 size(cellMaskCorrected,2)],...
                        'CDataMapping','scaled', ...
                        'AlphaData',cellMaskCorrected_alpha);

                    % start corrections
                    % create ROI used to correct cell mask
                    cellMaskCorrectionROI = drawrectangle(ax_maskROI, ...
                        'Position',[1,1,5,5], ...
                        'InteractionsAllowed','translate', ...
                        'LineWidth', 2, 'Color',cellMaskColor, ...
                        'Tag', 'addMask');

                    % create context menu
                    cm = uicontextmenu(app.UIFigure);
                    m1 = uimenu(cm,'Text','add mask', ...
                        'MenuSelectedFcn',@app.setMaskCorrectionMode);
                    m2 = uimenu(cm,'Text','remove mask', ...
                        'MenuSelectedFcn',@app.setMaskCorrectionMode);
                    % assign it to mask correction ROI
                    cellMaskCorrectionROI.ContextMenu = cm;

                    % add listener to save changes of boundary made by user
                    % 'MovingROI' ROIMoved
                    addlistener(cellMaskCorrectionROI, ...
                        'MovingROI',@app.updateCellMask);
                    % save temp mask data
                    cellMaskCorr.ax_maskROI = ax_maskROI;
                    cellMaskCorr.cellMaskCorrected = cellMaskCorrected;
                    cellMaskCorr.hImg_tempMaskCell = hImg_tempMaskCell;
                    cellMaskCorr.cellMaskCorrectionROI = cellMaskCorrectionROI;
                    app.data.cellMaskCorr = cellMaskCorr;
                    % change pushbutton to: apply corrections
                    pb.Text  = 'apply corrections';
                    pb.FontColor = [1 0 0];
                    pb.Tag = 'apply corrections';
                    % set up figure functions to control cell mask
                    % correction ROI
                    app.UIFigure.WindowButtonMotionFcn = @app.positionAndSizeOfcellMaskROI;
                    app.UIFigure.WindowScrollWheelFcn = @app.positionAndSizeOfcellMaskROI;
                    % check if there is a conflict between mouse control of
                    % cell mask selection and axes interactivity
                    % (some interactivity on some axes is turned on e.g. zooming, datatip...)
                    if isempty(app.UIFigure.WindowScrollWheelFcn)
                        % throw an error dlg
                        uialert(app.UIFigure, ...
                            {'Please turn off all interactivity in all axes!', ...
                            'e.g. zooming'}, ...
                            'TURN OFF AXES INREACTIVITY')
                        % put it in the start state, basically call the
                        % same callback again
                        app.cellMask_manualCorrection(event)
                    end

                case 'apply corrections'
                    % extract only biggest object from corrected cell mask
                    cellMaskCorrected = bwareafilt( ...
                        app.data.cellMaskCorr.cellMaskCorrected, 1, 8);
                    % fill holes in mask
                    if app.CheckBox_cellMask_manulalCorrection_fillHoles.Value
                        cellMaskCorrected = imfill(cellMaskCorrected,'holes');
                    end
                    % check if mask correction applies to all frames/mask or only
                    % to selected one
                    if app.CheckBox_cellMask_manualCorrection_toAll.Value
                        % apply to all, change cell masks in all frames
                        app.data.cellMask = repmat(cellMaskCorrected, ...
                            1, 1, size(app.data.imgData,3));
                    else 
                        % only change mask of cell in selected frame
                        app.data.cellMask(:,:,app.Spinner_imgFrame.Value) = ...
                            cellMaskCorrected;
                    end

                    % change back settings, delete added things
                    pb.Text  = 'start corrections';
                    pb.Tag = 'start corrections';
                    pb.FontColor = [0 0 1];
                    app.CheckBox_cellMask_manualCorrection_toAll.Enable = 'on';
                    % delete axes with cell mask correction roi and remove
                    % also data associated with it
                    delete(app.data.cellMaskCorr.ax_maskROI)
                    app.data = rmfield(app.data, 'cellMaskCorr');
                    % remove window finctions
                    app.UIFigure.WindowButtonMotionFcn = [];
                    app.UIFigure.WindowScrollWheelFcn = [];

                    % update
                    showFilteredImgWithCellMask(app, event, false)
            end

        end

        % Button pushed function: Button_selfRatio
        function calculate_selfRatio(app, event)
            
            
            keyboard
            % calculate average of cell of each frame
             % check if there are some data and cell edge
            if isempty(app.data.imgData) || ~isfield(app.data, 'cellMask')
                return
            end

            % show state
            app.stateLampLabel.Text = 'busy';
            app.Lamp_state.Color = [1 0 0];
            drawnow
            % filter imgData before normalization, not so heavily filtered
            % filterImgForAnalysis(app, 'calculation_deltaF_div_F0')
            % get data
            imgData = double(app.data.imgData);
            % get maximum cell mask
            cellMask = app.data.cellMask;
            cellMask_proj = any(cellMask, 3);
            
            % get minimum (1st percentile) and average projection of imgData
            F0_imgSeries = prctile(imgData, 1, 3); % F0
            % F0_imgSeries = min(imgData,[],3); % F0
            img_min_proj = min(imgData,3);
                      
            % check whether calculate single F0 for whole image series or
            % for each frame
            if app.CheckBox_F0ForEachFrame.Value
                % for each frame
                F0 = zeros(size(imgData,3),1);
                img_blank = zeros(size(imgData,3),1);
                for i = 1:size(imgData,3)
                    % single frame, only data inside cell mask
                    img = imgData(:,:,i);
                    img_cell = img(cellMask(:,:,i));
                    % get blank from area which is not cell
                    img_blank(i) = median(img(~cellMask(:,:,i)));
                    if isnan(img_blank(i))
                        % means that cell mask is all true, no area ouside
                        % of cell
                        img_blank(i) = 0;
                    end
                    % get mean and SD fluorescence of cell in image
                    % avrg_img = mean(img_cell);
                    % take it from min image projection
                    avrg_img = mean(F0_imgSeries(cellMask(:,:,i)));
                    sd_img = std(img_cell);

                    % calculate F0 as mean from pixels which are less than mean + 1SD
                    F0(i) =  mean( img_cell(img_cell < (avrg_img+1*sd_img)) );

                    % normalize image as (F-F0)/(F0-blank)
                    imgData(:,:,i) = ( imgData(:,:,i) - F0(i) ) ./ ( F0(i) - img_blank(i) );
                end

            else
                % one for whole image series
                blank = median(img_min_proj(~cellMask_proj));
                % subtract blank
                imgData = imgData - blank;
                % normalize
                imgData = imgData ./ F0_imgSeries - 1;
                % filter after to remove outliers due to normalization
                for i=1:size(imgData,3)
                    imgData(:,:,i) = medfilt2(imgData(:,:,i), [3 3]);
                end
                
                % save F0
                F0 = repmat(mean(F0_imgSeries(cellMask_proj)),size(imgData,3),1);
                img_blank = repmat(blank,size(imgData,3),1);
            end
            
            % save normalized data
            app.data.imgData = imgData;
            app.data.F0 = F0;
            app.data.img_blank = img_blank;
            
            % show selected frame from image series with cell mask if calculated
            showFilteredImgWithCellMask(app, event, true)
            
            % show F0
            plot(app.UIAxes_F0, (1:size(imgData,3)), F0, ...
                'Marker', '.', 'MarkerSize', 10, 'Color', 'k', 'LineStyle','-')
            app.UIAxes_F0.XLim = [1 size(imgData,3)];
            app.UIAxes_F0.YLim = [min(F0)*0.99 max(F0)*1.01];
            
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow
            
        end

        % Value changed function: DropDown_photobleachFcn
        function fitF0WithSelectedFcn(app, event)
            keyboard
            
            value = app.DropDown_photobleachFcn.Value;
            
        end

        % Value changed function: CheckBox_cellMask_show
        function showCellMask(app, event)
            % find handle to cell mask
            h_cellMask = findobj(app.UIAxes_img, 'Type', 'patch', ...
                'Tag', 'cellMask');
            % check if there is mask ROI
            if ~isempty(h_cellMask)
                if app.CheckBox_cellMask_show.Value
                    h_cellMask.Visible = 'on';
                else
                    h_cellMask.Visible = 'off';
                end
            end           
        end

        % Cell edit callback: UITable_imgMeta
        function imgMetadataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            switch event.Source.ColumnName{indices(2)}
                case 'pixel size x (um)'
                    app.data.pxSz_x = newData;
                    
                case 'pixel size y (um)'
                    app.data.pxSz_y = newData;
                    
                case 'time step (ms)'
                    app.data.pxSzT = newData;
                    app.data.t = ...
                        (newData:newData:newData*size(app.data.imgData,3)) - newData;
            end
                     
        end

        % Value changed function: CheckBox_showBB_events, 
        % ...and 3 other components
        function showDetectedEvents_checkBoxes(app, event)
            
            % take only events present in current frame
            curr_frame = app.Spinner_imgFrame_events.Value;
            
            % handles of events in current frame
            h_events = app.data.eventsInAllFrames{curr_frame};
            
            switch event.Source.Tag
                case 'check_showBBofEvents'
                    if event.Value
                        arrayfun(@(x) set(x, 'EdgeAlpha', 1), h_events)
                    else
                        arrayfun(@(x) set(x, 'EdgeAlpha', 0), h_events)
                    end


                case 'check_showEvents'
                    if event.Value
                        arrayfun(@(x) set(x, 'EdgeAlpha', 1, ...
                            'FaceAlpha', 0.2, 'Visible', 1), ...
                            h_events)
                        app.CheckBox_showBB_events.Value = 1;
                        app.CheckBox_showmask_events.Value = 1;
                    else
                        arrayfun(@(x) set(x, 'Visible', 0), h_events)
                        app.CheckBox_showBB_events.Value = 0;
                        app.CheckBox_showmask_events.Value = 0;
                    end
                    

                case 'check_showMaskOfEvents'
                    if event.Value
                        arrayfun(@(x) set(x, 'FaceAlpha', 0.25), h_events)
                    else
                        arrayfun(@(x) set(x, 'FaceAlpha', 0), h_events)
                    end
                    

                case 'check_showCellMask_events'
                    h_cellMask = findobj(app.UIAxes_img_events, ...
                        'Type', 'Patch', 'Tag', 'cellMask');
                    if event.Value
                        arrayfun(@(x) set(x, 'EdgeAlpha', 1), h_cellMask)
                    else
                        arrayfun(@(x) set(x, 'EdgeAlpha', 0), h_cellMask)
                    end

            end


        end

        % Cell selection callback: UITable_events
        function showSelectedEvent(app, event)
            % indices of selected event in table
            indices = event.Indices;
            % get frame, in which selected event starts
            if isempty(indices)
                return
            end
            frame_val = double(app.UITable_events.Data.imgNum_s(indices(1)));
            
            % remove previously highlighted row
            removeStyle(app.UITable_events)
           
            % highlight row with selected event
            s = uistyle('BackgroundColor','yellow');
            addStyle(app.UITable_events, s, 'row', indices(1))
            
            % set up time, slider and spinner
            app.EditField_imgTime_events.Value = app.data.t(frame_val);
            app.Slider_imgFrame_events.Value = frame_val;
            app.Spinner_imgFrame_events.Value = frame_val;
            
            % check show selected event only
            app.CheckBox_showSelectedEvent.Value = 1;
            
            % save selected event
            app.data.selectedEvent = app.UITable_events.Data(indices(1),:);
            
            % show selected frame from image series with events
            % set previous to invisible
            cellfun(@(x) set(x, ...
                'Visible','off', 'FaceAlpha',0, 'EdgeAlpha',0), ...
                app.data.eventsInAllFrames)
       
            showFilteredImgWithEventsBoundaries(app, event, false, false)

            % show profile of selected event
            app.showProfilesAndFitsOfSelectedEvent()
            
        end

        % Window key press function: UIFigure
        function scrollEventsWithKeyArrows(app, event)
            % check if detected evetns tab is in focus
            if ~strcmp(app.TabGroup.SelectedTab.Tag, 'analysisTab')
                return
            end
            if ~isfield(app.data, 'selectedEvent')
                return
            end
            if isempty(app.data.selectedEvent)
                return
            end
       
            previousEvent = app.data.selectedEvent;
            idx_row_prev = find(previousEvent.eventID == ...
                app.UITable_events.Data.eventID);
            % move in table of events
            if app.CheckBox_showSelectedEvent.Value && ...
                ~isempty(previousEvent)
                    switch event.Key
                        case 'downarrow'
                            idx_row_new = idx_row_prev + 1;
                            if idx_row_new>height(app.UITable_events.Data)
                                idx_row_new = height(app.UITable_events.Data);
                            end
                        
                        case 'uparrow'
                            idx_row_new = idx_row_prev - 1;
                            if idx_row_new<1
                                idx_row_new = 1;
                            end
                        otherwise
                            return
                    end
                    
                    % remove highlight in table
                    removeStyle(app.UITable_events)
                    
                    % highlight row with new selected event
                    s = uistyle('BackgroundColor','yellow');
                    addStyle(app.UITable_events, s, 'row', idx_row_new)

                    % save selected event
                    app.data.selectedEvent = app.UITable_events.Data(idx_row_new,:);

                    % show profile of selected event
                    app.showProfilesAndFitsOfSelectedEvent()
                    
            end
        end

        % Button pushed function: Button_save
        function saveAnalysis(app, event)
            % save analysis results in to xlsx or csv file
            % check if there is something to save
            if isempty(app.data.detectedEvents)
                return
            end
            % get path to file where to save
            [imgPath, imgName, imgExt] = ...
                fileparts(char(app.data.imgPath));
            % save as xlsx file
            saveFileFormat = '.xlsx';
            resultsFilePath = fullfile( ...
                char(imgPath), ...
                char([imgName,'_results',saveFileFormat]));
            if ~app.data.batchMode
                % dialog window to choose where to save results
                [file, path] = uiputfile(...
                    {'*.xlsx','Excel files (*.xlsx)'}, ...
                    'save analysis results', ...
                    resultsFilePath);
                if path==0
                    return
                end
                % set up path for results file
                resultsFilePath = fullfile(path, file);
            end

            % prepare data to save to file
            % info 
            info = {'image name:', [imgName, imgExt]};
            info = [info;
                {'image path:', imgPath}];
            info = [info;
                {'pixel size x (µm):', app.data.pxSz_x}];
            info = [info;
                {'pixel size y (µm):', app.data.pxSz_y}];
            info = [info;
                {'pixel size t (ms):', app.data.pxSzT}];
            try
                info = [info; ...
                    {'blank:', mean(app.data.img_blank)}];
            catch
                info = [info; ...
                    {'blank:', nan}];
            end
            % save crop position
            if isempty(app.data.crop_roi_xy_pos)
                info = [info; ... 
                    {'crop start frame:', []; ...
                     'crop end frame:', []; ...
                     'crop ROI position (x,y,w,h):', []}];
            else
                info = [info; ...
                    {'crop start frame:', app.data.crop_s_frame; ...
                     'crop end frame:', app.data.crop_e_frame; ...
                     'crop ROI position (x):', app.data.crop_roi_xy_pos(1); ...
                     'crop ROI position (y):', app.data.crop_roi_xy_pos(2); ...
                     'crop ROI position (w):', app.data.crop_roi_xy_pos(3); ...
                     'crop ROI position (h):', app.data.crop_roi_xy_pos(4)}];
            end

            % parameters
            eventsParams = app.data.detectedEvents(...
                app.data.detectedEvents.accepted, [[1,2,3],(6:13)]);
            eventsParamsCellArr = [ 
                cellfun(@(x,y) [x, ' (', y, ')'], ...
                eventsParams.Properties.VariableNames, ...
                eventsParams.Properties.VariableUnits, ...
                'UniformOutput', false); ...  
                table2cell(eventsParams) 
                ];
            % create one array from parameters and info
            % find dimensions
            numOfrows = max(size(info,1),size(eventsParamsCellArr,1));
            numOfCols = size(info,2) + 2 + size(eventsParamsCellArr,2);
            outputToSave = cell([numOfrows, numOfCols]);
            % add info
            outputToSave(1:size(info,1), 1:size(info,2)) = info;
            % add parameters
            outputToSave(1:size(eventsParamsCellArr,1), size(info,2)+3:end) = ...
                eventsParamsCellArr;
            % save to file
            % delete xls file if exist
            if exist(resultsFilePath, 'file')
                delete(resultsFilePath)
            end
            % save new file
            writecell(outputToSave, resultsFilePath, ...
                'Sheet','results', 'Range', 'A1')

            % save analysis data if selected
            if app.CheckBox_saveDetection.Value
                app.data.imgAnalysisData.detectedEvents = app.data.detectedEvents;
                imgAnalysisData = app.data.imgAnalysisData;
                % delete analysis file if exist
                if exist(imgAnalysisData.imgAnalysisData_filePath, 'file')
                    delete(imgAnalysisData.imgAnalysisData_filePath)
                end
                % save analysis data
                save(imgAnalysisData.imgAnalysisData_filePath, 'imgAnalysisData')
            end

            % clear app content
            clearAppContent(app, 'all')


        end

        % Value changed function: DropDown_fitFunSelection, 
        % ...and 2 other components
        function refitSelectedEvent(app, event)
            % used for all components in refit event panel
            % get selected event, highlighted in uitable
            if ~isfield(app.data, 'selectedEvent')
                return
            end
            % in uitable
            selectedEvent = app.data.selectedEvent;
            % get data and fits
            idx = find(selectedEvent.eventID == ...
                app.data.detectedEvents.eventID);
            selectedEventAll = app.data.detectedEvents(idx,:);
            
            % refit
            eventFits = getProfileAndParamsOfSelectedEvent(app, ...
                selectedEventAll.boundary, ...
                selectedEventAll.imgNum_s, ...
                selectedEventAll.imgNum_e, ...
                selectedEventAll.eventType, ...
                app.DropDown_fitFunSelection.Value, ...
                app.EditField_smoothParam.Value, ...
                app.EditField_weightsPower2DGaussFit.Value);

            % save new fits and parameters of selected event
            app.data.detectedEvents.fits_params_event(idx).tProfFit = ...
                eventFits.tProfFit;
            app.data.detectedEvents.fits_params_event(idx).eventMaxImg2DFit = ...
                eventFits.eventMaxImg2DFit;
            % supporting parameters of event, used to draw parameters
            % in profile
            app.data.detectedEvents.fits_params_event(idx).eventSupportParams = ...
                eventFits.eventSupportParams;
            % parameters of event
            app.data.detectedEvents.amplitude(idx) = eventFits.eventParams.amplitude;
            app.data.detectedEvents.TTP(idx) = eventFits.eventParams.TTP;
            app.data.detectedEvents.FDHM(idx) = eventFits.eventParams.FDHM;
            app.data.detectedEvents.tauRise(idx) = eventFits.eventParams.tauRise;
            app.data.detectedEvents.tauDecay(idx) = eventFits.eventParams.tauDecay;
            app.data.detectedEvents.FWHMx(idx) = eventFits.eventParams.FWHMx;
            app.data.detectedEvents.FWHMy(idx) = eventFits.eventParams.FWHMy;
            app.data.detectedEvents.waveSpeed(idx) = eventFits.eventParams.waveSpeed;
            
            % show fits of event
            app.showProfilesAndFitsOfSelectedEvent()
            
        end

        % Menu selected function: Menu_saveAxesAsImgs
        function saveAxesContentToFile(app, event)
            % curObj = gco(app.UIFigure);
            % find axes to save
            % ax = ancestor(curObj,{'axes'});
            
            % create dialog window
            % in pixels
            w_fig = 450;
            h_fig = 400;
            d = 10;
            w_cbx = 250;
            h_cbx = 50;
            w_dm = 100;
            h_dm = 30;
            posFig = round([round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w_fig/2), ...
                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h_fig/2), ...
                w_fig, h_fig]);
            uifig = uifigure( ...
                'WindowStyle','modal',...
                'Units', 'pixels', ...
                'Position', posFig, ...
                'Name', 'Select which axes to save to files with selected format.');
            % checkboxes and dropdown menus
            cbx = gobjects(4,1);
            dm = gobjects(4,1);
            % axes to save
            h_ax = [app.UIAxes_img, app.UIAxes_img_events, ...
                app.UIAxes_eventTprof, app.UIAxes_eventXYprof];
            text_cbx = { ...
                {'image axes', 'Ca events detection tab'}, ...
                {'image axes', 'analysed events browser tab'}, ...
                {'t-profile of event', 'analysed events browser tab'}, ...
                {'2D Gauss fit of event', 'analysed events browser tab'}, ...
                };
            uipnl_ax = uipanel(uifig, ...
                'Position', [1 ...
                h_fig-h_cbx*numel(h_ax)-(numel(h_ax)-1)*d ...
                w_fig ...
                h_cbx*numel(h_ax)+(numel(h_ax)-1)*d]);
            for i=1:numel(h_ax)
                % checkboxes
                cbx(i) = uicheckbox(uipnl_ax, ...
                    'Text',text_cbx{i},...
                    'Value',1, 'FontSize',16, ...
                    'Position', round([ ...
                    (uipnl_ax.Position(3)-w_cbx-w_dm)/2 ...
                    uipnl_ax.Position(4)-i*h_cbx-(i-1)*d ...
                    w_cbx ...
                    h_cbx]));
                % dropdownmenus
                dm(i) = uidropdown(uipnl_ax, ...
                    'Items',{'.png','.pdf','.eps'},...
                    'Value','.pdf', 'FontSize',16, ...
                    'Position',round([ ...
                    (uipnl_ax.Position(3)-w_cbx-w_dm)/2+w_cbx ...
                    uipnl_ax.Position(4)-i*h_cbx-(i-1)*d+(h_cbx-h_dm)/2 ...
                    w_dm ...
                    h_dm]));
            end
            % panels for parameters
            uipnl_params = uipanel(uifig, ...
                'Position',round([1 1 w_fig/2 uipnl_ax.Position(2)]), ...
                'Title','Parameters of output', ...
                'FontSize',16, 'FontWeight','bold');
            % add selection for resolution of saved image
            uilabel(uifig, ...
                'Text','Resolution (pixels):', ...
                'FontSize',16, ...
                'HorizontalAlignment','center', ...
                'Position',round([d ...
                uipnl_params.Position(4)-h_dm-2*d ...
                uipnl_params.Position(3)-2*d ...
                h_dm]));
            dm_res = uidropdown(uifig, ...
                'Items',{'150','300','600','900','1200'},...
                'Value','300', 'FontSize',16, ...
                'Position',round([...
                (uipnl_params.Position(3)-w_dm)/2 ...
                uipnl_params.Position(4)-2*h_dm-2*d ...
                w_dm ...
                h_dm]));
            % and for content
            uilabel(uifig, ...
                'Text','Type of content of axes:', ...
                'FontSize',16, ...
                'HorizontalAlignment','center', ...
                'Position',round([d ...
                uipnl_params.Position(4)-3*h_dm-3*d ...
                uipnl_params.Position(3)-2*d ...
                h_dm]));
            dm_contentType = uidropdown(uifig, ...
                'Items',{'auto','vector','image'},...
                'Value','auto', 'FontSize',16, ...
                'Position',round([...
                (uipnl_params.Position(3)-w_dm)/2 ...
                uipnl_params.Position(4)-4*h_dm-3*d ...
                w_dm ...
                h_dm]));
            
            % push buttons
            h_pb = round((h_fig-uipnl_ax.Position(4)-6*d)/2);
            w_pb = (w_fig-(uipnl_params.Position(1)+uipnl_params.Position(3))-6*d);
            pb_Save = uibutton(uifig, ...
                'Text', 'Save', ...
                'Position', [ ...
                uipnl_params.Position(1)+uipnl_params.Position(3)+3*d ...
                uipnl_ax.Position(2)-h_pb-2*d ...
                w_pb ...
                h_pb], ...
                'FontSize',18, 'FontWeight','bold', ...
                'ButtonPushedFcn', ...
                {@app.saveSelectedAxesToFiles, uifig, cbx, dm, h_ax, dm_res, dm_contentType});
            pb_Cancel = uibutton(uifig, ...
                'Text', 'Cancel', ...
                'Position', [ ...
                uipnl_params.Position(1)+uipnl_params.Position(3)+3*d ...
                uipnl_ax.Position(2)-2*h_pb-4*d ...
                w_pb ...
                h_pb], ...
                'FontSize',18, 'FontWeight','normal', ...
                'ButtonPushedFcn', @(h,~)delete(h.Parent));
            
        end

        % Button pushed function: Button_restart
        function restartAnalysis(app, event)
            
            % delete all analysis progress and data, keep only raw image data
            app.data.imgData = app.data.imgDataAll;
            app.data.imgDataRaw = app.data.imgDataAll;
            % recalculate time in case image series was cropped
            dt = app.data.pxSzT;
            t = (dt:dt:size(app.data.imgDataAll,3)*dt) - dt;
            app.data.t = t(:);
            app.data.detectedEvents = table();
            app.data.imgDataCaxisLimits = [];
            app.data.cellMask = true(size(app.data.imgData));
            app.data.F0 = [];
            app.data.img_blank = [];
            app.data.eventsIDs = [];
            app.data.eventsIDs_types = [];
            app.data.eventsInAllFrames = [];
            app.data.selectedEvent = [];
            app.data.crop_s_frame = [];
            app.data.crop_e_frame = [];
            app.data.crop_roi_xy_pos = [];

            % set up pushbutton for cropping
            app.Button_crop.Text = 'select xy area to crop';
            app.Button_crop.FontWeight = 'normal';

            % show image
            % rescale axes and respective uicontrols
            % based on size of image
            showImgAndRescaleAxes(app, event)

            % clear events tab content
            clearAppContent(app)

            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow 
             
        end

        % Value changed function: CheckBox_cellMaskForEachFrame
        function checkBoxes_cellEdgePanel(app, event)
            if app.CheckBox_cellMaskForEachFrame.Value
                app.CheckBox_cellMask_manualCorrection_toAll.Value = 0;
            else
                app.CheckBox_cellMask_manualCorrection_toAll.Value = 1;
            end
            
        end

        % Button pushed function: Button_batchAnalysis
        function batchModeAnalysis(app, event)
            % select folder with movies
            dir_path = uigetdir();
            if dir_path==0
                return
            end
%             % use datastore, did not really worked for this app
%             imgSeriesDatastore = imageDatastore(dir_path, ...
%                 'FileExtensions','.tif', ...
%                 'IncludeSubfolders',app.CheckBox_includeSubfolders.Value, ...
%                 'LabelSource','foldernames');
            % get only TIFF files from selected folder
            if app.CheckBox_includeSubfolders.Value
                listing_tif = dir( fullfile(dir_path, '**', '*.tif*') );
                listing_all = dir( fullfile(dir_path, '**', '*.*') );
            else
                listing_tif = dir( fullfile(dir_path, '*.tif*') );
                listing_all = dir( fullfile(dir_path, '*.*') );
            end
            % exclude invalid entries if any
            listing_tif = listing_tif(~cellfun('isempty', {listing_tif.date}));
            listing_all = listing_all(~cellfun('isempty', {listing_all.date}));
            % check and throw error if listing is empty
            if isempty(listing_tif)
               uialert(app.UIFigure, ...
                   {'No TIFF files found in selected directory!', ...
                   'Check if subfolders are included.'}, ...
                   'Error', 'Icon','error')
               return 
            end
            % check if there are some results .mat files
            m_resultsFiles = ...
                contains({listing_all.name}, {'_sparks', '_puffs', '_waves'});
            if any(m_resultsFiles)
                selection = uiconfirm(app.UIFigure, ...
                    {'Results TIFF files found!', 'If continue, old results files will be deleted.'}, ...
                    'Delete results TIFF files?', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 2);
                switch selection 
                    case 'Continue'
                        % delete results files
                        cellfun(@(x) delete(x), ...
                            fullfile({listing_all(m_resultsFiles).folder}, ...
                            {listing_all(m_resultsFiles).name}));
                    case 'Cancel'
                        return
                end
            end
            % create image series paths
            imgsPaths = fullfile({listing_tif.folder}, {listing_tif.name});
            imgsPaths = imgsPaths(:);
            % show selected image series files in uitable
            % figure size in pixels
            w_fig = 1200;
            h_fig = 900;
            d = 10;
            h_pb = 75;
            w_pb = 100;
            posFig = round([round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w_fig/2), ...
                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h_fig/2), ...
                w_fig, h_fig]);
            UIfig_batch = uifigure( ...
                'WindowStyle','normal',...
                'Units', 'pixels', ...
                'Position', posFig, ...
                'Name', 'Image series selected for batch analysis.');
            % get metadata for each img series, create table to show
            infoProgressBar = uiprogressdlg(UIfig_batch, ...
                'Title','Please wait.', ...
                'Message','getting info about selected image series...', ...
                'ShowPercentage','on');
            drawnow
            imgSeriesData_tbl = table( imgsPaths, ...
                zeros(size(imgsPaths)), ...
                zeros(size(imgsPaths)), ...
                zeros(size(imgsPaths)), ...
                zeros(size(imgsPaths)), ...
                zeros(size(imgsPaths)), ...
                true(size(imgsPaths)), ...
                'VariableNames',{'image series path', '# images', '# channels', ...
                'pxSz_x (um)', 'pxSz_y (um)', 'pxSz_t (ms)', 'selected'});
            for i=1:height(imgSeriesData_tbl)
                img_info = imfinfo(imgSeriesData_tbl.("image series path"){i});
                imgDescrStr = img_info(1).ImageDescription;
                N_imgs = size(img_info, 1);
                imgMetadata = app.getImgMetadataFromImgDescrStr(...
                    imgDescrStr, N_imgs);
                imgSeriesData_tbl.("# images")(i) = N_imgs;
                imgSeriesData_tbl.("# channels")(i) = imgMetadata.n_ch;
                imgSeriesData_tbl.("pxSz_x (um)")(i) = imgMetadata.pxSz_x;
                imgSeriesData_tbl.("pxSz_y (um)")(i) = imgMetadata.pxSz_y;
                imgSeriesData_tbl.("pxSz_t (ms)")(i) = imgMetadata.pxSzT;
                % update progress bar
                infoProgressBar.Value = i/height(imgSeriesData_tbl);
            end
            % delete progress bar
            close(infoProgressBar)
            % uitable with selected image series
            UItbl_imgSeries = uitable(UIfig_batch, ...
                'Data',imgSeriesData_tbl, ...
                'Position', [d h_pb+2*d w_fig-2*d h_fig-h_pb-3*d], ...
                'ColumnEditable',[false true true true true true], ...
                'Multiselect','on', ...
                'SelectionChangedFcn', @app.changeValueOfMultipleSelectedCells);

            % push buttons
            pb_continue = uibutton(UIfig_batch, ...
                'Text', 'Continue', ...
                'Position', [round((w_fig-2*w_pb-4*d)/2) d w_pb h_pb], ...
                'FontSize',18, 'FontWeight','bold', ...
                'FontColor', [1 0 0], ...
                'ButtonPushedFcn', ...
                {@app.analyseImgSeriesInBatchMode, UIfig_batch, UItbl_imgSeries});
            pb_cancel = uibutton(UIfig_batch, ...
                'Text', 'Cancel', ...
                'Position', [round((w_fig-2*w_pb-4*d)/2)+w_pb+4*d d w_pb h_pb], ...
                'FontSize',18, 'FontWeight','normal', ...
                'Tag', 'menu_openImg', ...
                'ButtonPushedFcn', @(h,~)delete(h.Parent));
        end

        % Menu selected function: Menu_loadAnalyzedImgSeries
        function loadAnalyzedImgSeries(app, event)
            % set flag for loading analyzed image series to true
            app.data.loadAnalyzedImgSeriesFlag = true;
            % clear app content in gui
            clearAppContent(app, 'all')
            
            % load analyzed image series
            % select (.tif) image series and load respective analysis (.mat file)
            % simulate event structure after button click
            simButtonEvent = struct('Source', struct('Tag', 'menu_openImg'));
            app.openImg(simButtonEvent)
            
            % crop image series
            if ~isempty(app.data.imgAnalysisData.crop_roi_pos)
                % crop image series
                simButtonEvent = struct( ...
                    'Source', struct('Tag', 'cropImage', ...
                                     'Text', 'crop', ...
                                     'Type', 'uibutton'));
                app.cropImgSeries(simButtonEvent)
            end

            % load cell mask
            if ~isempty(app.data.imgAnalysisData.cellMask)
                app.data.cellMask = app.data.imgAnalysisData.cellMask;
                % show cell mask
                app.showFilteredImgWithCellMask(event, false)
            end

            % load F0
            if ~isempty(app.data.imgAnalysisData.F0)
                % recalculate F0, maybe consider to save whole matrix of
                % F0, if also some fitting of baseline is implemented
                % not only averages per frames
                app.CheckBox_F0ForEachFrame.Value = ...
                    app.data.imgAnalysisData.calcF0ForEachFrame;
                app.calculate_selfRatio(event)
            end
            
            % load detected events
            app.data.detectedEvents = app.data.imgAnalysisData.detectedEvents;
            % rescale axes and respective uicontrols based on size of image
            simButtonEvent = struct(...
                'Source', struct('Tag', 'detectEvents', ...
                                 'Text', 'detect events', ...
                                 'Type', 'uibutton') );
            showImgAndRescaleAxes(app, simButtonEvent)
            % show detected events with image
            showFilteredImgWithEventsBoundaries(app, simButtonEvent, false, true)

            % reset flag for loading analyzed image series
            app.data.loadAnalyzedImgSeriesFlag = false;

        end

        % Menu selected function: Menu_convertMatFileToTIFFs
        function convertMatFileToTIFFs(app, event)
            keyboard


        end

        % Button pushed function: Button_butterworthFilter
        function applyButterworthFilter(app, event)
            % create dialog window
            % in pixels
            w_fig = app.UIFigure.Position(3)-200;
            h_fig = app.UIFigure.Position(4);
            d = 30;
            d_pnl = 10;
            w_ax = w_fig-2*d;
            h_ax = h_fig/3;
            w_edit = 175;
            h_edit = 30;
            posFig = round([ ...
                round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w_fig/2), ...
                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h_fig/2), ...
                w_fig, ...
                h_fig ...
                ]);
            uiFig = uifigure( ...
                'WindowStyle','normal',... % modal normal
                'Units', 'pixels', ...
                'Position', posFig, ...
                'Name', 'Apply Butterworth filter to image series.');
            % add uiaxes with average temporal profile of image series and 
            % calculated amplitude spectrum
            ax_prof = uiaxes(uiFig, 'Position',[d h_fig-d-h_ax w_ax h_ax]);
            ax_spectrum = uiaxes(uiFig, 'Position',[d h_fig-2*d-2*h_ax w_ax h_ax]);
            % panel for parameters
            pnl = uipanel(uiFig, ...
                'Position',round([(w_fig-ax_prof.Position(3)/3)/2 ...
                                   d ...
                                   ax_prof.Position(3)/3 ...
                                   h_ax-4*d]), ...
                'Title','Parameters of filtering:', ...
                'FontSize',18, 'FontWeight','bold');
            % add numeric edit boxes to change parameters of filtering
            lbl_butterOrder = uilabel(pnl, ...
                'Text','order of filter', 'HorizontalAlignment','center', ...
                'FontSize',16, 'VerticalAlignment', 'bottom',...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit)/2 ...
                pnl.Position(4)-2*d_pnl-h_edit ...
                w_edit h_edit]));
            edit_butterOrder = uieditfield(pnl, 'numeric', ...
                'Value',4, 'FontSize',16, 'HorizontalAlignment','center', ...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit/3)/2 ...
                pnl.Position(4)-3*d_pnl-2*h_edit ...
                w_edit/3 h_edit]), 'Tag','butterOrder');
            
            lbl_widthOfBandStop = uilabel(pnl, ...
                'Text','width of bandstop (Hz)', 'HorizontalAlignment','center', ...
                'FontSize',16, 'VerticalAlignment', 'bottom',...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit)/2 ...
                pnl.Position(4)-4*d_pnl-3*h_edit ...
                w_edit h_edit]));
            edit_widthOfBandStop = uieditfield(pnl, 'numeric', ...
                'Value',1, 'FontSize',16, 'HorizontalAlignment','center', ...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit/3)/2 ...
                pnl.Position(4)-5*d_pnl-4*h_edit ...
                w_edit/3 h_edit]), 'Tag','widthOfBandStop', ...
                'ValueChangedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});

            lbl_RMS_multipl = uilabel(pnl, ...
                'Text','multiplier of RMS noise', 'HorizontalAlignment','center', ...
                'FontSize',16, 'VerticalAlignment', 'bottom',...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit)/2+pnl.Position(3)/3 ...
                pnl.Position(4)-2*d_pnl-h_edit ...
                w_edit h_edit]));
            edit_peakPromin = uieditfield(pnl, 'numeric', ...
                'Value',1, 'FontSize',16, 'HorizontalAlignment','center', ...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit/3)/2+pnl.Position(3)/3 ...
                pnl.Position(4)-3*d_pnl-2*h_edit ...
                w_edit/3 h_edit]), 'Tag','peakPromin', ...
                'ValueChangedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});
            
            lbl_minFreq = uilabel(pnl, ...
                'Text','freq filtered (Hz) >=', 'HorizontalAlignment','center', ...
                'FontSize',16, 'VerticalAlignment', 'bottom',...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit)/2+pnl.Position(3)/3 ...
                pnl.Position(4)-4*d_pnl-3*h_edit ...
                w_edit h_edit]));
            edit_minFreq = uieditfield(pnl, 'numeric', ...
                'Value',7, 'FontSize',16, 'HorizontalAlignment','center', ...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit/3)/2+pnl.Position(3)/3 ...
                pnl.Position(4)-5*d_pnl-4*h_edit ...
                w_edit/3 h_edit]), 'Tag','minFreq', ...
                'ValueChangedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});
            
            % add pushbuttons - filter, accept
            h_pb = round((pnl.InnerPosition(4)-4*d_pnl)/3);
            w_pb = pnl.InnerPosition(3)/3-4*d_pnl;
            pb_filter = uibutton(pnl, 'push', ...
                'Text','filter data','FontSize',18, 'FontWeight','bold', ...
                'Position',round([ ...
                (pnl.InnerPosition(3)/3-w_pb)/2+2*pnl.InnerPosition(3)/3 ...
                pnl.InnerPosition(4)-h_pb-d_pnl w_pb h_pb]), ...
                'ButtonPushedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});

            pb_accept = uibutton(pnl, 'push', ...
                'Text','accept','FontSize',18, 'FontWeight','bold', ...
                'Position',round([ ...
                (pnl.InnerPosition(3)/3-w_pb)/2+2*pnl.InnerPosition(3)/3 ...
                pnl.InnerPosition(4)-2*h_pb-2*d_pnl w_pb h_pb]), ...
                'ButtonPushedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});
            
            pb_Cancel = uibutton(pnl, ...
                'Text', 'cancel', ...
                'Position',round([ ...
                (pnl.InnerPosition(3)/3-w_pb)/2+2*pnl.InnerPosition(3)/3 ...
                pnl.InnerPosition(4)-3*h_pb-3*d_pnl w_pb h_pb]), ...
                'FontSize',18, 'FontWeight','normal', ...
                'ButtonPushedFcn', @(h,~)delete(h.Parent.Parent));

            % filter data using default values
            app.filterDataWithButterworthFilter([], [], ...
                uiFig, ax_prof, ax_spectrum)


        end

        % Menu selected function: Menu_settings
        function setSettingsWindow(app, event)
            % create dialog window
            % in pixels
            w_fig = 1000;
            h_fig = 350;
            d = 10;
            w_pnl = w_fig-2*d;
            h_pnl = (h_fig-3*d)/2;
            h_pb = 60;
            w_pb = 150;

            posFig = round([round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w_fig/2), ...
                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h_fig/2), ...
                w_fig, h_fig]);
            uifig = uifigure( ...
                'WindowStyle','modal',... % modal normal
                'Units', 'pixels', ...
                'Position', posFig, ...
                'Name', 'Settings');
            % create table with filters selection
            uipnl_f = uipanel(uifig, ...
                'Title','filters applied in sequence after loading of data:', ...
                'FontSize',16, 'FontWeight','bold', ...
                'Position',[d h_fig-d-h_pnl w_pnl h_pnl]);
            uit_f = uitable(uipnl_f, 'Data',app.data.defaultFiltersUsed.filters, ...
                'ColumnEditable',[true, false,true, false,true, false,true, false,true], ...
                'FontSize',14, 'Tag','filtersSelectionTbl', ...
                'Position',[d d ...
                            uipnl_f.InnerPosition(3)-2*d ...
                            uipnl_f.InnerPosition(4)-2*d], ...
                'CellEditCallback',{@app.setFiltersSettingsInTable,uifig});
            s1_f = uistyle('FontWeight','bold');
            addStyle(uit_f,s1_f,'column',1)
            s2_f = uistyle('HorizontalAlignment','center');
            addStyle(uit_f,s2_f,'column',(2:9))

              % add panel to set up conda environment
            uipnl_conda = uipanel(uifig, ...
                'Title','setup of conda environment:', ...
                'FontSize',16, 'FontWeight','bold', ...
                'Position',[d h_fig-2*d-2*h_pnl w_pnl-d-w_pb h_pnl]);
            w_pb_conda = (uipnl_conda.InnerPosition(3)-4*d)/2;
            h_editTxt = 25;
            h_pb_conda = uipnl_conda.InnerPosition(4)-4*d-2*h_editTxt;
            % path to conda environment
            uibutton(uipnl_conda, 'push', ...
                'Text','get path to conda environment directory', ...
                'FontSize',16, 'FontWeight','bold', 'WordWrap','on', ...
                'Position',[d, ...
                            uipnl_conda.InnerPosition(4)-2*d-h_pb_conda, ...
                            w_pb_conda, ...
                            h_pb_conda], ...
                'ButtonPushedFcn',{@app.getCondaEnvDirPath,uifig});
            % create conda environment
            uibutton(uipnl_conda, 'push', ...
                'Text','create required conda environment', ...
                'FontSize',16, 'FontWeight','bold', 'WordWrap','on',...
                'Position',[3*d+w_pb_conda, ...
                            uipnl_conda.InnerPosition(4)-2*d-h_pb_conda, ...
                            w_pb_conda, ...
                            h_pb_conda], ...
                'ButtonPushedFcn',{@app.createCondaEnv,uifig});
            % show path to conda environment
            uilabel(uipnl_conda, ...
                'Text','path to required conda environment (e.g. /Users/name/opt/anaconda3/envs/xytCaSignals)', ...
                'FontSize',16, ...
                'Position',[d ...
                            uipnl_conda.InnerPosition(4)-3*d-h_pb_conda-h_editTxt ...
                            uipnl_conda.InnerPosition(3)-2*d ...
                            h_editTxt]);
            uieditfield(uipnl_conda, 'text', ...
                'Value',app.data.condaEnvPath, 'FontSize',16, ...
                'Position',[d ...
                            uipnl_conda.InnerPosition(4)-3*d-h_pb_conda-2*h_editTxt ...
                            uipnl_conda.InnerPosition(3)-2*d ...
                            h_editTxt]);
            % add pushbutton, accept and save filters and path to conda
            % environment
            uibutton(uifig, 'push', ...
                'Text','Save & Close', ...
                'FontSize',18, 'FontWeight','bold', ...
                'Position',[uipnl_conda.Position(3)+2*d, ...
                            uipnl_conda.Position(2)+uipnl_conda.Position(4)/2-h_pb/2, ...
                            w_pb, ...
                            h_pb], ...
                'ButtonPushedFcn',{@app.setFiltersSettingsInTable,uifig});

            
        end

        % Callback function: UITable_filters, 
        % ...and 1 other component
        function setAndApplyFilters(app, event)
            switch event.Source.Type
                case 'uitable'
                    % set up filter parameteres
                    app.setFiltersSettingsInTable(event.Source, ...
                        event, app.UIFigure)

                case 'uibutton'
                    % filter data in sequence with filters selected in
                    % filter table
                    % check if there are some data
                    if isempty(app.data.imgData)
                        return
                    end
                    % show state
                    app.stateLampLabel.Text = 'busy';
                    app.Lamp_state.Color = [1 0 0];
                    drawnow
                    % add progressbar
                    h_dlg = uiprogressdlg(app.UIFigure, ...
                        'Indeterminate','on', ...
                        'Message', '', ...
                        'Title', 'Filtering image series.');
                    % get filters names and params
                    filtersSeq = app.UITable_filters.Data;
                    filtersSeq(filtersSeq.filter=='none',:) = [];
                    % filter data
                    imgDataFilt = app.data.imgDataRaw;
                    if ~isempty(filtersSeq)
                        for idx = 1:height(filtersSeq)
                            imgDataFilt = app.filterImgSeries(imgDataFilt, ...
                                filtersSeq(idx,:), h_dlg);
                        end
                    end
                    % save caxis limits
                    app.data.imgDataCaxisLimits = ...
                        prctile(imgDataFilt, [1 99], 'all');
                    % save filtered data
                    app.data.filtersUsed = filtersSeq;
                    app.data.imgData = imgDataFilt;
                    % show selected frame from image series in respective tab
                    switch event.Source.Tag
                        case 'filter_img'
                            % with cell mask if calculated
                            showFilteredImgWithCellMask(app, event, true)
                        case 'filter_img_events'
                            % with detected events
                            showFilteredImgWithEventsBoundaries(app, event, true, false)
                    end
                    % close progress bar
                    close(h_dlg)
                    % show state
                    app.stateLampLabel.Text = 'ready';
                    app.Lamp_state.Color = [0 1 0];
                    drawnow
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @scrollEventsWithKeyArrows, true);
            app.Menu_openImg = uimenu(app.UIFigure);
            app.Menu_openImg.MenuSelectedFcn = createCallbackFcn(app, @openImg, true);
            app.Menu_openImg.Text = '     open image     ';
            app.Menu_openImg.Tag = 'menu_openImg';
            app.Menu_settings = uimenu(app.UIFigure);
            app.Menu_settings.MenuSelectedFcn = createCallbackFcn(app, @setSettingsWindow, true);
            app.Menu_settings.Text = '    settings    ';
            app.Menu_loadAnalyzedImgSeries = uimenu(app.UIFigure);
            app.Menu_loadAnalyzedImgSeries.MenuSelectedFcn = createCallbackFcn(app, @loadAnalyzedImgSeries, true);
            app.Menu_loadAnalyzedImgSeries.Text = '     load analyzed image series     ';
            app.Menu_loadAnalyzedImgSeries.Tag = 'menu_loadAnalyzedImgSeries';
            app.Menu_saveAxesAsImgs = uimenu(app.UIFigure);
            app.Menu_saveAxesAsImgs.MenuSelectedFcn = createCallbackFcn(app, @saveAxesContentToFile, true);
            app.Menu_saveAxesAsImgs.Text = '     save axes as images     ';
            app.Menu_convertMatFileToTIFFs = uimenu(app.UIFigure);
            app.Menu_convertMatFileToTIFFs.MenuSelectedFcn = createCallbackFcn(app, @convertMatFileToTIFFs, true);
            app.Menu_convertMatFileToTIFFs.Enable = 'off';
            app.Menu_convertMatFileToTIFFs.Text = '     convert .mat file to tifs     ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_CaEventsDetection = uitab(app.TabGroup);
            app.Tab_CaEventsDetection.Title = 'Ca events detection';
            app.Tab_CaEventsDetection.Tag = 'detectionTab';
            app.UIAxes_img = uiaxes(app.Tab_CaEventsDetection);
            title(app.UIAxes_img, 'xyt recording')
            xlabel(app.UIAxes_img, 'x (pixels)')
            ylabel(app.UIAxes_img, 'y (pixels)')
            zlabel(app.UIAxes_img, 'Z')
            app.UIAxes_img.Tag = 'img_ax';
            app.UIAxes_avrgCellSignal = uiaxes(app.Tab_CaEventsDetection);
            title(app.UIAxes_avrgCellSignal, 'average signal from cell area')
            xlabel(app.UIAxes_avrgCellSignal, 'frame')
            ylabel(app.UIAxes_avrgCellSignal, 'fluorescence')
            zlabel(app.UIAxes_avrgCellSignal, 'Z')
            app.UITable_imgMeta = uitable(app.Tab_CaEventsDetection);
            app.UITable_imgMeta.ColumnName = {'image path'; '# of channels'; '# of images'; 'img size x (px)'; 'img size y (px)'; 'pixel size x (um)'; 'pixel size y (um)'; 'time step (ms)'};
            app.UITable_imgMeta.RowName = {};
            app.UITable_imgMeta.ColumnEditable = [false false false false false true true true];
            app.UITable_imgMeta.CellEditCallback = createCallbackFcn(app, @imgMetadataTableCellEdit, true);
            app.UITable_imgMeta.Tag = 'metadataTable';
            app.Panel_caEventsDetection = uipanel(app.Tab_CaEventsDetection);
            app.Panel_caEventsDetection.Title = 'Ca events detection';
            app.selectmethodDropDownLabel = uilabel(app.Panel_caEventsDetection);
            app.selectmethodDropDownLabel.Text = 'select method';
            app.DropDown_detectionMethod = uidropdown(app.Panel_caEventsDetection);
            app.DropDown_detectionMethod.Items = {'U-Net', 'threshold'};
            app.DropDown_detectionMethod.Tag = 'drop_methodToDetect';
            app.DropDown_detectionMethod.Value = 'threshold';
            app.Button_detectevents = uibutton(app.Panel_caEventsDetection, 'push');
            app.Button_detectevents.ButtonPushedFcn = createCallbackFcn(app, @detectCalciumEvents, true);
            app.Button_detectevents.Tag = 'detectEvents';
            app.Button_detectevents.Text = 'detect events';
            app.Panel_unetThresholds = uipanel(app.Panel_caEventsDetection);
            app.Panel_unetThresholds.Title = 'U-Net thresholds';
            app.puffEditFieldLabel = uilabel(app.Panel_unetThresholds);
            app.puffEditFieldLabel.Text = 'puff';
            app.EditField_puff_thr = uieditfield(app.Panel_unetThresholds, 'numeric');
            app.EditField_puff_thr.Limits = [0 1];
            app.EditField_puff_thr.Tooltip = {'regions detected with U-Net with probability higher than x are accepted as respective events. Choose number from [0,1]'};
            app.EditField_puff_thr.Value = 0.5;
            app.waveEditFieldLabel = uilabel(app.Panel_unetThresholds);
            app.waveEditFieldLabel.Text = 'wave';
            app.EditField_wave_thr = uieditfield(app.Panel_unetThresholds, 'numeric');
            app.EditField_wave_thr.Limits = [0 1];
            app.EditField_wave_thr.Tooltip = {'regions detected with U-Net with probability higher than x are accepted as respective events. Choose number from [0,1]'};
            app.EditField_wave_thr.Value = 0.5;
            app.sparkEditField_5Label = uilabel(app.Panel_unetThresholds);
            app.sparkEditField_5Label.Text = 'spark';
            app.EditField_spark_thr = uieditfield(app.Panel_unetThresholds, 'numeric');
            app.EditField_spark_thr.Limits = [0 1];
            app.EditField_spark_thr.Tooltip = {'regions detected with U-Net with probability higher than x are accepted as respective events. Choose number from [0,1]'};
            app.EditField_spark_thr.Value = 0.5;
            app.Panel_batchMode = uipanel(app.Panel_caEventsDetection);
            app.Panel_batchMode.Title = 'batch mode';
            app.Button_batchAnalysis = uibutton(app.Panel_batchMode, 'push');
            app.Button_batchAnalysis.ButtonPushedFcn = createCallbackFcn(app, @batchModeAnalysis, true);
            app.Button_batchAnalysis.Text = {'select folder'; '(image series, .tif)'};
            app.CheckBox_includeSubfolders = uicheckbox(app.Panel_batchMode);
            app.CheckBox_includeSubfolders.Text = 'include subfolders';
            app.CheckBox_includeSubfolders.Value = true;
            app.typeofprocessDropDownLabel = uilabel(app.Panel_batchMode);
            app.typeofprocessDropDownLabel.Text = 'type of process';
            app.DropDown_typeOfAnalysisInBatch = uidropdown(app.Panel_batchMode);
            app.DropDown_typeOfAnalysisInBatch.Items = {'detect & analyze events', 'detect events'};
            app.DropDown_typeOfAnalysisInBatch.Value = 'detect & analyze events';
            app.CheckBox_findCellMaskInBatch = uicheckbox(app.Panel_batchMode);
            app.CheckBox_findCellMaskInBatch.Text = 'find cell mask';
            app.CheckBox_findCellMaskInBatch.Value = true;
            app.CheckBox_calcSelfRatioInBatch = uicheckbox(app.Panel_batchMode);
            app.CheckBox_calcSelfRatioInBatch.Text = 'calculate self-ratio';
            app.CheckBox_calcSelfRatioInBatch.Value = true;
            app.CheckBox_saveDetection = uicheckbox(app.Panel_caEventsDetection);
            app.CheckBox_saveDetection.Text = 'save detection (.mat file)';
            app.CheckBox_saveDetection.Value = true;
            app.Panel_imgVisual = uipanel(app.Tab_CaEventsDetection);
            app.Panel_imgVisual.Title = 'image series visualization control';
            app.Slider_imgFrame = uislider(app.Panel_imgVisual);
            app.Slider_imgFrame.ValueChangedFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Slider_imgFrame.ValueChangingFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Slider_imgFrame.Tag = 'img_sld';
            app.Button_play = uibutton(app.Panel_imgVisual, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @playWholeImgSeries, true);
            app.Button_play.Tag = 'play_imgSeries';
            app.Button_play.Text = 'play';
            app.CheckBox_playInLoop = uicheckbox(app.Panel_imgVisual);
            app.CheckBox_playInLoop.Tag = 'playInLoop';
            app.CheckBox_playInLoop.Text = 'play in loop';
            app.Button_stop = uibutton(app.Panel_imgVisual, 'push');
            app.Button_stop.ButtonPushedFcn = createCallbackFcn(app, @stopPlayingImgSeries, true);
            app.Button_stop.Tag = 'stop_imgSeries';
            app.Button_stop.Text = 'stop';
            app.frame1SpinnerLabel = uilabel(app.Panel_imgVisual);
            app.frame1SpinnerLabel.Text = 'frame 1';
            app.Spinner_imgFrame = uispinner(app.Panel_imgVisual);
            app.Spinner_imgFrame.ValueChangingFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Spinner_imgFrame.RoundFractionalValues = 'on';
            app.Spinner_imgFrame.ValueChangedFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Spinner_imgFrame.Tag = 'frame_select';
            app.Spinner_imgFrame.Value = 1;
            app.framessEditFieldLabel = uilabel(app.Panel_imgVisual);
            app.framessEditFieldLabel.Text = 'frames/s';
            app.EditField_framesPerSec = uieditfield(app.Panel_imgVisual, 'numeric');
            app.EditField_framesPerSec.Tag = 'frameRate';
            app.EditField_framesPerSec.Tooltip = {'maximum is 50 frames per second'};
            app.EditField_framesPerSec.Value = 20;
            app.timemsLabel = uilabel(app.Panel_imgVisual);
            app.timemsLabel.Text = 'time (ms)';
            app.EditField_imgTime = uieditfield(app.Panel_imgVisual, 'numeric');
            app.EditField_imgTime.ValueDisplayFormat = '%5.3f';
            app.EditField_imgTime.Tag = 'showImgTime';
            app.stateLampLabel = uilabel(app.Panel_imgVisual);
            app.stateLampLabel.Text = 'state';
            app.Lamp_state = uilamp(app.Panel_imgVisual);
            app.Lamp_state.Tag = 'stateOfDetection';
            app.Lamp_state.Tooltip = {'show state of detection: events detection running (red), done (green)'};
            app.Panel_visualisation = uipanel(app.Panel_imgVisual);
            app.Panel_visualisation.Title = 'Visualisation';
            app.lookuptableDropDownLabel = uilabel(app.Panel_visualisation);
            app.lookuptableDropDownLabel.Text = 'lookup table';
            app.DropDown_lookuptable = uidropdown(app.Panel_visualisation);
            app.DropDown_lookuptable.Items = {'jet', 'parula', 'turbo', 'hot', 'gray'};
            app.DropDown_lookuptable.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.DropDown_lookuptable.Tag = 'selectColormap';
            app.DropDown_lookuptable.Value = 'jet';
            app.setglobalminEditFieldLabel = uilabel(app.Panel_visualisation);
            app.setglobalminEditFieldLabel.Text = 'set global min';
            app.EditField_setglobalmin = uieditfield(app.Panel_visualisation, 'numeric');
            app.EditField_setglobalmin.ValueDisplayFormat = '%11.2f';
            app.EditField_setglobalmin.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.EditField_setglobalmin.Tag = 'edit_minImg';
            app.setglobalmaxEditFieldLabel = uilabel(app.Panel_visualisation);
            app.setglobalmaxEditFieldLabel.Text = 'set global max';
            app.EditField_setglobalmax = uieditfield(app.Panel_visualisation, 'numeric');
            app.EditField_setglobalmax.ValueDisplayFormat = '%11.2f';
            app.EditField_setglobalmax.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.EditField_setglobalmax.Tag = 'edit_maxImg';
            app.Panel_imgFilters = uipanel(app.Panel_imgVisual);
            app.Panel_imgFilters.Title = 'Filters applied to image series in sequence';
            app.filterimageserieswithselectedfiltersButton = uibutton(app.Panel_imgFilters, 'push');
            app.filterimageserieswithselectedfiltersButton.ButtonPushedFcn = createCallbackFcn(app, @setAndApplyFilters, true);
            app.filterimageserieswithselectedfiltersButton.Tag = 'filter_img';
            app.filterimageserieswithselectedfiltersButton.Text = 'filter image series with selected filters';
            app.UITable_filters = uitable(app.Panel_imgFilters);
            app.UITable_filters.ColumnName = {'filter'; 'parameter #1'; 'p#1 value'; 'parameter #2'; 'p#2 value'; 'parameter #3'; 'p#3 value'; 'parameter #4'; 'p#4 value'};
            app.UITable_filters.RowName = {};
            app.UITable_filters.ColumnEditable = [true false true false true false true false true];
            app.UITable_filters.CellEditCallback = createCallbackFcn(app, @setAndApplyFilters, true);
            app.UITable_filters.Tag = 'filtersSelectionTbl';
            app.Button_butterworthFilter = uibutton(app.Panel_imgFilters, 'push');
            app.Button_butterworthFilter.ButtonPushedFcn = createCallbackFcn(app, @applyButterworthFilter, true);
            app.Button_butterworthFilter.Text = 'set parametes of Butterworth filter';
            app.Panel_cropImageSeries = uipanel(app.Panel_imgVisual);
            app.Panel_cropImageSeries.Title = 'Crop image series';
            app.Button_crop = uibutton(app.Panel_cropImageSeries, 'push');
            app.Button_crop.ButtonPushedFcn = createCallbackFcn(app, @cropImgSeries, true);
            app.Button_crop.Tag = 'cropImage';
            app.Button_crop.Text = 'select xy area to crop';
            app.endframeLabel = uilabel(app.Panel_cropImageSeries);
            app.endframeLabel.Text = 'end frame';
            app.EditField_cropEndFrame = uieditfield(app.Panel_cropImageSeries, 'numeric');
            app.EditField_cropEndFrame.Value = 1000;
            app.startframeLabel = uilabel(app.Panel_cropImageSeries);
            app.startframeLabel.Text = 'start frame';
            app.EditField_cropStartFrame = uieditfield(app.Panel_cropImageSeries, 'numeric');
            app.EditField_cropStartFrame.Value = 1;
            app.Button_restart = uibutton(app.Panel_imgVisual, 'push');
            app.Button_restart.ButtonPushedFcn = createCallbackFcn(app, @restartAnalysis, true);
            app.Button_restart.Tag = 'restartAnalysis';
            app.Button_restart.Text = 'restart';
            app.selfratioPanel = uipanel(app.Tab_CaEventsDetection);
            app.selfratioPanel.Title = 'self-ratio';
            app.UIAxes_F0 = uiaxes(app.selfratioPanel);
            title(app.UIAxes_F0, 'photobleaching')
            xlabel(app.UIAxes_F0, 'frame')
            ylabel(app.UIAxes_F0, 'calculated F0')
            app.Button_selfRatio = uibutton(app.selfratioPanel, 'push');
            app.Button_selfRatio.ButtonPushedFcn = createCallbackFcn(app, @calculate_selfRatio, true);
            app.Button_selfRatio.Tooltip = {'calculate self ratio of image series using detected cell edge'};
            app.Button_selfRatio.Text = {'calculate'; 'corrected'; 'self-ratio'};
            app.functionforcorrectionLabel = uilabel(app.selfratioPanel);
            app.functionforcorrectionLabel.Text = 'function for correction';
            app.DropDown_photobleachFcn = uidropdown(app.selfratioPanel);
            app.DropDown_photobleachFcn.Items = {'none', 'mono-exponential', 'bi-exponential', 'stretched-exponential', 'polynomial'};
            app.DropDown_photobleachFcn.ValueChangedFcn = createCallbackFcn(app, @fitF0WithSelectedFcn, true);
            app.DropDown_photobleachFcn.Value = 'none';
            app.polynomialdegreeEditFieldLabel = uilabel(app.selfratioPanel);
            app.polynomialdegreeEditFieldLabel.Text = 'polynomial degree';
            app.EditField_polyDegree = uieditfield(app.selfratioPanel, 'numeric');
            app.EditField_polyDegree.Limits = [1 9];
            app.EditField_polyDegree.RoundFractionalValues = 'on';
            app.EditField_polyDegree.Tooltip = {'polynomial degree [1 - 9]'};
            app.EditField_polyDegree.Value = 4;
            app.CheckBox_F0ForEachFrame = uicheckbox(app.selfratioPanel);
            app.CheckBox_F0ForEachFrame.Text = 'calculate F0 for each frame';
            app.Panel_cellMask = uipanel(app.Tab_CaEventsDetection);
            app.Panel_cellMask.Title = 'cell mask';
            app.Button_cellMask = uibutton(app.Panel_cellMask, 'push');
            app.Button_cellMask.ButtonPushedFcn = createCallbackFcn(app, @findCellMask, true);
            app.Button_cellMask.Text = 'find cell mask';
            app.kernelsizeLabel = uilabel(app.Panel_cellMask);
            app.kernelsizeLabel.Text = {'kernel'; 'size'};
            app.EditField_kernelSz_cellMask = uieditfield(app.Panel_cellMask, 'numeric');
            app.EditField_kernelSz_cellMask.Value = 5;
            app.CheckBox_cellMask_show = uicheckbox(app.Panel_cellMask);
            app.CheckBox_cellMask_show.ValueChangedFcn = createCallbackFcn(app, @showCellMask, true);
            app.CheckBox_cellMask_show.Text = {'show '; 'cell mask'};
            app.CheckBox_cellMask_show.Value = true;
            app.Panel_cellMaskManual = uipanel(app.Panel_cellMask);
            app.Panel_cellMaskManual.Title = 'Manual correction of cell mask';
            app.Button_cellMask_manualCorrection = uibutton(app.Panel_cellMaskManual, 'push');
            app.Button_cellMask_manualCorrection.ButtonPushedFcn = createCallbackFcn(app, @cellMask_manualCorrection, true);
            app.Button_cellMask_manualCorrection.Tag = 'start corrections';
            app.Button_cellMask_manualCorrection.Text = 'start corrections';
            app.CheckBox_cellMask_manualCorrection_toAll = uicheckbox(app.Panel_cellMaskManual);
            app.CheckBox_cellMask_manualCorrection_toAll.Text = {'apply to'; 'all frames'};
            app.CheckBox_cellMask_manualCorrection_toAll.Value = true;
            app.CheckBox_cellMask_manulalCorrection_fillHoles = uicheckbox(app.Panel_cellMaskManual);
            app.CheckBox_cellMask_manulalCorrection_fillHoles.Text = 'fill holes';
            app.CheckBox_cellMask_manulalCorrection_fillHoles.Value = true;
            app.CheckBox_cellMaskForEachFrame = uicheckbox(app.Panel_cellMask);
            app.CheckBox_cellMaskForEachFrame.ValueChangedFcn = createCallbackFcn(app, @checkBoxes_cellEdgePanel, true);
            app.CheckBox_cellMaskForEachFrame.Text = 'find for each frame';
            app.Tab_analysedEventsBrowser = uitab(app.TabGroup);
            app.Tab_analysedEventsBrowser.Title = 'analysed events browser';
            app.Tab_analysedEventsBrowser.Tag = 'analysisTab';
            app.UIAxes_img_events = uiaxes(app.Tab_analysedEventsBrowser);
            title(app.UIAxes_img_events, 'xyt recording')
            xlabel(app.UIAxes_img_events, 'x (pixels)')
            ylabel(app.UIAxes_img_events, 'y (pixels)')
            zlabel(app.UIAxes_img_events, 'Z')
            app.UIAxes_img_events.Tag = 'img_ax_events';
            app.Panel_detectedEvents = uipanel(app.Tab_analysedEventsBrowser);
            app.Panel_detectedEvents.Title = 'Detected events';
            app.UITable_events = uitable(app.Panel_detectedEvents);
            app.UITable_events.ColumnName = {'event ID'; 'type'; 'start frame'; 'end frame'; 'accepted'};
            app.UITable_events.RowName = {};
            app.UITable_events.ColumnEditable = [false false false false true];
            app.UITable_events.CellSelectionCallback = createCallbackFcn(app, @showSelectedEvent, true);
            app.UITable_events.Tag = 'detectedEventsTable';
            app.SelectedeventPanel = uipanel(app.Tab_analysedEventsBrowser);
            app.SelectedeventPanel.Title = 'Selected event';
            app.UIAxes_eventTprof = uiaxes(app.SelectedeventPanel);
            title(app.UIAxes_eventTprof, 't-profile; event type (ID# 1)')
            xlabel(app.UIAxes_eventTprof, 'time (ms)')
            ylabel(app.UIAxes_eventTprof, 'amplitude (ΔF/F_0)')
            zlabel(app.UIAxes_eventTprof, 'Z')
            app.UIAxes_eventTprof.Tag = 'tProfile_ax';
            app.UIAxes_eventXYprof = uiaxes(app.SelectedeventPanel);
            title(app.UIAxes_eventXYprof, 'xy profiles in event maximum')
            xlabel(app.UIAxes_eventXYprof, 'x (µm)')
            ylabel(app.UIAxes_eventXYprof, 'y (µm)')
            zlabel(app.UIAxes_eventXYprof, 'F')
            app.UIAxes_eventXYprof.Tag = '2DGaussFit_ax';
            app.UITable_eventParams = uitable(app.SelectedeventPanel);
            app.UITable_eventParams.ColumnName = {'Amplitude (∆F/F0)'; 'TTP (ms)'; 'FDHM (ms)'; 'tauRise (ms)'; 'tauDecay (ms)'; 'FWHMx (µm)'; 'FWHMy (µm)'; 'WaveSpeed (µm/s)'};
            app.UITable_eventParams.RowName = {};
            app.Panel_firCorrection = uipanel(app.SelectedeventPanel);
            app.Panel_firCorrection.Title = 'Correct fit of event';
            app.smoothspanmstprofileLabel = uilabel(app.Panel_firCorrection);
            app.smoothspanmstprofileLabel.Text = {'smooth span (ms)'; '(t-profile)'};
            app.EditField_smoothParam = uieditfield(app.Panel_firCorrection, 'numeric');
            app.EditField_smoothParam.Limits = [0 Inf];
            app.EditField_smoothParam.ValueChangedFcn = createCallbackFcn(app, @refitSelectedEvent, true);
            app.EditField_smoothParam.Value = 50;
            app.selectfitfunctiontprofileLabel = uilabel(app.Panel_firCorrection);
            app.selectfitfunctiontprofileLabel.Text = {'select fit function'; '(t-profile)'};
            app.DropDown_fitFunSelection = uidropdown(app.Panel_firCorrection);
            app.DropDown_fitFunSelection.Items = {'spline', 'EMG', '1expR1expD', 'mean'};
            app.DropDown_fitFunSelection.ValueChangedFcn = createCallbackFcn(app, @refitSelectedEvent, true);
            app.DropDown_fitFunSelection.Value = 'spline';
            app.k_thpowerofweights2DGaussianfitLabel = uilabel(app.Panel_firCorrection);
            app.k_thpowerofweights2DGaussianfitLabel.Text = {'k_th power of weights'; '(2D Gaussian fit)'};
            app.EditField_weightsPower2DGaussFit = uieditfield(app.Panel_firCorrection, 'numeric');
            app.EditField_weightsPower2DGaussFit.UpperLimitInclusive = 'off';
            app.EditField_weightsPower2DGaussFit.Limits = [1 Inf];
            app.EditField_weightsPower2DGaussFit.RoundFractionalValues = 'on';
            app.EditField_weightsPower2DGaussFit.ValueChangedFcn = createCallbackFcn(app, @refitSelectedEvent, true);
            app.EditField_weightsPower2DGaussFit.Value = 1;
            app.Panel_imgVisual_events = uipanel(app.Tab_analysedEventsBrowser);
            app.Panel_imgVisual_events.Title = 'image series detected events visualization control';
            app.Slider_imgFrame_events = uislider(app.Panel_imgVisual_events);
            app.Slider_imgFrame_events.ValueChangedFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Slider_imgFrame_events.ValueChangingFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Slider_imgFrame_events.Tag = 'img_sld_events';
            app.Button_play_events = uibutton(app.Panel_imgVisual_events, 'push');
            app.Button_play_events.ButtonPushedFcn = createCallbackFcn(app, @playWholeImgSeries, true);
            app.Button_play_events.Tag = 'play_imgSeries_events';
            app.Button_play_events.Text = 'play';
            app.CheckBox_playInLoop_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_playInLoop_events.Tag = 'playInLoop_events';
            app.CheckBox_playInLoop_events.Text = 'play in loop';
            app.Button_stop_events = uibutton(app.Panel_imgVisual_events, 'push');
            app.Button_stop_events.ButtonPushedFcn = createCallbackFcn(app, @stopPlayingImgSeries, true);
            app.Button_stop_events.Tag = 'stop_imgSeries_events';
            app.Button_stop_events.Text = 'stop';
            app.frame1SpinnerLabel_2 = uilabel(app.Panel_imgVisual_events);
            app.frame1SpinnerLabel_2.Text = 'frame 1';
            app.Spinner_imgFrame_events = uispinner(app.Panel_imgVisual_events);
            app.Spinner_imgFrame_events.RoundFractionalValues = 'on';
            app.Spinner_imgFrame_events.ValueChangedFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Spinner_imgFrame_events.Tag = 'frame_select_events';
            app.Spinner_imgFrame_events.Value = 1;
            app.framessEditFieldLabel_2 = uilabel(app.Panel_imgVisual_events);
            app.framessEditFieldLabel_2.Text = 'frames/s';
            app.EditField_framesPerSec_events = uieditfield(app.Panel_imgVisual_events, 'numeric');
            app.EditField_framesPerSec_events.Tag = 'frameRate_events';
            app.EditField_framesPerSec_events.Tooltip = {'maximum is 50 frames per second'};
            app.EditField_framesPerSec_events.Value = 20;
            app.timemsLabel_2 = uilabel(app.Panel_imgVisual_events);
            app.timemsLabel_2.Text = 'time (ms)';
            app.EditField_imgTime_events = uieditfield(app.Panel_imgVisual_events, 'numeric');
            app.EditField_imgTime_events.ValueDisplayFormat = '%5.3f';
            app.EditField_imgTime_events.Tag = 'showImgTime_events';
            app.stateLampLabel_2 = uilabel(app.Panel_imgVisual_events);
            app.stateLampLabel_2.Text = 'state';
            app.Lamp_state_events = uilamp(app.Panel_imgVisual_events);
            app.Lamp_state_events.Tag = 'stateOfDetection';
            app.Lamp_state_events.Tooltip = {'show state of detection: events detection running (red), done (green)'};
            app.Panel_visualisation_events = uipanel(app.Panel_imgVisual_events);
            app.Panel_visualisation_events.Title = 'Visualisation';
            app.lookuptableDropDownLabel_3 = uilabel(app.Panel_visualisation_events);
            app.lookuptableDropDownLabel_3.Text = 'lookup table';
            app.DropDown_lookuptable_events = uidropdown(app.Panel_visualisation_events);
            app.DropDown_lookuptable_events.Items = {'jet', 'parula', 'turbo', 'hot', 'gray'};
            app.DropDown_lookuptable_events.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.DropDown_lookuptable_events.Tag = 'selectColormap_events';
            app.DropDown_lookuptable_events.Value = 'jet';
            app.setglobalminEditFieldLabel_2 = uilabel(app.Panel_visualisation_events);
            app.setglobalminEditFieldLabel_2.Text = 'set global min';
            app.EditField_setglobalmin_events = uieditfield(app.Panel_visualisation_events, 'numeric');
            app.EditField_setglobalmin_events.ValueDisplayFormat = '%11.2f';
            app.EditField_setglobalmin_events.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.EditField_setglobalmin_events.Tag = 'edit_minImg_events';
            app.setglobalmaxEditFieldLabel_2 = uilabel(app.Panel_visualisation_events);
            app.setglobalmaxEditFieldLabel_2.Text = 'set global max';
            app.EditField_setglobalmax_events = uieditfield(app.Panel_visualisation_events, 'numeric');
            app.EditField_setglobalmax_events.ValueDisplayFormat = '%11.2f';
            app.EditField_setglobalmax_events.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.EditField_setglobalmax_events.Tag = 'edit_maxImg_events';
            app.CheckBox_showevents_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showevents_events.ValueChangedFcn = createCallbackFcn(app, @showDetectedEvents_checkBoxes, true);
            app.CheckBox_showevents_events.Tag = 'check_showEvents';
            app.CheckBox_showevents_events.Text = 'show events';
            app.CheckBox_showevents_events.Value = true;
            app.CheckBox_showmask_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showmask_events.ValueChangedFcn = createCallbackFcn(app, @showDetectedEvents_checkBoxes, true);
            app.CheckBox_showmask_events.Tag = 'check_showMaskOfEvents';
            app.CheckBox_showmask_events.Tooltip = {'show mask of events'};
            app.CheckBox_showmask_events.Text = 'show masks of events';
            app.CheckBox_showmask_events.Value = true;
            app.CheckBox_showBB_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showBB_events.ValueChangedFcn = createCallbackFcn(app, @showDetectedEvents_checkBoxes, true);
            app.CheckBox_showBB_events.Tag = 'check_showBBofEvents';
            app.CheckBox_showBB_events.Tooltip = {'show bounding box of events'};
            app.CheckBox_showBB_events.Text = 'show BB of events';
            app.CheckBox_showBB_events.Value = true;
            app.CheckBox_showCellMask_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showCellMask_events.ValueChangedFcn = createCallbackFcn(app, @showDetectedEvents_checkBoxes, true);
            app.CheckBox_showCellMask_events.Tag = 'check_showCellMask_events';
            app.CheckBox_showCellMask_events.Text = 'show cell boundary';
            app.CheckBox_showCellMask_events.Value = true;
            app.CheckBox_showSelectedEvent = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showSelectedEvent.Tag = 'checkBox_showSelectedEvent';
            app.CheckBox_showSelectedEvent.Text = 'show only selected event from table';
            app.Button_save = uibutton(app.Tab_analysedEventsBrowser, 'push');
            app.Button_save.ButtonPushedFcn = createCallbackFcn(app, @saveAnalysis, true);
            app.Button_save.Text = 'save analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CaSignalsAnalysisXYT

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @start_up_fcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end